classdef TWS100 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TimescaleDropDownLabel       matlab.ui.control.Label
        TimescaleDropDown            matlab.ui.control.DropDown
        PlotTypeDropDown             matlab.ui.control.DropDown
        PlotTypeDropDownLabel        matlab.ui.control.Label
        BasinSelectionButton         matlab.ui.control.DropDown
        BasinDropDownLabel           matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.StateButton
        TimeSpan19012025Slider       matlab.ui.control.RangeSlider
        TimeSpan19012025SliderLabel  matlab.ui.control.Label
        validation1                  matlab.ui.container.CheckBoxTree
        GroundwaterNode              matlab.ui.container.TreeNode
        CGWBNode                     matlab.ui.container.TreeNode
        GRACENode                    matlab.ui.container.TreeNode
        PrecipitationNode_2          matlab.ui.container.TreeNode
        IMDNode_2                    matlab.ui.container.TreeNode
        evalution_metrices           matlab.ui.container.CheckBoxTree
        EvaluationMetricsNode        matlab.ui.container.TreeNode
        CCNode                       matlab.ui.container.TreeNode
        NSENode                      matlab.ui.container.TreeNode
        RSMENode                     matlab.ui.container.TreeNode
        SensitivityAnalysisNode      matlab.ui.container.TreeNode
        ExportPlotButton             matlab.ui.control.StateButton
        Trend                        matlab.ui.container.CheckBoxTree
        TrendsNode                   matlab.ui.container.TreeNode
        LinearNode                   matlab.ui.container.TreeNode
        PolynomialNode               matlab.ui.container.TreeNode
        LoessNode                    matlab.ui.container.TreeNode
        SensslopeNode                matlab.ui.container.TreeNode
        Precipitation                matlab.ui.container.CheckBoxTree
        PrecipitationNode            matlab.ui.container.TreeNode
        IMDNode                      matlab.ui.container.TreeNode
        ERA5Node                     matlab.ui.container.TreeNode
        GPCPNode                     matlab.ui.container.TreeNode
        This_Study                   matlab.ui.container.CheckBoxTree
        ThisStudyNode                matlab.ui.container.TreeNode
        ANN_GNode                    matlab.ui.container.TreeNode
        RF_GNode                     matlab.ui.container.TreeNode
        ANN_IMDNode                  matlab.ui.container.TreeNode
        RF_IMDNode                   matlab.ui.container.TreeNode
        ReconstructionNode           matlab.ui.container.TreeNode
        RTWSA                        matlab.ui.container.CheckBoxTree
        Previous_TWSANode            matlab.ui.container.TreeNode
        HG19Node                     matlab.ui.container.TreeNode
        D23Node                      matlab.ui.container.TreeNode
        L21Node                      matlab.ui.container.TreeNode
        M25Node                      matlab.ui.container.TreeNode
        P25Node                      matlab.ui.container.TreeNode
        TWSA                         matlab.ui.container.CheckBoxTree
        GRACETWSANode                matlab.ui.container.TreeNode
        JPLNode                      matlab.ui.container.TreeNode
        CSRNode                      matlab.ui.container.TreeNode
        GSFCNode                     matlab.ui.container.TreeNode
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        MainAxesOriginalPosition
        % Variable to store IMD data
        IMDData;IMDLat;IMDLon;IMDTime;IMDMN
        % Variable to store GPCP data
        GPCPData;GPCPTime;GPCPLat;GPCPLon;GPCPMN
        % Variable to store ERA5 data
        ERA5Data;ERA5Time;ERA5Lat;ERA5Lon;ERA5MN
        % Variable to store Humphrey reconstructed data
        Hp_GERECData;Hp_GERECTime;Hp_GERECLat;Hp_GERECLon;Hp_GERECMN
        Hp_GGRECData;Hp_GGRECTime;Hp_GGRECLat;Hp_GGRECLon;Hp_GGRECMN
        Hp_GMRECData;Hp_GMRECTime;Hp_GMRECLat;Hp_GMRECLon;Hp_GMRECMN
        Hp_JERECData;Hp_JERECTime;Hp_JERECLat;Hp_JERECLon;Hp_JERECMN
        Hp_JGRECData;Hp_JGRECTime;Hp_JGRECLat;Hp_JGRECLon;Hp_JGRECMN
        Hp_JMRECData;Hp_JMRECTime;Hp_JMRECLat;Hp_JMRECLon;Hp_JMRECMN
        Hp_JPL_TWS_BA;Hp_CSR_TWS_BA
        % Variable to store Yin reconstructed data
        YinRECData
        % Variable to store Li reconstructed data
        LiRECData;LiRECTime;LiRECLat;LiRECLon;LiRECMN
        % Variable to store JPL TWS data
        JPLTWSData;JPLTWSTime;JPLTWSLat;JPLTWSLon;JPLTWSMN
        % Variable to store CSR TWS data
        CSRTWSData;CSRTWSTime;CSRTWSLat;CSRTWSLon;CSRTWSMN
        % Variable to store GFSC TWS data
        GFSCTWSData;GFSCTWSTime;GFSCTWSLat;GFSCTWSLon;GFSCTWSMN
        Basins;selectedStartTime;selectedEndTime;StartTimeLabel;EndTimeLabel;H_REC;IMD_BA;GPCP_BA;ERA5_BA
        % Deng 2023
        DengRECData;DengRECTime;DengRECLat;DengRECLon;DengRECMN
        % Mandal 2025
        MandalRECData;MandalRECTime;MandalRECLat;MandalRECLon;MandalRECMN
        % Nabakishor 2025
        NabakishorRECData;NabakishorRECTime;NabakishorRECLat;NabakishorRECLon;NabakishorRECMN

        % Palazzoli 2025 - multiple models
        PalazzoliLSTMData;PalazzoliLSTMTime;PalazzoliLSTMLat;PalazzoliLSTMLon;PalazzoliLSTMMN

        PalazzoliLSTMnoLCSIFData;PalazzoliLSTMnoLCSIFTime;PalazzoliLSTMnoLCSIFLat;PalazzoliLSTMnoLCSIFLon;PalazzoliLSTMnoLCSIFMN

        PalazzoliBiLSTMData;PalazzoliBiLSTMTime;PalazzoliBiLSTMLat;PalazzoliBiLSTMLon;PalazzoliBiLSTMMN

        PalazzoliBiLSTMnoLCSIFData;PalazzoliBiLSTMnoLCSIFTime;PalazzoliBiLSTMnoLCSIFLat;PalazzoliBiLSTMnoLCSIFLon;PalazzoliBiLSTMnoLCSIFMN

        % ================= THIS STUDY - multiple models =================
        ThisStudyBestData;ThisStudyBestTime;ThisStudyBestLat;ThisStudyBestLon;ThisStudyBestMN

        ThisStudyANNData;ThisStudyANNTime;ThisStudyANNLat;ThisStudyANNLon;ThisStudyANNMN

        ThisStudyLSTMData;ThisStudyLSTMTime;ThisStudyLSTMLat;ThisStudyLSTMLon;ThisStudyLSTMMN

        ThisStudyRFData;ThisStudyRFTime;ThisStudyRFLat;ThisStudyRFLon;ThisStudyRFMN
        GLDAS_PData;GLDAS_PTime;GLDAS_PLat;GLDAS_PLon;GLDAS_PMN

        GLDAS_ETData; GLDAS_ETTime; GLDAS_ETLat; GLDAS_ETLon; GLDAS_ETMN

        GLDAS_SMData; GLDAS_SMTime; GLDAS_SMLat; GLDAS_SMLon; GLDAS_SMMN

        GLDAS_TData; GLDAS_TTime; GLDAS_TLat; GLDAS_TLon; GLDAS_TMN

        GLDAS_WSData; GLDAS_WSTime; GLDAS_WSLat; GLDAS_WSLon; GLDAS_WSMN

        GLDAS_SHData; GLDAS_SHTime; GLDAS_SHLat; GLDAS_SHLon; GLDAS_SHMN

        GLDAS_LWData; GLDAS_LWTime; GLDAS_LWLat; GLDAS_LWLon; GLDAS_LWMN

        GLDAS_SWData; GLDAS_SWTime; GLDAS_SWLat; GLDAS_SWLon; GLDAS_SWMN

        CGWBData;CGWBAnomalies; CGWBAvg; CGWBTime; CGWBLat; CGWBLon
        MainAxesOriginalUnits
        NewAxes matlab.ui.control.UIAxes
        CGWBAxes matlab.ui.control.UIAxes
        GRACEAxes matlab.ui.control.UIAxes
        PrecipitationAxes matlab.ui.control.UIAxes
        DynamicAxes matlab.ui.control.UIAxes
        IMD_datetime; JPL_datetime; GLDAS_datetime
    end

    methods (Access = private)
        % % Function for Loading NetCDF file
        % function [data, time, lat, lon, mappedTime] = loadNetCDFData(app, filepath, varname, latname, longname, timename)
        %     % Load data, spatial coordinates, and correctly interpret the time variable from a NetCDF file
        %     try
        %         % Load the primary variable
        %         data = ncread(filepath, varname);
        % 
        %         % Load spatial and temporal coordinates
        %         lat = ncread(filepath, latname);
        %         lon = ncread(filepath, longname);
        %         time = ncread(filepath, timename);
        %         % Clear previous plots in the UIAxes
        %         cla(app.UIAxes);
        %         % Get time units from the file
        %         info = ncinfo(filepath);
        %         timeVar = info.Variables(strcmp({info.Variables.Name}, timename));
        %         timeUnits = '';
        %         for i = 1:length(timeVar.Attributes)
        %             if strcmpi(timeVar.Attributes(i).Name, 'units')
        %                 timeUnits = timeVar.Attributes(i).Value;
        %                 break;
        %             end
        %         end
        % 
        %         % Parse time units (e.g., "days since 1900-01-01")
        %         if contains(timeUnits, 'since', 'IgnoreCase', true)
        %             parts = regexp(timeUnits, 'since\s*(\d{4}-\d{2}-\d{2})', 'tokens', 'once');
        %             if ~isempty(parts)
        %                 baseDate = datetime(parts{1}, 'InputFormat', 'yyyy-MM-dd');
        %                 if contains(timeUnits, 'days', 'IgnoreCase', true)
        %                     time = baseDate + days(time);
        %                 elseif contains(timeUnits, 'hours', 'IgnoreCase', true)
        %                     time = baseDate + hours(time);
        %                 elseif contains(timeUnits, 'minutes', 'IgnoreCase', true)
        %                     time = baseDate + minutes(time);
        %                 else
        %                     msgbox('Unsupported time units: %s', timeUnits);
        %                 end
        %                 % Truncate to month and year and format as MM-yyyy
        %                 timeFormatted = string(datestr(time, 'mm-yyyy'));
        %             else
        %                 msgbox('Failed to parse base date from time units: %s', timeUnits);
        %             end
        %         else
        %             msgbox('Time units not recognized or missing in the NetCDF file.');
        %         end
        % 
        %         % Create mapping from 01-1901 to 12-2100
        %         startDate = datetime(1901, 1, 1); % Start date
        %         endDate = datetime(2100, 12, 1); % End date
        %         allMonths = startDate:calmonths(1):endDate; % Generate all months in the range
        %         allMonthStrings = string(datestr(allMonths, 'mm-yyyy')); % Convert to MM-yyyy format
        % 
        %         % Map the time variable to indices
        %         mappedTime = arrayfun(@(t) find(allMonthStrings == t, 1), timeFormatted);
        % 
        %         % Check for unmapped time values
        %         if any(isnan(mappedTime))
        %             msgbox('Some time values could not be mapped to the range 01-1901 to 12-2100.');
        %         end
        % 
        %     catch ME
        %         msgbox('Error reading NetCDF filD: %s', ME.message);
        %     end
        % end

        function [data, time, lat, lon, mappedTime] = loadNetCDFData(app, filepath, varname, latname, longname, timename)
            % Load data, coordinates, and convert NetCDF time to MATLAB datetime

            % Initialize outputs to avoid returning nothing after errors
            data = [];
            time = [];
            lat = [];
            lon = [];
            mappedTime = [];

            try
                % Load main variable and coordinates
                data = ncread(filepath, varname);
                lat  = ncread(filepath, latname);
                lon  = ncread(filepath, longname);
                rawTime = ncread(filepath, timename);

                % Clear UIAxes
                cla(app.UIAxes);

                % Get time units attribute
                info = ncinfo(filepath);
                varIndex = strcmp({info.Variables.Name}, timename);

                if ~any(varIndex)
                    errordlg(sprintf('Time variable "%s" not found in file.', timename));
                    return;
                end

                timeVar = info.Variables(varIndex);

                % Extract time units
                timeUnits = '';
                for i = 1:length(timeVar.Attributes)
                    if strcmpi(timeVar.Attributes(i).Name, 'units')
                        timeUnits = timeVar.Attributes(i).Value;
                        break;
                    end
                end

                if isempty(timeUnits)
                    errordlg('Time units attribute missing in NetCDF file.');
                    return;
                end

                % Parse base date
                if contains(timeUnits, 'since', 'IgnoreCase', true)
                    tokens = regexp(timeUnits, 'since\s*(\d{4}-\d{2}-\d{2})', 'tokens', 'once');
                    if isempty(tokens)
                        errordlg(sprintf('Unable to parse base date from units: %s', timeUnits));
                        return;
                    end
                    baseDate = datetime(tokens{1}, 'InputFormat','yyyy-MM-dd');
                else
                    errordlg(sprintf('Unsupported time unit format: %s', timeUnits));
                    return;
                end

                % Convert raw time values to MATLAB datetime
                if contains(timeUnits, 'days', 'IgnoreCase', true)
                    time = baseDate + days(rawTime);
                elseif contains(timeUnits, 'hours', 'IgnoreCase', true)
                    time = baseDate + hours(rawTime);
                elseif contains(timeUnits, 'minutes', 'IgnoreCase', true)
                    time = baseDate + minutes(rawTime);
                elseif contains(timeUnits, 'seconds', 'IgnoreCase', true)
                    time = baseDate + seconds(rawTime);
                else
                    errordlg(sprintf('Unsupported time step units in file: %s', timeUnits));
                    return;
                end

                % Create MM-yyyy formatted strings
                timeFormatted = string(datestr(time, 'mm-yyyy'));

                % Build mapping range 1901–2100
                startDate = datetime(1901,1,1);
                endDate   = datetime(2100,12,1);
                allMonths = startDate : calmonths(1) : endDate;
                allMonthStrings = string(datestr(allMonths, 'mm-yyyy'));

                % Map to indices
                mappedTime = arrayfun(@(t) find(allMonthStrings == t, 1,'first'), timeFormatted);

                if any(isnan(mappedTime))
                    errordlg('Some time values could not be mapped between 1901–2100.');
                end

            catch ME
                errordlg(sprintf('Error reading NetCDF file:\n\n%s', ME.message));
            end
        end
% function [data, time, lat, lon, mappedTime] = loadNetCDFData(app, filepath, varname, latname, longname, timename)
% 
% data = []; time = []; lat = []; lon = []; mappedTime = [];
% 
% try
%     % Faster coordinate reads
%     lat  = ncread(filepath, latname);
%     lon  = ncread(filepath, longname);
% 
%     % FAST TIME READ (if supported)
%     try
%         time = ncdateread(filepath, timename);
%     catch
%         % fallback to manual conversion
%         rawTime = ncread(filepath, timename);
%         info = ncinfo(filepath);
%         tvar = info.Variables(strcmp({info.Variables.Name}, timename));
% 
%         units = '';
%         for a = tvar.Attributes
%             if strcmpi(a.Name,'units'), units = a.Value; break; end
%         end
% 
%         base = datetime(extractBetween(units,"since ",""),'InputFormat','yyyy-MM-dd');
% 
%         if contains(units,'day','IgnoreCase',true)
%             time = base + days(rawTime);
%         elseif contains(units,'hour','IgnoreCase',true)
%             time = base + hours(rawTime);
%         elseif contains(units,'minute','IgnoreCase',true)
%             time = base + minutes(rawTime);
%         elseif contains(units,'second','IgnoreCase',true)
%             time = base + seconds(rawTime);
%         else
%             errordlg("Unsupported time units."); return;
%         end
%     end
% 
%     % SUPER FAST TIME INDEX (1901–2100)
%     mappedTime = (year(time) - 1901) * 12 + month(time);
% 
%     % Read variable (optional: partial read for ERA5)
%     data = ncread(filepath, varname);
% 
% catch ME
%     errordlg(sprintf("Error reading NetCDF:\n%s", ME.message));
% end
% 
% end

    function clearBasinData(app)
        % Clear all basin-specific data properties to free memory
        propertiesToClear = {
                            app.IMDData;           app.IMDLat;           app.IMDLon;           app.IMDTime;           app.IMDMN;
                            app.GPCPData;          app.GPCPTime;         app.GPCPLat;          app.GPCPLon;          app.GPCPMN;
                            app.ERA5Data;          app.ERA5Time;         app.ERA5Lat;          app.ERA5Lon;          app.ERA5MN;
                            
                            app.Hp_GERECData;      app.Hp_GERECTime;     app.Hp_GERECLat;      app.Hp_GERECLon;      app.Hp_GERECMN;
                            app.Hp_GGRECData;      app.Hp_GGRECTime;     app.Hp_GGRECLat;      app.Hp_GGRECLon;      app.Hp_GGRECMN;
                            app.Hp_GMRECData;      app.Hp_GMRECTime;     app.Hp_GMRECLat;      app.Hp_GMRECLon;      app.Hp_GMRECMN;
                            
                            app.Hp_JERECData;      app.Hp_JERECTime;     app.Hp_JERECLat;      app.Hp_JERECLon;      app.Hp_JERECMN;
                            app.Hp_JGRECData;      app.Hp_JGRECTime;     app.Hp_JGRECLat;      app.Hp_JGRECLon;      app.Hp_JGRECMN;
                            app.Hp_JMRECData;      app.Hp_JMRECTime;     app.Hp_JMRECLat;      app.Hp_JMRECLon;      app.Hp_JMRECMN;
                            
                            app.Hp_JPL_TWS_BA;     app.Hp_CSR_TWS_BA;
                            app.YinRECData;
                            
                            app.LiRECData;         app.LiRECTime;        app.LiRECLat;         app.LiRECLon;         app.LiRECMN;
                            
                            app.JPLTWSData;        app.JPLTWSTime;       app.JPLTWSLat;        app.JPLTWSLon;        app.JPLTWSMN;
                            app.CSRTWSData;        app.CSRTWSTime;       app.CSRTWSLat;        app.CSRTWSLon;        app.CSRTWSMN;
                            app.GFSCTWSData;       app.GFSCTWSTime;      app.GFSCTWSLat;       app.GFSCTWSLon;       app.GFSCTWSMN;
                            
                            app.Basins;
                            app.selectedStartTime; app.selectedEndTime;
                            app.StartTimeLabel;    app.EndTimeLabel;
                            
                            app.H_REC;
                            app.IMD_BA;            app.GPCP_BA;          app.ERA5_BA;
                            
                            app.DengRECData;      app.DengRECTime;       app.DengRECLat;      app.DengRECLon;      app.DengRECMN;
                            app.MandalRECData;    app.MandalRECTime;     app.MandalRECLat;    app.MandalRECLon;    app.MandalRECMN;
                            app.NabakishorRECData; app.NabakishorRECTime; app.NabakishorRECLat; app.NabakishorRECLon; app.NabakishorRECMN;
                            
                            app.PalazzoliLSTMData;         app.PalazzoliLSTMTime;         app.PalazzoliLSTMLat;         app.PalazzoliLSTMLon;         app.PalazzoliLSTMMN;
                            app.PalazzoliLSTMnoLCSIFData;  app.PalazzoliLSTMnoLCSIFTime;  app.PalazzoliLSTMnoLCSIFLat;  app.PalazzoliLSTMnoLCSIFLon;  app.PalazzoliLSTMnoLCSIFMN;
                            
                            app.PalazzoliBiLSTMData;       app.PalazzoliBiLSTMTime;       app.PalazzoliBiLSTMLat;       app.PalazzoliBiLSTMLon;       app.PalazzoliBiLSTMMN;
                            app.PalazzoliBiLSTMnoLCSIFData; app.PalazzoliBiLSTMnoLCSIFTime; app.PalazzoliBiLSTMnoLCSIFLat; app.PalazzoliBiLSTMnoLCSIFLon; app.PalazzoliBiLSTMnoLCSIFMN;
                            
                            app.ThisStudyBestData;   app.ThisStudyBestTime;   app.ThisStudyBestLat;   app.ThisStudyBestLon;   app.ThisStudyBestMN;
                            app.ThisStudyANNData;    app.ThisStudyANNTime;    app.ThisStudyANNLat;    app.ThisStudyANNLon;    app.ThisStudyANNMN;
                            app.ThisStudyLSTMData;   app.ThisStudyLSTMTime;   app.ThisStudyLSTMLat;   app.ThisStudyLSTMLon;   app.ThisStudyLSTMMN;
                            app.ThisStudyRFData;     app.ThisStudyRFTime;     app.ThisStudyRFLat;     app.ThisStudyRFLon;     app.ThisStudyRFMN;
                            
                            app.GLDAS_PData;   app.GLDAS_PTime;   app.GLDAS_PLat;   app.GLDAS_PLon;   app.GLDAS_PMN;
                            app.GLDAS_ETData;  app.GLDAS_ETTime;  app.GLDAS_ETLat;  app.GLDAS_ETLon;  app.GLDAS_ETMN;
                            app.GLDAS_SMData;  app.GLDAS_SMTime;  app.GLDAS_SMLat;  app.GLDAS_SMLon;  app.GLDAS_SMMN;
                            app.GLDAS_TData;   app.GLDAS_TTime;   app.GLDAS_TLat;   app.GLDAS_TLon;   app.GLDAS_TMN;
                            app.GLDAS_WSData;  app.GLDAS_WSTime;  app.GLDAS_WSLat;  app.GLDAS_WSLon;  app.GLDAS_WSMN;
                            app.GLDAS_SHData;  app.GLDAS_SHTime;  app.GLDAS_SHLat;  app.GLDAS_SHLon;  app.GLDAS_SHMN;
                            app.GLDAS_LWData;  app.GLDAS_LWTime;  app.GLDAS_LWLat;  app.GLDAS_LWLon;  app.GLDAS_LWMN;
                            app.GLDAS_SWData;  app.GLDAS_SWTime;  app.GLDAS_SWLat;  app.GLDAS_SWLon;  app.GLDAS_SWMN;
                            
                            app.CGWBData;      app.CGWBAnomalies; app.CGWBAvg; 
                            app.CGWBTime;      app.CGWBLat;       app.CGWBLon
};
        
        for i = 1:size(propertiesToClear, 1)
            for j = 1:size(propertiesToClear, 2)
                propName = propertiesToClear{i,j};
                if isprop(app, propName)
                    app.(propName) = [];
                end
            end
        end
        
        % Clear current basin reference
        if isprop(app, 'CurrentBasin')
            app.CurrentBasin = [];
        end
        
        % Force garbage collection
        pause(0.1);
    end
    
    function [basinData, basinTime, basinLat, basinLon] = extractBasinData(app, data, time, lat, lon, basinPolygon)
        % Extract only data within the specified basin polygon
        
        % Convert longitudes if needed
        if any(lon > 180)
            lon = lon - 360;
        end
        
        % Ensure basin polygon longitudes are in -180 to 180 range
        basinPolygon(:,1) = mod(basinPolygon(:,1) + 180, 360) - 180;
        
        % Create meshgrid for coordinates
        [LonGrid, LatGrid] = meshgrid(lon, lat);
        
        % Create mask for points inside basin
        inBasin = inpolygon(LonGrid(:), LatGrid(:), basinPolygon(:,1), basinPolygon(:,2));
        
        % Check if any points are inside basin
        if sum(inBasin) == 0
            warning('No data points found within the selected basin.');
            basinData = [];
            basinTime = [];
            basinLat = [];
            basinLon = [];
            return;
        end
        
        % Handle different data dimensions
        dataSize = size(data);
        
        if length(dataSize) == 3
            % 3D data (lat x lon x time or lon x lat x time)
            if dataSize(1) == numel(lon) && dataSize(2) == numel(lat)
                % Data is lon x lat x time, permute to lat x lon x time
                data = permute(data, [2, 1, 3]);
                dataSize = size(data);
            end
            
            % Reshape to (space x time)
            data2D = reshape(data, [], dataSize(3));
            basinData = data2D(inBasin, :);
            
        elseif length(dataSize) == 2
            % 2D data (already space x time)
            if size(data, 1) ~= numel(lat) * numel(lon)
                error('2D data dimensions do not match lat/lon grid');
            end
            basinData = data(inBasin, :);
        else
            error('Unsupported data dimensions: %s', mat2str(dataSize));
        end
        
        % Extract coordinates of points inside basin
        basinLat = LatGrid(inBasin);
        basinLon = LonGrid(inBasin);
        basinTime = time;
        
        % Reshape if needed for consistency
        if length(dataSize) == 3
            basinData = reshape(basinData, [sum(inBasin), dataSize(3)]);
        end
        
        fprintf('Extracted %d points from basin (out of %d total grid points)\n', ...
            sum(inBasin), numel(LatGrid));
    end

        %% Helper method to apply a basin mask
        function maskedData = applyBasinMask(app, data, lat, lon)
    % Applies a spatial mask for the selected basin and retains the timespan
    % Organizes output data where each column corresponds to the time series
    % for a point inside the selected basin.

    % ===================== BASIN SELECTION VALIDATION =====================
    selectedBasin = app.BasinSelectionButton.Value;

    if isempty(app.Basins)
        uialert(app.UIFigure, ...
            {'No basin shapefile data found.', ...
            'Please upload data before proceeding.'}, ...
            'Data Missing', 'Icon', 'warning');
        return;
    end

    if strcmpi(selectedBasin, 'Select Basin') || isempty(selectedBasin)
        uialert(app.UIFigure, ...
            {'Please select a basin from the dropdown before proceeding.'}, ...
            'No Basin Selected', 'Icon', 'warning');
        return;
    end

    % ===================== EXTRACT BASIN POLYGON =====================
    basins = app.Basins;
    basinPolygon = [];

    for i = 1:length(basins)
        if strcmpi(basins(i).RIVER_BASI, selectedBasin)
            basinPolygon = [basins(i).X', basins(i).Y'];
            break;
        end
    end

    if ~isempty(basinPolygon)
        basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);
    end

    if isempty(basinPolygon)
        uialert(app.UIFigure, ...
            {['The selected basin "', selectedBasin, '" is not available in the shapefile.'], ...
            'Please check your selection or upload the correct shapefile.'}, ...
            'Basin Not Found', 'Icon', 'warning');
        return;
    end

    % ===================== LONGITUDE RANGE FIX =====================
    % Convert data longitudes from 0–360 to -180–180
    if any(lon > 180)
        lon = lon - 360;
    end

    % Convert basin polygon longitudes to -180–180
    basinPolygon(:,1) = mod(basinPolygon(:,1) + 180, 360) - 180;

    % ===================== BUILD MESHGRID AND MASK =====================
    [Long, Lat] = meshgrid(lon, lat);
    Long = Long(:);
    Lat  = Lat(:);

    % Determine points inside the basin
    inBasin = inpolygon(Long, Lat, basinPolygon(:,1), basinPolygon(:,2));

    % ===================== VALIDATE AND FORMAT INPUT DATA =====================
    dataSize = size(data);

    if length(dataSize) == 3
        if dataSize(1) == numel(lon) && dataSize(2) == numel(lat)
            % Data arranged as lon–lat–time → convert to lat–lon–time
            data = permute(data, [2, 1, 3]);
        elseif dataSize(1) ~= numel(lat) || dataSize(2) ~= numel(lon)
            msgbox('Unexpected data dimensions. Ensure data matches lat, lon, and time.');
            return;
        end
    else
        msgbox('Data must be 3D with dimensions matching lat, lon, and time.');
        return;
    end

    % Reshape data into 2D (space × time)
    data2D = reshape(data, [], dataSize(3));

    % ===================== EXTRACT FINAL MASKED DATA =====================
    maskedData = data2D(inBasin, :);
end


        %% Helper method to apply a basin mask and also return lat/lon of points
       function [maskedData, latInBasin, lonInBasin] = applyBasinMaskWithCoords(app, data, lat, lon)
    % Applies a spatial mask for the selected basin and retains the timespan.
    % Returns:
    %   maskedData   -> time series inside basin [nPoints x nTime]
    %   latInBasin   -> latitudes of points inside basin
    %   lonInBasin   -> longitudes of points inside basin

    % ===================== BASIN SELECTION VALIDATION =====================
    selectedBasin = app.BasinSelectionButton.Value;

    if isempty(app.Basins)
        uialert(app.UIFigure, ...
            {'No basin shapefile data found.', ...
            'Please upload data before proceeding.'}, ...
            'Data Missing', 'Icon', 'warning');
        return;
    end

    if strcmpi(selectedBasin, 'Select Basin') || isempty(selectedBasin)
        uialert(app.UIFigure, ...
            {'Please select a basin from the dropdown before proceeding.'}, ...
            'No Basin Selected', 'Icon', 'warning');
        return;
    end

    % ===================== EXTRACT BASIN POLYGON =====================
    basins = app.Basins;
    basinPolygon = [];

    for i = 1:length(basins)
        if strcmpi(basins(i).RIVER_BASI, selectedBasin)
            basinPolygon = [basins(i).X', basins(i).Y'];
            break;
        end
    end

    if ~isempty(basinPolygon)
        basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);
    end

    if isempty(basinPolygon)
        uialert(app.UIFigure, ...
            {['The selected basin "', selectedBasin, '" is not available in the shapefile.'], ...
            'Please check your selection or upload the correct shapefile.'}, ...
            'Basin Not Found', 'Icon', 'warning');
        return;
    end

    % ===================== LONGITUDE RANGE FIX =====================
    % Convert model/data longitudes from 0–360 → −180–180
    if any(lon > 180)
        lon = lon - 360;
    end

    % Convert polygon longitudes to same range
    basinPolygon(:,1) = mod(basinPolygon(:,1) + 180, 360) - 180;

    % ===================== MESHGRID & POINT CLASSIFICATION =====================
    [Long, Lat] = meshgrid(lon, lat);
    Long = Long(:);
    Lat  = Lat(:);

    % Determine points inside polygon
    inBasin = inpolygon(Long, Lat, basinPolygon(:, 1), basinPolygon(:, 2));

    % Extract coordinates inside basin
    lonInBasin = Long(inBasin);
    latInBasin = Lat(inBasin);

    % ===================== DATA DIMENSION FIX =====================
    dataSize = size(data);

    if length(dataSize) == 3
        if dataSize(1) == numel(lon) && dataSize(2) == numel(lat)
            % lon-lat-time → convert to lat-lon-time
            data = permute(data, [2, 1, 3]);
        elseif dataSize(1) ~= numel(lat) || dataSize(2) ~= numel(lon)
            msgbox('Unexpected data dimensions. Ensure data matches lat, lon, and time.');
            return;
        end
    else
        msgbox('Data must be 3D with dimensions matching lat, lon, and time.');
        return;
    end

    % ===================== EXTRACT MASKED TIME SERIES =====================
    data2D = reshape(data, [], dataSize(3));
    maskedData = data2D(inBasin, :);
end

        % Helper ternary function (inline)
        function out = ternary(~,cond,a,b)
            if cond, out=a; else, out=b; end
        end

        function plotSpatialBasinMap(app, lonPts, latPts, values, basinPolygon, plotTitle)
            % % Get dropdown selection
            screenSize = get(0,'ScreenSize');

            % Design size (from your UIFigure in Design View)
            designWidth = 1212;
            designHeight = 764;

            % Compute scale factor relative to screen size
            scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            % 1. Define fine grid
            res = 0.025;
            lonRange = min(basinPolygon(:,1)):res:max(basinPolygon(:,1));
            latRange = min(basinPolygon(:,2)):res:max(basinPolygon(:,2));
            [LonGrid, LatGrid] = meshgrid(lonRange, latRange);

            % 2. Interpolate
            F = scatteredInterpolant(lonPts, latPts, values, 'natural','linear');
            GridVals = F(LonGrid, LatGrid);

            % 3. Mask outside
            inMask = inpolygon(LonGrid, LatGrid, basinPolygon(:,1), basinPolygon(:,2));
            GridVals(~inMask) = NaN;

            % 4. Plot
            cla(app.UIAxes);
            pcolor(app.UIAxes, LonGrid, LatGrid, GridVals);
            shading(app.UIAxes,'flat');

            baseColors = [...
                103 0   31;   % dark red
                178 24  43;   % medium red
                214 96  77;   % light red
                244 165 130;  % very light red
                247 247 247;  % white
                146 197 222;  % light blue
                67  147 195;  % medium blue
                33  102 172;  % darker blue
                5   48  97];  % darkest blue

            % Normalize to [0,1]
            baseColors = baseColors ./ 255;

            % --- Interpolate colormap ---
            x  = linspace(0, 1, size(baseColors,1));
            xi = linspace(0, 1, 256);
            cmap = interp1(x, baseColors, xi, 'linear');
            colormap(app.UIAxes, cmap);

            % --- Symmetric color axis ---
            cmin = min(values(:));
            cmax = max(values(:));
            cabs = max(abs([cmin cmax]));
            clim(app.UIAxes, [-cabs cabs]);

            % --- Colorbar below, centered ---
            c = colorbar(app.UIAxes,'southoutside');
            c.Label.String     = 'TWSA (mm/month)';
            c.Label.FontWeight = 'bold';
            c.Label.FontName   = 'Times New Roman';
            c.Label.FontSize   = 12;

            % Center the colorbar (adjust numbers if needed)
            cpos = c.Position;   % [x y w h]
            cpos(1) = 0.35;      % shift horizontally
            cpos(2) = cpos(2)-0.06;
            cpos(3) = 0.3;       % shrink width
            c.Position = cpos;


            % Labels & title
            xlabel(app.UIAxes,'Longitude','FontWeight','bold');
            ylabel(app.UIAxes,'Latitude','FontWeight','bold');
            %title(app.UIAxes, plotTitle,'FontWeight','bold');

            % Fix aspect
            axis(app.UIAxes,'equal','tight');

            % Overlay polygon
            hold(app.UIAxes,'on');
            plot(app.UIAxes, basinPolygon(:,1), basinPolygon(:,2), 'k-', 'LineWidth',2);
            hold(app.UIAxes,'off');

            % Format ticks (°E/°W, °N/°S)
            xticks = get(app.UIAxes,'XTick');
            yticks = get(app.UIAxes,'YTick');
            xticklabels = arrayfun(@(x) sprintf('%.1f°%s', abs(x), ternary(app,x>=0,'E','W')), xticks, 'UniformOutput', false);
            yticklabels = arrayfun(@(y) sprintf('%.1f°%s', abs(y), ternary(app,y>=0,'N','S')), yticks, 'UniformOutput', false);
            set(app.UIAxes,'XTickLabel',xticklabels,'YTickLabel',yticklabels);
            % ✅ Rotate Y-axis labels and set font
            %app.UIAxes.XAxis.TickLabelRotation = 90;         % rotate 90 degrees
            app.UIAxes.XAxis.FontName = 'Times New Roman';   % font style
            app.UIAxes.XAxis.FontSize = 12;                  % (optional) font size
            app.UIAxes.XAxis.FontWeight = 'normal';          % or 'bold' if you want bold
            % ✅ Rotate Y-axis labels and set font
            app.UIAxes.YAxis.TickLabelRotation = 90;         % rotate 90 degrees
            app.UIAxes.YAxis.FontName = 'Times New Roman';   % font style
            app.UIAxes.YAxis.FontSize = 12;                  % (optional) font size
            app.UIAxes.YAxis.FontWeight = 'normal';          % or 'bold' if you want bold
        end

        function plotSpatialBasinMapDynamic(app, lonPts, latPts, values, basinPolygon, plotTitle)
            % 1. Define fine grid
            res = 0.025;
            lonRange = min(basinPolygon(:,1)):res:max(basinPolygon(:,1));
            latRange = min(basinPolygon(:,2)):res:max(basinPolygon(:,2));
            [LonGrid, LatGrid] = meshgrid(lonRange, latRange);

            % 2. Interpolate scattered points
            F = scatteredInterpolant(lonPts, latPts, values, 'natural', 'linear');
            GridVals = F(LonGrid, LatGrid);

            % 3. Mask outside the basin
            inMask = inpolygon(LonGrid, LatGrid, basinPolygon(:,1), basinPolygon(:,2));
            GridVals(~inMask) = NaN;

            % 4. Plot on dynamic axes
            cla(app.DynamicAxes);
            pcolor(app.DynamicAxes, LonGrid, LatGrid, GridVals);
            shading(app.DynamicAxes, 'flat');

            % 5. Custom colormap
            baseColors = [...
                103 0   31;   % dark red
                178 24  43;   % medium red
                214 96  77;   % light red
                244 165 130;  % very light red
                247 247 247;  % white
                146 197 222;  % light blue
                67  147 195;  % medium blue
                33  102 172;  % darker blue
                5   48  97];  % darkest blue

            baseColors = baseColors ./ 255;
            x  = linspace(0, 1, size(baseColors,1));
            xi = linspace(0, 1, 256);
            cmap = interp1(x, baseColors, xi, 'linear');
            colormap(app.DynamicAxes, cmap);

            % 6. Symmetric color axis
            cmin = min(values(:));
            cmax = max(values(:));
            cabs = max(abs([cmin cmax]));
            clim(app.DynamicAxes, [-cabs cabs]);

            % 7. Colorbar
            c = colorbar(app.DynamicAxes, 'southoutside');
            c.Label.String = 'TWSA (mm/month)';
            c.Label.FontWeight = 'bold';
            c.Label.FontName   = 'Times New Roman';
            c.Label.FontSize   = 12;
            % Adjust position
            cpos = c.Position;
            cpos(1) = 0.35;
            cpos(2) = cpos(2)-0.06;
            cpos(3) = 0.3;
            c.Position = cpos;

            % 8. Labels & title
            xlabel(app.DynamicAxes, 'Longitude');
            ylabel(app.DynamicAxes, 'Latitude');
            title(app.DynamicAxes, plotTitle);

            % 9. Grid & aspect
            grid(app.DynamicAxes, 'on');
            axis(app.DynamicAxes, 'equal');

            % 10. Overlay polygon
            hold(app.DynamicAxes, 'on');
            plot(app.DynamicAxes, basinPolygon(:,1), basinPolygon(:,2), 'k-', 'LineWidth', 2);
            hold(app.DynamicAxes, 'off');

            % 11. Tick labels formatting
            xticks = get(app.DynamicAxes,'XTick');
            yticks = get(app.DynamicAxes,'YTick');
            xticklabels = arrayfun(@(x) sprintf('%.1f°%s', abs(x), ternary(app,x>=0,'E','W')), xticks, 'UniformOutput', false);
            yticklabels = arrayfun(@(y) sprintf('%.1f°%s', abs(y), ternary(app,y>=0,'N','S')), yticks, 'UniformOutput', false);
            set(app.DynamicAxes,'XTickLabel',xticklabels,'YTickLabel',yticklabels);

            app.DynamicAxes.XAxis.TickLabelRotation = 0;  % optional
            app.DynamicAxes.XAxis.FontName = 'Times New Roman';
            app.DynamicAxes.XAxis.FontSize = 12;
            app.DynamicAxes.XAxis.FontWeight = 'normal';
            app.DynamicAxes.YAxis.TickLabelRotation = 90;
            app.DynamicAxes.YAxis.FontName = 'Times New Roman';
            app.DynamicAxes.YAxis.FontSize = 12;
            app.DynamicAxes.YAxis.FontWeight = 'normal';
        end
        %% ==================== HELPER FUNCTIONS ====================
        % function annualData = convertMonthlyToAnnual(app,monthlyData, timeIndices, startYr, selectedTimeRange)
        %     % Convert monthly data to annual means for the selected time range
        %     if isempty(monthlyData) || isempty(timeIndices)
        %         annualData = [];
        %         return;
        %     end
        %
        %     if ndims(monthlyData) == 2
        %         nPoints = size(monthlyData, 1);
        %     elseif ndims(monthlyData) == 3
        %         nPoints = size(monthlyData, 1) * size(monthlyData, 2);
        %     end
        %     nTime = 1500;
        %
        %     % Create full monthly time series
        %     fullMonthly = nan(nPoints, nTime);
        %     fullMonthly(:, timeIndices) = monthlyData;
        %
        %     % Calculate annual means
        %     nYears = 125; % 1901-2025
        %     annualData = nan(nPoints, nYears);
        %
        %     for yr = 1:nYears
        %         startMonth = (yr-1)*12 + 1;
        %         endMonth = yr*12;
        %         if endMonth <= size(fullMonthly, 2)
        %             yearData = fullMonthly(:, startMonth:endMonth);
        %             annualData(:, yr) = mean(yearData, 2, 'omitnan');
        %         end
        %     end
        %
        %     % Filter for selected time range
        %     startYearIdx = selectedTimeRange(1) - startYr + 1;
        %     endYearIdx = selectedTimeRange(end) - startYr + 1;
        %
        %     if startYearIdx >= 1 && endYearIdx <= size(annualData, 2)
        %         annualData = annualData(:, startYearIdx:endYearIdx);
        %     else
        %         annualData = [];
        %     end
        % end

        function [trendLine, slope, intercept, r2, pval] = calculateTrend(app, x, y, method)
            % Calculate trend line and statistics based on selected method
            trendLine = [];
            slope = NaN;
            intercept = NaN;
            r2 = NaN;
            pval = NaN;

            n = length(x);
            if n < 2
                return;
            end

            switch method
                case 'linear'
                    % Linear regression
                    p = polyfit(x, y, 1);
                    slope = p(1);
                    intercept = p(2);
                    trendLine = polyval(p, x);

                case 'polynomial'
                    % Polynomial fit (2nd degree)
                    p = polyfit(x, y, 2);
                    trendLine = polyval(p, x);
                    % For polynomial, use linear slope for consistency in reporting
                    p_linear = polyfit(x, y, 1);
                    slope = p_linear(1);
                    intercept = p_linear(2);

                case 'loess'
                    % LOESS smoothing
                    span = 0.5; % Span parameter
                    if length(x) > 5
                        trendLine = smooth(x, y, span, 'loess');
                        % Calculate linear trend of the LOESS curve for slope
                        p_linear = polyfit(x, trendLine, 1);
                        slope = p_linear(1);
                        intercept = p_linear(2);
                    else
                        % Fallback to linear for small datasets
                        p = polyfit(x, y, 1);
                        slope = p(1);
                        intercept = p(2);
                        trendLine = polyval(p, x);
                    end

                case 'sens slope'
                    % Sen's slope (non-parametric)
                    slopes = zeros(n*(n-1)/2, 1);
                    k = 0;

                    for i = 1:n-1
                        for j = i+1:n
                            k = k + 1;
                            slopes(k) = (y(j) - y(i)) / (x(j) - x(i));
                        end
                    end

                    % Sen's slope is the median of all slopes
                    slope = median(slopes, 'omitnan');

                    % Calculate intercept using median of y - slope*x
                    intercepts = y - slope * x;
                    intercept = median(intercepts, 'omitnan');

                    trendLine = slope * x + intercept;
            end

            % Calculate R-squared and p-value (for all methods)
            if ~isempty(trendLine)
                % R-squared calculation
                y_fit = trendLine;
                y_mean = mean(y);
                ss_res = sum((y - y_fit).^2);
                ss_tot = sum((y - y_mean).^2);
                if ss_tot > 0
                    r2 = 1 - (ss_res / ss_tot);
                else
                    r2 = NaN;
                end

                % p-value calculation (using linear model for consistency)
                if n > 2
                    [~, ~, ~, ~, stats] = regress(y(:), [ones(length(x), 1), x(:)]);
                    if ~isempty(stats)
                        pval = stats(3);
                    else
                        pval = NaN;
                    end
                else
                    pval = NaN;
                end
            end
        end

        function [y1_aligned, y2_aligned] = alignData(app,y1, y2)
            % Align two datasets by removing time points where either has NaN
            validMask = ~isnan(y1) & ~isnan(y2);
            y1_aligned = y1(validMask);
            y2_aligned = y2(validMask);
        end

        function cc = calculateCorrelationCoefficient(app,y1, y2)
            % Calculate correlation coefficient
            if length(y1) < 2
                cc = NaN;
                return;
            end
            R = corrcoef(y1, y2);
            cc = R(1,2);
        end

        function nse = calculateNSE(app,observed, simulated)
            % Calculate Nash-Sutcliffe Efficiency
            % NSE = 1 - (sum((observed - simulated)^2) / sum((observed - mean(observed))^2))
            if length(observed) < 2
                nse = NaN;
                return;
            end
            numerator = sum((observed - simulated).^2);
            denominator = sum((observed - mean(observed)).^2);

            if denominator == 0
                nse = NaN;
            else
                nse = 1 - (numerator / denominator);
            end
        end

        % --- Example RMSE function ---
        function rmse = calculateRMSE(app,observed, simulated)
            % Calculate Root Mean Square Error with robust error handling

            % Input validation
            if isempty(observed) || isempty(simulated)
                rmse = NaN;
                return;
            end

            % Ensure both inputs are vectors of the same size
            observed = observed(:);
            simulated = simulated(:);

            if length(observed) ~= length(simulated)
                warning('Observed and simulated data have different lengths. Using common valid indices.');
                % Find common non-NaN indices
                valid_obs = ~isnan(observed);
                valid_sim = ~isnan(simulated);
                valid_idx = valid_obs & valid_sim;

                if sum(valid_idx) < 1
                    rmse = NaN;
                    return;
                end

                observed = observed(valid_idx);
                simulated = simulated(valid_idx);
            else
                % Remove NaN pairs
                valid_idx = ~isnan(observed) & ~isnan(simulated);
                if sum(valid_idx) < 1
                    rmse = NaN;
                    return;
                end
                observed = observed(valid_idx);
                simulated = simulated(valid_idx);
            end

            % Check if we have enough data points
            if length(observed) < 2
                rmse = NaN;
                return;
            end

            try
                % Calculate RMSE
                squared_errors = (observed - simulated).^2;
                mean_squared_error = mean(squared_errors, 'omitnan');
                rmse = sqrt(mean_squared_error);
            catch ME
                warning(ME.identifier,'Error calculating RMSE: %s', ME.message);
                rmse = NaN;
            end
        end

        function [features, target, valid_time] = createLaggedFeatures2D(app, gldas_vars, tws_data, time_data, lag_months, n_points)
            % Create lagged features for 2D data - CORRECTED VERSION
            % Features from months t-lag_months to t-1 predict target at month t
            % gldas_vars: cell array where each variable is [n_time x n_points] or [n_time x 1]
            % tws_data: [n_time x n_points] or [n_time x 1]

            n_time = size(gldas_vars{1}, 1);
            n_vars = length(gldas_vars);

            % Initialize feature matrix (ONLY lagged features, no current month)
            features = zeros(n_time - lag_months, n_vars * lag_months);

            if ~isempty(tws_data)
                target = zeros(n_time - lag_months, n_points);
            else
                target = [];
            end

            % Create lagged features (ONLY from previous months)
            for t = (lag_months + 1):n_time
                feature_idx = 1;
                for var_idx = 1:n_vars
                    for lag = 1:lag_months  % Only previous months, no current month
                        time_idx = t - lag; % This ensures features PRECEDE target

                        if time_idx >= 1 && time_idx <= size(gldas_vars{var_idx}, 1)
                            if n_points == 1
                                % Basin average: data is [n_time x 1]
                                features(t - lag_months, feature_idx) = gldas_vars{var_idx}(time_idx, 1);
                            else
                                % Individual points: data is [n_time x n_points]
                                % For feature creation, we need to handle each point separately
                                % This function should be called for each point individually
                                features(t - lag_months, feature_idx) = gldas_vars{var_idx}(time_idx, 1);
                            end
                        else
                            features(t - lag_months, feature_idx) = NaN;
                        end
                        feature_idx = feature_idx + 1;
                    end
                end

                % Target (current month TWSA)
                if ~isempty(tws_data) && t <= size(tws_data, 1)
                    if n_points == 1
                        target(t - lag_months, :) = tws_data(t, :);
                    else
                        target(t - lag_months, :) = tws_data(t, :);
                    end
                end
            end

            valid_time = time_data(lag_months + 1:end);

            % === VALIDATION CHECK ===
            fprintf('Lagged features created: %d samples, %d features\n', size(features, 1), size(features, 2));
            fprintf('Features cover months t-%d to t-1\n', lag_months);
            fprintf('Target is at month t\n');

            % Additional diagnostic
            if ~isempty(target) && size(features, 1) > 0
                sample_idx = min(3, size(features, 1));
                fprintf('Sample - Features t-2: %.3f, t-1: %.3f | Target t: %.3f\n', ...
                    features(sample_idx, 1), features(sample_idx, 2), target(sample_idx, 1));
            end
        end
        function shap_results = performSHAPanalysis2D(app, rf_models, X_train, y_train, feature_names, n_points)
            % Perform SHAP analysis for 2D data - CORRECTED VERSION

            shap_results = cell(1, n_points);

            try
                % Check if Python is available
                try
                    py.importlib.import_module('numpy');
                    py.importlib.import_module('shap');
                    py.importlib.import_module('sklearn.ensemble');
                    python_available = true;
                catch
                    python_available = false;
                    fprintf('Python not available for SHAP, using MATLAB feature importance instead\n');
                end

                if python_available
                    % Standardize training features for SHAP (interpretability only)
                    X_train_scaled = X_train;
                    if ~isempty(X_train)
                        X_train_scaled = (X_train - mean(X_train, 1, 'omitnan')) ./ std(X_train, 0, 1, 'omitnan');
                        % Replace NaN std with 1 to avoid division issues
                        s = std(X_train, 0, 1, 'omitnan');
                        s(s==0 | isnan(s)) = 1;
                        X_train_scaled = (X_train - mean(X_train, 1, 'omitnan')) ./ s;
                    end

                    for point_idx = 1:n_points
                        if ~isempty(rf_models{point_idx})
                            % Get valid data for this point
                            valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train_scaled), 2);

                            if sum(valid_train) > 10
                                X_train_point = X_train_scaled(valid_train, :);
                                y_train_point = y_train(valid_train, point_idx);

                                try
                                    % Convert to Python arrays
                                    X_train_py = py.numpy.array(double(X_train_point));
                                    y_train_py = py.numpy.array(double(y_train_point));

                                    % Retrain a Python RF with similar config for SHAP analysis
                                    rf_py = py.sklearn.ensemble.RandomForestRegressor(...
                                        pyargs('n_estimators', int32(100), ...
                                        'random_state', int32(42), ...
                                        'max_depth', int32(10)));

                                    rf_py.fit(X_train_py, y_train_py);

                                    % Calculate SHAP values
                                    explainer = py.shap.TreeExplainer(rf_py);
                                    shap_vals = explainer.shap_values(X_train_py);

                                    % Convert to MATLAB array
                                    shap_values_mat = double(py.numpy.array(shap_vals));

                                    % Take mean absolute SHAP per feature
                                    mean_abs_shap = mean(abs(shap_values_mat), 1);
                                    shap_results{point_idx} = mean_abs_shap;
                                catch
                                    % Fallback to MATLAB feature importance
                                    if ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)
                                        shap_results{point_idx} = rf_models{point_idx}.OOBPermutedPredictorDeltaError;
                                    else
                                        shap_results{point_idx} = [];
                                    end
                                end
                            else
                                shap_results{point_idx} = [];
                            end
                        else
                            shap_results{point_idx} = [];
                        end

                        if mod(point_idx, 10) == 0
                            fprintf('SHAP analysis completed for %d/%d points\n', point_idx, n_points);
                        end
                    end
                else
                    % Use MATLAB feature importance as fallback
                    for point_idx = 1:n_points
                        if ~isempty(rf_models{point_idx}) && ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)
                            shap_results{point_idx} = rf_models{point_idx}.OOBPermutedPredictorDeltaError;
                        else
                            shap_results{point_idx} = [];
                        end
                    end
                end

            catch MEshap
                fprintf('SHAP analysis failed: %s\n', MEshap.message);
                % Fallback to MATLAB feature importance
                for point_idx = 1:n_points
                    if ~isempty(rf_models{point_idx}) && ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)
                        shap_results{point_idx} = rf_models{point_idx}.OOBPermutedPredictorDeltaError;
                    else
                        shap_results{point_idx} = [];
                    end
                end
            end
        end

        function saveReconstructedTWSToExcel2D(app, reconstructed_tws, time_data, point_names, reconstructionType, lat_masked, lon_masked)
            % Save reconstructed TWSA to Excel - CORRECTED VERSION

            [n_time, n_points] = size(reconstructed_tws);

            if strcmp(reconstructionType, 'all_points')
                % For all points in the basin - create separate tables for coordinates and data
                excel_data = table();

                % Add coordinate columns
                excel_data.Latitude = lat_masked(:);
                excel_data.Longitude = lon_masked(:);

                % Add point names
                excel_data.PointName = point_names(:);

                % Add mean reconstructed TWSA across time
                excel_data.Mean_TWSA = mean(reconstructed_tws, 1, 'omitnan')';

                % Save coordinates table
                writetable(excel_data, 'Reconstructed_TWSA_RF_AllPoints_Coordinates.xlsx');

                % Save time series data separately
                time_series_data = table();
                time_series_data.Time = time_data;

                for point_idx = 1:n_points
                    time_series_data.(point_names{point_idx}) = reconstructed_tws(:, point_idx);
                end

                writetable(time_series_data, 'Reconstructed_TWSA_RF_AllPoints_TimeSeries.xlsx');

                fprintf('Reconstructed TWSA saved to separate coordinate and timeseries files\n');

            else
                % For basin average
                excel_data = table();
                excel_data.Time = time_data;
                excel_data.Basin_Average_TWSA = reconstructed_tws;

                % Add statistics
                excel_data.Month = month(time_data);
                excel_data.Year = year(time_data);

                filename = 'Reconstructed_TWSA_RF_BasinAverage.xlsx';
                writetable(excel_data, filename);
                fprintf('Reconstructed TWSA saved to %s\n', filename);
            end
        end

        function saveSHAPToExcel2D(app, shap_results, feature_names, point_names, reconstructionType)
            % Save SHAP analysis results to Excel - CORRECTED VERSION

            if isempty(shap_results) || all(cellfun(@isempty, shap_results))
                fprintf('No SHAP results to save\n');
                return;
            end

            % Determine number of features from first non-empty result
            n_features = 0;
            for i = 1:length(shap_results)
                if ~isempty(shap_results{i})
                    n_features = length(shap_results{i});
                    break;
                end
            end

            if n_features == 0
                fprintf('No valid SHAP results found\n');
                return;
            end

            % Create feature names with lags (assuming 2 months lag)
            full_feature_names = {};
            lag_months = 2;
            for lag = 1:lag_months  % Note: starting from 1, not 0
                for i = 1:length(feature_names)
                    full_feature_names{end+1} = sprintf('%s_lag%d', feature_names{i}, lag);
                end
            end

            % Ensure we don't exceed available features
            if length(full_feature_names) > n_features
                full_feature_names = full_feature_names(1:n_features);
            elseif length(full_feature_names) < n_features
                % Add generic names for extra features
                for i = (length(full_feature_names)+1):n_features
                    full_feature_names{end+1} = sprintf('Feature_%d', i);
                end
            end

            % Create SHAP data table
            shap_data = table();
            shap_data.Point = point_names';

            for f = 1:n_features
                % Extract SHAP values for this feature across all points
                shap_values = zeros(length(point_names), 1);
                for p = 1:length(point_names)
                    if ~isempty(shap_results{p}) && length(shap_results{p}) >= f
                        shap_values(p) = shap_results{p}(f);
                    else
                        shap_values(p) = NaN;
                    end
                end
                shap_data.(full_feature_names{f}) = shap_values;
            end

            if strcmp(reconstructionType, 'basin_average')
                filename = 'SHAP_Analysis_Results_BasinAverage.xlsx';
            else
                filename = 'SHAP_Analysis_Results_AllPoints.xlsx';
            end

            writetable(shap_data, filename);
            fprintf('SHAP analysis results saved to %s\n', filename);
        end

        function [train_rmse, test_rmse, train_corr, test_corr] = calculatePerformanceMetrics2D(~, y_train, y_pred_train, y_test, y_pred_test)
            % Calculate performance metrics for 2D data - CORRECTED VERSION

            n_points = size(y_train, 2);
            train_rmse = zeros(1, n_points);
            test_rmse = zeros(1, n_points);
            train_corr = zeros(1, n_points);
            test_corr = zeros(1, n_points);

            for point_idx = 1:n_points
                % Training metrics
                valid_train = ~isnan(y_train(:, point_idx)) & ~isnan(y_pred_train(:, point_idx));
                if sum(valid_train) > 2
                    train_actual = y_train(valid_train, point_idx);
                    train_pred = y_pred_train(valid_train, point_idx);

                    train_rmse(point_idx) = sqrt(mean((train_actual - train_pred).^2));

                    % Correlation with safety check
                    if length(train_actual) > 1 && ~all(train_actual == train_actual(1))
                        corr_matrix = corrcoef(train_actual, train_pred, 'Rows', 'complete');
                        if all(size(corr_matrix) == [2, 2]) && ~any(isnan(corr_matrix(:)))
                            train_corr(point_idx) = corr_matrix(1, 2);
                        else
                            train_corr(point_idx) = NaN;
                        end
                    else
                        train_corr(point_idx) = NaN;
                    end
                else
                    train_rmse(point_idx) = NaN;
                    train_corr(point_idx) = NaN;
                end

                % Testing metrics
                valid_test = ~isnan(y_test(:, point_idx)) & ~isnan(y_pred_test(:, point_idx));
                if sum(valid_test) > 2
                    test_actual = y_test(valid_test, point_idx);
                    test_pred = y_pred_test(valid_test, point_idx);

                    test_rmse(point_idx) = sqrt(mean((test_actual - test_pred).^2));

                    % Correlation with safety check
                    if length(test_actual) > 1 && ~all(test_actual == test_actual(1))
                        corr_matrix = corrcoef(test_actual, test_pred, 'Rows', 'complete');
                        if all(size(corr_matrix) == [2, 2]) && ~any(isnan(corr_matrix(:)))
                            test_corr(point_idx) = corr_matrix(1, 2);
                        else
                            test_corr(point_idx) = NaN;
                        end
                    else
                        test_corr(point_idx) = NaN;
                    end
                else
                    test_rmse(point_idx) = NaN;
                    test_corr(point_idx) = NaN;
                end
            end

            % Display summary statistics
            fprintf('=== PERFORMANCE SUMMARY ===\n');
            fprintf('Training - Mean RMSE: %.4f, Mean Correlation: %.4f\n', ...
                nanmean(train_rmse), nanmean(train_corr));
            fprintf('Testing  - Mean RMSE: %.4f, Mean Correlation: %.4f\n', ...
                nanmean(test_rmse), nanmean(test_corr));

            % Warn if testing correlation is poor
            if nanmean(test_corr) < 0.3
                fprintf('WARNING: Testing correlation is low. Model may not be generalizing well.\n');
            end
        end
        %% Reconstruction
        function reconstructTWSWithRF(app, reconstructionType)
            % reconstructionType: 'basin_average' or 'all_points'
            % CORRECTED VERSION with proper TreeBagger parameters

            try
                % ==================== BASIN MASKING ====================
                fprintf('Applying basin mask...\n');

                % Apply basin mask to JPL TWSA data
                [jpl_tws_masked, lat_masked, lon_masked] = app.applyBasinMaskWithCoords( ...
                    app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);

                % Apply basin mask to GLDAS variables
                gldas_vars_masked = {};
                gldas_data_vars = {app.GLDAS_PData, app.GLDAS_ETData, app.GLDAS_SMData, ...
                    app.GLDAS_TData, app.GLDAS_WSData, app.GLDAS_SHData, ...
                    app.GLDAS_LWData, app.GLDAS_SWData};
                gldas_var_names = {'P', 'ET', 'SM', 'T', 'WS', 'SH', 'LW', 'SW'};

                for i = 1:length(gldas_data_vars)
                    [gldas_var_masked, ~, ~] = app.applyBasinMaskWithCoords( ...
                        gldas_data_vars{i}, app.GLDAS_PLat, app.GLDAS_PLon);
                    gldas_vars_masked{i} = gldas_var_masked;
                end

                fprintf('Basin masking completed. Points in basin: %d\n', size(jpl_tws_masked, 1));

                % ==================== DATA PREPROCESSING ====================
                jpl_tws_masked = jpl_tws_masked * 10; % scale

                % Get common time period (2003–2024)
                jpl_time_years = year(app.JPLTWSTime);
                gldas_time_years = year(app.GLDAS_PTime);

                jpl_start_idx = find(jpl_time_years >= 2003, 1);
                jpl_end_idx = find(jpl_time_years <= 2024, 1, 'last');
                gldas_start_idx = find(gldas_time_years >= 2003, 1);
                gldas_end_idx = find(gldas_time_years <= 2024, 1, 'last');

                if isempty(jpl_start_idx) || isempty(jpl_end_idx)
                    error('No JPL data found in period 2003–2024');
                end
                if isempty(gldas_start_idx) || isempty(gldas_end_idx)
                    error('No GLDAS data found in period 2003–2024');
                end

                common_time = app.JPLTWSTime(jpl_start_idx:jpl_end_idx);
                jpl_tws_common = jpl_tws_masked(:, jpl_start_idx:jpl_end_idx)';

                gldas_vars_common = cell(length(gldas_vars_masked), 1);
                for i = 1:length(gldas_vars_masked)
                    gldas_vars_common{i} = gldas_vars_masked{i}(:, gldas_start_idx:gldas_end_idx)';
                end

                n_points = size(jpl_tws_common, 2);
                point_names = arrayfun(@(i) sprintf('Lat_%.3f_Lon_%.3f', ...
                    lat_masked(i), lon_masked(i)), 1:n_points, 'UniformOutput', false);

                % ==================== BASIN AVERAGE / ALL POINTS ====================
                if strcmp(reconstructionType, 'basin_average')
                    fprintf('Calculating basin average...\n');
                    jpl_tws_2d = mean(jpl_tws_common, 2, 'omitnan');
                    gldas_vars_2d = cellfun(@(x) mean(x, 2, 'omitnan'), gldas_vars_common, 'UniformOutput', false);
                    n_points = 1;
                    point_names = {'Basin_Average'};
                else
                    fprintf('Processing all points within basin...\n');
                    jpl_tws_2d = jpl_tws_common;
                    gldas_vars_2d = gldas_vars_common;
                end

                fprintf('Data prepared: %d time steps, %d points\n', length(common_time), n_points);

                % ==================== CREATE LAGGED FEATURES ====================
                lag_months = 2;
                [features, target, valid_time] = app.createLaggedFeatures2D( ...
                    gldas_vars_2d, jpl_tws_2d, common_time, lag_months, n_points);

                % Enhanced temporal alignment check
                fprintf('=== TEMPORAL ALIGNMENT VERIFICATION ===\n');
                if size(features, 1) > 10 && size(target, 1) > 10
                    for lag_check = 1:3
                        if size(features, 1) > lag_check
                            valid_idx = ~isnan(features(:,1)) & ~isnan(target(:,1));
                            if sum(valid_idx) > 10
                                if lag_check == 1
                                    % This is the correct alignment (features lead by 1 month)
                                    r_correct = corr(features(1:end-1,1), target(2:end,1), 'rows','complete');
                                    fprintf('Correct alignment (features lead by 1 month): r = %.3f\n', r_correct);
                                else
                                    % Other alignments for comparison
                                    r_other = corr(features(1:end-lag_check+1,1), target(lag_check:end,1), 'rows','complete');
                                    fprintf('Features lead by %d months: r = %.3f\n', lag_check, r_other);
                                end
                            end
                        end
                    end
                end

                % Data cleaning
                [unique_time, unique_idx] = unique(valid_time);
                features = features(unique_idx, :);
                target = target(unique_idx, :);
                valid_time = unique_time;

                nan_mask = any(isnan(target), 2) | any(isnan(features), 2);
                features = features(~nan_mask, :);
                target = target(~nan_mask, :);
                valid_time = valid_time(~nan_mask);

                fprintf('After cleaning: %d samples\n', size(features, 1));

                % ==================== TRAIN-TEST SPLIT ====================
                valid_time_years = year(valid_time);
                train_mask = valid_time_years >= 2003 & valid_time_years <= 2016;
                test_mask = valid_time_years >= 2019 & valid_time_years <= 2024;

                X_train = features(train_mask, :);
                X_test = features(test_mask, :);
                y_train = target(train_mask, :);
                y_test = target(test_mask, :);
                train_time = valid_time(train_mask);
                test_time = valid_time(test_mask);

                fprintf('Training samples: %d, Testing samples: %d\n', size(X_train, 1), size(X_test, 1));

                % ==================== RANDOM FOREST TRAINING ====================
                [n_train, ~] = size(y_train);
                [n_test, ~] = size(y_test);
                y_pred_train = zeros(n_train, n_points);
                y_pred_test = zeros(n_test, n_points);
                full_predictions = zeros(size(features, 1), n_points);

                rf_models = cell(1, n_points);
                feature_importance = zeros(n_points, size(X_train, 2));

                fprintf('Training Random Forest models...\n');

                rng(42); % reproducibility

                % Calculate number of features for sampling
                n_features = size(X_train, 2);
                num_vars_to_sample = max(1, floor(sqrt(n_features))); % Use sqrt(n_features) as integer

                for point_idx = 1:n_points
                    y_train_point = y_train(:, point_idx);
                    y_test_point = y_test(:, point_idx);

                    % Ensure rows with NaN in predictors are excluded
                    valid_train = ~isnan(y_train_point) & ~any(isnan(X_train), 2);
                    valid_test = ~isnan(y_test_point) & ~any(isnan(X_test), 2);

                    if sum(valid_train) > 20  % Increased minimum sample requirement
                        % CORRECTED RF parameters - fixed NumPredictorsToSample
                        rf_model = TreeBagger(100, X_train(valid_train,:), y_train_point(valid_train), ...
                            'Method', 'regression', ...
                            'MinLeafSize', 15, ...      % Increased for regularization
                            'MaxNumSplits', 8, ...      % Reduced to prevent overfitting
                            'NumPredictorsToSample', num_vars_to_sample, ... % CORRECTED: integer value
                            'OOBPrediction', 'on', ...
                            'OOBPredictorImportance', 'on', ...
                            'Reproducible', true);

                        rf_models{point_idx} = rf_model;

                        % Predictions
                        y_pred_train(valid_train, point_idx) = predict(rf_model, X_train(valid_train, :));
                        if sum(valid_test) > 0
                            y_pred_test(valid_test, point_idx) = predict(rf_model, X_test(valid_test, :));
                        end

                        % Full period predictions
                        valid_all = ~any(isnan(features), 2);
                        full_predictions(valid_all, point_idx) = predict(rf_model, features(valid_all, :));

                        % Feature importance
                        if ~isempty(rf_model.OOBPermutedPredictorDeltaError)
                            feature_importance(point_idx, :) = rf_model.OOBPermutedPredictorDeltaError;
                        end

                        % Monitor OOB error
                        oob_error = oobError(rf_model);
                        fprintf('Point %d: Final OOB Error = %.4f\n', point_idx, oob_error(end));

                    else
                        fprintf('Warning: insufficient samples for point %d (%d samples)\n', point_idx, sum(valid_train));
                        rf_models{point_idx} = [];
                    end

                    if mod(point_idx, 10) == 0
                        fprintf('Completed %d/%d points\n', point_idx, n_points);
                    end
                end

                % ==================== FULL RECONSTRUCTION ====================
                fprintf('Starting full reconstruction (1948–2024)...\n');

                full_time_mask = year(app.GLDAS_PTime) >= 1948 & year(app.GLDAS_PTime) <= 2024;
                full_time = app.GLDAS_PTime(full_time_mask);

                full_gldas_vars = {};
                full_gldas_data = {app.GLDAS_PData, app.GLDAS_ETData, app.GLDAS_SMData, ...
                    app.GLDAS_TData, app.GLDAS_WSData, app.GLDAS_SHData, ...
                    app.GLDAS_LWData, app.GLDAS_SWData};

                for i = 1:length(full_gldas_data)
                    [gldas_var_masked, ~, ~] = app.applyBasinMaskWithCoords( ...
                        full_gldas_data{i}, app.GLDAS_PLat, app.GLDAS_PLon);
                    full_gldas_vars{i} = gldas_var_masked(:, full_time_mask)';
                end

                if strcmp(reconstructionType, 'basin_average')
                    full_gldas_vars_2d = cellfun(@(x) mean(x, 2, 'omitnan'), full_gldas_vars, 'UniformOutput', false);
                else
                    full_gldas_vars_2d = full_gldas_vars;
                end

                [full_features, ~, full_valid_time] = app.createLaggedFeatures2D( ...
                    full_gldas_vars_2d, [], full_time, lag_months, n_points);

                n_full = size(full_features, 1);
                reconstructed_tws = zeros(n_full, n_points);
                fprintf('Reconstructing for %d time steps...\n', n_full);

                for point_idx = 1:n_points
                    if ~isempty(rf_models{point_idx})
                        valid_full = ~any(isnan(full_features), 2);
                        if sum(valid_full) > 0
                            reconstructed_tws(valid_full, point_idx) = predict(rf_models{point_idx}, full_features(valid_full, :));
                        end
                    else
                        reconstructed_tws(:, point_idx) = NaN;
                    end

                    if mod(point_idx, 10) == 0
                        fprintf('Reconstructed %d/%d points\n', point_idx, n_points);
                    end
                end

                % ==================== SHAP ANALYSIS ====================
                fprintf('Performing SHAP analysis...\n');
                shap_results = app.performSHAPanalysis2D(rf_models, X_train, y_train, gldas_var_names, n_points);

                % ==================== SAVE RESULTS ====================
                fprintf('Saving results...\n');
                app.saveReconstructedTWSToExcel2D(reconstructed_tws, full_valid_time, point_names, reconstructionType, lat_masked, lon_masked);
                app.saveSHAPToExcel2D(shap_results, gldas_var_names, point_names, reconstructionType);

                % ==================== DISPLAY RESULTS ====================
                [train_rmse, test_rmse, train_corr, test_corr] = app.calculatePerformanceMetrics2D( ...
                    y_train, y_pred_train, y_test, y_pred_test);

                fprintf('=== FINAL RECONSTRUCTION RESULTS ===\n');
                fprintf('Training RMSE: %.4f ± %.4f\n', nanmean(train_rmse), nanstd(train_rmse));
                fprintf('Testing RMSE: %.4f ± %.4f\n', nanmean(test_rmse), nanstd(test_rmse));
                fprintf('Training Correlation: %.4f ± %.4f\n', nanmean(train_corr), nanstd(train_corr));
                fprintf('Testing Correlation: %.4f ± %.4f\n', nanmean(test_corr), nanstd(test_corr));
                fprintf('Number of points in basin: %d\n', length(lat_masked));

                % Success rate
                valid_test_corr = test_corr(~isnan(test_corr));
                if ~isempty(valid_test_corr)
                    success_rate = sum(valid_test_corr > 0.3) / length(valid_test_corr) * 100;
                    fprintf('Success rate (testing correlation > 0.3): %.1f%%\n', success_rate);
                end

                uialert(app.UIFigure, sprintf('TWSA Reconstruction Completed! Testing correlation: %.3f', nanmean(test_corr)), 'Success', 'Icon', 'success');

            catch ME
                fprintf('Error in TWSA reconstruction: %s\n', ME.message);
                for i = 1:length(ME.stack)
                    fprintf('  File: %s, Function: %s, Line: %d\n', ...
                        ME.stack(i).file, ME.stack(i).name, ME.stack(i).line);
                end
                uialert(app.UIFigure, sprintf('Reconstruction Failed: %s', ME.message), 'Error', 'Icon', 'error');
            end
        end

        % % Callback when the user selects a different plot type
        % function PlotTypeDropDownValueChanged(app, event)
        %     value = app.PlotTypeDropDown.Value;
        %     try
        %         switch value
        %             case 'Temporal'
        %                 plotTemporal(app);
        %             case 'Spatial'
        %                 plotSpatial(app);
        %         end
        %     catch ME
        %         uialert(app.UIFigure, ME.message, 'Plotting Error');
        %     end
        % end

        % Callback when the user checks/unchecks nodes in the tree
        % function validation1NodeChecked(app, event)
        %     % Replot based on current plot type
        %     try
        %         switch app.PlotTypeDropDown.Value
        %             case 'Temporal'
        %                 plotTemporal(app);
        %             case 'Spatial'
        %                 plotSpatial(app);
        %         end
        %     catch ME
        %         uialert(app.UIFigure, ME.message, 'Plotting Error');
        %     end
        % end

        function validation1Clicked(app)
            % Delete dynamic axes if exists
            if ~isempty(app.DynamicAxes) && isvalid(app.DynamicAxes)
                delete(app.DynamicAxes);
                %app.DynamicAxes = []; % Clear the reference
            end

            % Restore main axes to original position if we have it stored
            if ~isempty(app.MainAxesOriginalPosition)
                currentUnits = app.UIAxes.Units;
                app.UIAxes.Units = app.MainAxesOriginalUnits;
                app.UIAxes.Position = app.MainAxesOriginalPosition;
                app.UIAxes.Units = currentUnits;
            end
        end

        function createDynamicAxes(app)
            % Store original position ONLY if not already stored
            if isempty(app.MainAxesOriginalPosition)
                app.MainAxesOriginalPosition = app.UIAxes.Position;
                app.MainAxesOriginalUnits = app.UIAxes.Units;
            end

            % Ensure we have valid figure dimensions
            drawnow;

            % Convert to pixels for consistent calculations
            originalAxesUnits = app.UIAxes.Units;
            originalFigureUnits = app.UIFigure.Units;

            app.UIAxes.Units = 'pixels';
            app.UIFigure.Units = 'pixels';

            % Get current layout information
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            currentMainPos = app.UIAxes.Position;

            % Calculate new positions
            padding = 15;
            availableSpace = figWidth - currentMainPos(1) - padding;

            % Split available space: 60% for main, 40% for dynamic
            mainWidth = round(availableSpace * 0.60);
            dynamicWidth = round(availableSpace * 0.40);

            % Enforce minimum sizes
            minMainWidth = 400;
            minDynamicWidth = 250;

            if mainWidth < minMainWidth
                mainWidth = minMainWidth;
                dynamicWidth = availableSpace - mainWidth - padding;
            end

            if dynamicWidth < minDynamicWidth
                dynamicWidth = minDynamicWidth;
                mainWidth = availableSpace - dynamicWidth - padding;
                if mainWidth < minMainWidth
                    mainWidth = minMainWidth;
                    totalNeeded = minMainWidth + minDynamicWidth + padding;
                    if availableSpace < totalNeeded
                        scale = availableSpace / totalNeeded;
                        mainWidth = round(minMainWidth * scale);
                        dynamicWidth = round(minDynamicWidth * scale);
                    end
                end
            end

            % Position main axes
            newMainPos = [currentMainPos(1), currentMainPos(2), mainWidth, currentMainPos(4)];
            app.UIAxes.Position = newMainPos;

            % Create or update dynamic axes
            xPos = currentMainPos(1) + mainWidth + padding;
            yPos = currentMainPos(2);
            axHeight = currentMainPos(4);

            if isempty(app.DynamicAxes) || ~isvalid(app.DynamicAxes)
                app.DynamicAxes = uiaxes(app.UIFigure);
            end

            app.DynamicAxes.Units = 'pixels';
            app.DynamicAxes.Position = [xPos, yPos, dynamicWidth, axHeight];
            app.DynamicAxes.Visible = 'on';
            app.DynamicAxes.Parent = app.UIAxes.Parent;

            % Configure dynamic axes appearance
            title(app.DynamicAxes, 'Validation Results');
            xlabel(app.DynamicAxes, 'Time');
            ylabel(app.DynamicAxes, 'Value');
            app.DynamicAxes.Box = 'on';

            % Restore original units
            app.UIAxes.Units = originalAxesUnits;
            app.UIFigure.Units = originalFigureUnits;
        end
        % end
        %
        % methods (Access = private)

        function plotPrecipitationTemporal(app)
            try
                % Use your existing precipitation data
                % Filter to 2002-2018 period
                time_mask = app.IMDTime >= datetime(2002,1,1) & app.IMDTime <= datetime(2018,12,31);
                precip_time = app.IMDTime(time_mask);

                % Calculate basin average and anomalies (you might want to precompute this)
                [precip_masked, ~, ~] = app.applyBasinMaskWithCoords(...
                    app.IMDData, app.IMDLat, app.IMDLon);
                precip_basin_avg = mean(precip_masked, 1, 'omitnan');
                precip_basin_avg = precip_basin_avg(time_mask);

                % Calculate anomalies relative to 2004-2009 baseline
                baseline_idx = precip_time >= datetime(2004,1,1) & precip_time <= datetime(2009,12,31);
                clim_mean = mean(precip_basin_avg(baseline_idx), 'omitnan');
                precip_anom = precip_basin_avg - clim_mean;

                % Separate positive and negative values
                pos_idx = precip_anom >= 0;
                neg_idx = precip_anom < 0;

                % Plot positive bars
                if any(pos_idx)
                    bar(app.UIAxes, precip_time(pos_idx), precip_anom(pos_idx), ...
                        'FaceColor', [0.627, 0.800, 0.910], ... % #a0cce8
                        'EdgeColor', 'none', ...
                        'BarWidth', 1, ...
                        'DisplayName', 'Precip. Anomaly (+)');
                end

                % Plot negative bars
                if any(neg_idx)
                    bar(app.UIAxes, precip_time(neg_idx), precip_anom(neg_idx), ...
                        'FaceColor', [0.949, 0.733, 0.733], ... % #F2BBBB
                        'EdgeColor', 'none', ...
                        'BarWidth', 1, ...
                        'DisplayName', 'Precip. Anomaly (–)');
                end

            catch ME
                warning(ME.identifier,'Could not plot precipitation: %s', ME.message);
            end
        end

        function plotGRACE_Temporal(app)
            try
                % Filter GRACE data to 2002-2018 period
                time_mask = app.JPLTWSTime >= datetime(2002,1,1) & app.JPLTWSTime <= datetime(2018,12,31);
                grace_time = app.JPLTWSTime(time_mask);

                % Calculate basin average (you might want to precompute this)
                [grace_masked, ~, ~] = app.applyBasinMaskWithCoords(...
                    app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);
                grace_basin_avg = mean(grace_masked, 1, 'omitnan');
                grace_basin_avg = grace_basin_avg(time_mask);

                plot(app.UIAxes, grace_time, grace_basin_avg, ...
                    's--', 'Color', 'black', ...
                    'LineWidth', 2, ...
                    'MarkerSize', 6, ...
                    'DisplayName', 'GRACE TWSA');

            catch ME
                warning(ME.identifier,'Could not plot GRACE data: %s', ME.message);
            end
        end

        function plotCGWB_Temporal(app)
            try
                % Use your loaded CGWB data
                plot(app.UIAxes, app.CGWBTime, app.CGWBAvg, ...
                    'o--', 'Color', [0.620, 0.482, 0.710], ... % #9e7bb5
                    'LineWidth', 2, ...
                    'MarkerSize', 6, ...
                    'DisplayName', 'CGWB Groundwater Level');

            catch ME
                warning(ME.identifier,'Could not plot CGWB data: %s', ME.message);
            end
        end
        function pr_seasonal_anom = computeIMDSeasonalAnomalies(app, IMD_masked, IMD_datetime)
            % computeIMDSeasonalAnomalies
            % -------------------------------------------------------------------------
            % Compute basin-averaged, seasonal precipitation anomalies (2002-2018)
            % Optimized version with vectorized operations
            %
            % INPUTS:
            %   IMD_masked   - Precipitation data masked to basin (lat×lon×time or pts×time)
            %   IMD_datetime - datetime vector for the time dimension
            %
            % OUTPUT:
            %   pr_seasonal_anom - Table with columns: Date, Year, Season, Anomaly
            % -------------------------------------------------------------------------

            try
                % --- Step 1: Compute basin mean (vectorized) ---
                IMD_basin_mean = mean(IMD_masked, 1, 'omitnan');
                IMD_basin_mean = squeeze(IMD_basin_mean); % Remove singleton dimensions

                % --- Step 2: Select period (2002-2024) ---
                startDate = datetime(2002,1,1);
                endDate   = datetime(2024,12,31);
                selIdx = IMD_datetime >= startDate & IMD_datetime <= endDate;
                IMD_sel = IMD_basin_mean(selIdx);
                time_sel = IMD_datetime(selIdx);

                % --- Step 3: Climatology (2004-2009) ---
                climIdx = time_sel >= datetime(2004,1,1) & time_sel <= datetime(2009,12,31);
                clim_mean = mean(IMD_sel(climIdx), 'omitnan');

                % --- Step 4: Anomalies ---
                IMD_anom = IMD_sel - clim_mean;

                % --- Step 5: Shift December to next year's January (vectorized) ---
                time_shifted = time_sel;
                dec_months = month(time_sel) == 12;
                time_shifted(dec_months) = dateshift(time_sel(dec_months), 'start', 'month', 1);

                % --- Step 6: Map to Seasons (vectorized) ---
                months = month(time_shifted);
                seasons = strings(size(months));

                % Vectorized season assignment
                seasons(ismember(months, [1, 12])) = "JAN";
                seasons(ismember(months, [2, 3, 4, 5])) = "MAY";
                seasons(ismember(months, [6, 7, 8])) = "AUG";
                seasons(ismember(months, [9, 10, 11])) = "NOV";

                years = year(time_shifted);

                % --- Step 7: Group by Year + Season ---
                T = table(years(:), seasons(:), IMD_anom(:), time_shifted(:), ...
                    'VariableNames', {'Year','Season','Anomaly','Time'});
                seasonalSummary = groupsummary(T, {'Year','Season'}, 'mean', 'Anomaly');

                % --- Step 8: Create chronological season date (vectorized) ---
                season_map = containers.Map({'JAN','MAY','AUG','NOV'}, [1, 5, 8, 11]);
                months_vector = zeros(height(seasonalSummary), 1);

                for i = 1:height(seasonalSummary)
                    months_vector(i) = season_map(seasonalSummary.Season{i});
                end

                seasonalSummary.Date = datetime(seasonalSummary.Year, months_vector, 1);

                % --- Step 9: Sort chronologically ---
                seasonalSummary = sortrows(seasonalSummary, 'Date');
                seasonalSummary.Date.Format = 'MM-yyyy';

                % --- Step 10: Final output table ---
                pr_seasonal_anom = table(seasonalSummary.Date, seasonalSummary.Year, ...
                    seasonalSummary.Season, seasonalSummary.mean_Anomaly, ...
                    'VariableNames', {'Date','Year','Season','Anomaly'});

            catch ME
                warning(ME.identifier,'Error in computeIMDSeasonalAnomalies: %s', ME.message);
                % Return empty table on error
                pr_seasonal_anom = table('Size', [0, 4], ...
                    'VariableTypes', {'datetime', 'double', 'string', 'double'}, ...
                    'VariableNames', {'Date', 'Year', 'Season', 'Anomaly'});
            end
        end


        function plotGRACE_Spatial(app)
            try
                [grace_masked, grace_lat, grace_lon] = app.applyBasinMaskWithCoords(...
                    app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);

                % Take temporal mean for spatial pattern (2002-2018 period)
                time_mask = app.JPLTWSTime >= datetime(2002,1,1) & app.JPLTWSTime <= datetime(2018,12,31);
                grace_spatial = mean(grace_masked(:, time_mask), 2, 'omitnan');

                % Create spatial grid
                [unique_lat, ~, lat_idx] = unique(grace_lat);
                [unique_lon, ~, lon_idx] = unique(grace_lon);
                spatial_grid = NaN(length(unique_lat), length(unique_lon));

                for i = 1:length(grace_lat)
                    spatial_grid(lat_idx(i), lon_idx(i)) = grace_spatial(i);
                end

                % Plot spatial data
                imagesc(app.UIAxes, unique_lon, unique_lat, spatial_grid);
                colorbar(app.UIAxes);
                title(app.UIAxes, 'GRACE TWSA Spatial Distribution');

                % Add basin boundary
                hold(app.UIAxes, 'on');
                for i = 1:length(app.Basins)
                    if strcmpi(app.Basins(i).RIVER_BASI, 'GODAVARI')
                        plot(app.UIAxes, app.Basins(i).X, app.Basins(i).Y, ...
                            'k-', 'LineWidth', 2, 'DisplayName', 'Basin Boundary');
                    end
                end
                hold(app.UIAxes, 'off');

                axis(app.UIAxes, 'equal');
                xlabel(app.UIAxes, 'Longitude');
                ylabel(app.UIAxes, 'Latitude');

            catch ME
                uialert(app.UIFigure, sprintf('Error plotting GRACE spatial: %s', ME.message), 'Plot Error');
            end
        end

        function plotCGWB_Spatial(app)
            try
                % Check if CGWB data has coordinates
                if ~any(contains(app.CGWBData.Properties.VariableNames, {'Latitude', 'Longitude', 'LAT', 'LON', 'lat', 'lon'}))
                    % If no coordinates, show message
                    text(app.UIAxes, 0.5, 0.5, 'CGWB coordinates not available', ...
                        'HorizontalAlignment', 'center', 'Units', 'normalized', ...
                        'FontSize', 14);
                    title(app.UIAxes, 'CGWB Data - Coordinates Required for Spatial Plot');
                    return;
                end

                % Extract coordinates
                if ismember('Latitude', app.CGWBData.Properties.VariableNames)
                    well_lats = app.CGWBData.Latitude;
                    well_lons = app.CGWBData.Longitude;
                elseif ismember('LAT', app.CGWBData.Properties.VariableNames)
                    well_lats = app.CGWBData.LAT;
                    well_lons = app.CGWBData.LON;
                else
                    % Try to find coordinate columns by pattern matching
                    lat_col = find(contains(app.CGWBData.Properties.VariableNames, {'lat', 'Lat'}, 'IgnoreCase', true), 1);
                    lon_col = find(contains(app.CGWBData.Properties.VariableNames, {'lon', 'Lon'}, 'IgnoreCase', true), 1);
                    if ~isempty(lat_col) && ~isempty(lon_col)
                        well_lats = app.CGWBData{:, lat_col};
                        well_lons = app.CGWBData{:, lon_col};
                    else
                        msgbox('Could not find coordinate columns in CGWB data');
                    end
                end

                % Plot well locations
                scatter(app.UIAxes, well_lons, well_lats, 50, 'filled', ...
                    'MarkerFaceColor', [0.620, 0.482, 0.710], ... % #9e7bb5
                    'MarkerEdgeColor', 'k', ...
                    'DisplayName', 'CGWB Wells');

                % Add basin boundary
                hold(app.UIAxes, 'on');
                for i = 1:length(app.Basins)
                    if strcmpi(app.Basins(i).RIVER_BASI, 'GODAVARI')
                        plot(app.UIAxes, app.Basins(i).X, app.Basins(i).Y, ...
                            'k-', 'LineWidth', 2, 'DisplayName', 'Basin Boundary');
                    end
                end
                hold(app.UIAxes, 'off');

                title(app.UIAxes, 'CGWB Well Locations');
                axis(app.UIAxes, 'equal');
                xlabel(app.UIAxes, 'Longitude');
                ylabel(app.UIAxes, 'Latitude');
                legend(app.UIAxes, 'show', 'Location', 'best');

            catch ME
                uialert(app.UIFigure, sprintf('Error plotting CGWB spatial: %s', ME.message), 'Plot Error');
            end
        end

        function plotSpatialSideBySide(app)
            % Make both axes visible and adjust positions
            app.UIAxes2.Visible = 'on';
            app.UIAxes.Position = [0.08 0.25 0.4 0.65];
            app.UIAxes2.Position = [0.54 0.25 0.4 0.65];

            % Plot GRACE on left axes
            app.plotGRACE_Spatial();
            title(app.UIAxes, 'GRACE TWSA Spatial Distribution');

            % Plot CGWB on right axes
            cla(app.UIAxes2);
            app.plotCGWB_Spatial();
            title(app.UIAxes2, 'CGWB Well Locations');
        end

        function formatTemporalPlot(app)
            xlabel(app.UIAxes, 'Time', 'FontSize', 14, 'FontWeight', 'bold');
            ylabel(app.UIAxes, 'Anomaly (mm)', 'FontSize', 14, 'FontWeight', 'bold');

            % Set x-axis limits to match CGWB data period
            xlim(app.UIAxes, [datetime(2002,1,1), datetime(2018,12,31)]);

            % Yearly ticks
            years = 2002:2018;
            xticks(app.UIAxes, datetime(years, 1, 1));
            xticklabels(app.UIAxes, arrayfun(@num2str, years, 'UniformOutput', false));

            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'show', 'Location', 'best');
            title(app.UIAxes, 'Temporal Analysis: GRACE vs CGWB vs Precipitation');
        end
        % Helper function to safely test if a field exists and is valid
        function tf = isvalidField(app, propName)
            tf = isprop(app, propName) && ~isempty(app.(propName)) && isvalid(app.(propName));
        end
        function plotOnDynamicAxes(app, checkedTexts)
            if isempty(app.DynamicAxes) || ~isvalid(app.DynamicAxes)
                return;
            end

            % Clear previous plots
            cla(app.DynamicAxes);
            hold(app.DynamicAxes, 'on');

            colors = lines(3); % Get different colors for each dataset
            plotType = app.PlotTypeDropDown.Value;

            hasPlots = false;

            % Plot CGWB data
            if any(strcmp(checkedTexts, 'CGWB'))
                hasPlots = plotCGWBData(app, plotType) || hasPlots;
            end

            % Plot GRACE data
            if any(strcmp(checkedTexts, 'GRACE'))
                hasPlots = plotGRACEData(app, plotType) || hasPlots;
            end

            % Plot Precipitation data
            if any(strcmp(checkedTexts, 'Precipitation'))
                hasPlots = plotPrecipitationData(app, plotType) || hasPlots;
            end

            hold(app.DynamicAxes, 'off');

            % Add legend only if we have plots
            if hasPlots
                legend(app.DynamicAxes, 'show', 'Location', 'best');
            else
                legend(app.DynamicAxes, 'off');
            end
        end

        function success = plotCGWBData(app, plotType)
            success = false;
            try
                switch plotType
                    case 'Temporal'
                        if ~isempty(app.CGWBTime) && ~isempty(app.CGWBAvg) && ...
                                length(app.CGWBTime) == length(app.CGWBAvg)
                            plot(app.DynamicAxes, app.CGWBTime, app.CGWBAvg, ...
                                'Color', [0.62, 0.482, 0.71], 'DisplayName', 'CGWB_GWSA', 'LineWidth', 1.5);
                            success = true;
                        end
                    case 'Spatial'
                        if ~isempty(app.CGWBAnomalies) && ...
                                ~isempty(app.CGWBLat) && ...
                                ~isempty(app.CGWBLon) && ...
                                ~isempty(app.CGWBTime)

                            % === Calculate trends ===
                            numPoints = size(app.CGWBAnomalies, 1);
                            trendValues = zeros(numPoints, 1);

                            if isdatetime(app.CGWBTime)
                                timeNum = datenum(app.CGWBTime);
                            else
                                timeNum = app.CGWBTime;
                            end
                            timeNum = timeNum - min(timeNum);

                            for i = 1:numPoints
                                y = squeeze(app.CGWBAnomalies(i, :));
                                validIdx = ~isnan(y);
                                if sum(validIdx) > 1
                                    p = polyfit(timeNum(validIdx), y(validIdx), 1);
                                    trendValues(i) = p(1);
                                else
                                    trendValues(i) = NaN;
                                end
                            end

                            % === Plot trend points ===
                            scatter(app.DynamicAxes, app.CGWBLon, app.CGWBLat, 40, trendValues, ...
                                'filled', 'MarkerEdgeColor', 'k', 'LineWidth', 0.5);

                            % Colormap & colorbar
                            redBlueMap = [linspace(0,1,64)' zeros(64,1) linspace(1,0,64)'];
                            colormap(app.DynamicAxes, redBlueMap);
                            c = colorbar(app.DynamicAxes, 'southoutside');
                            c.Label.String = 'CGWB GWSA (mm/year)';

                            maxAbs = max(abs(trendValues), [], 'omitnan');
                            if maxAbs > 0
                                caxis(app.DynamicAxes, [-maxAbs, maxAbs]);
                            end

                            % === Retrieve and plot basin polygon ===
                            selectedBasin = app.BasinSelectionButton.Value;
                            basins = app.Basins;

                            basinPolygon = [];
                            for i = 1:length(basins)
                                if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                                    basinPolygon = [basins(i).X', basins(i).Y'];
                                    break;
                                end
                            end

                            % Clean polygon
                            basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                            % === Plot polygon ===
                            hold(app.DynamicAxes, 'on');

                            if ~isempty(basinPolygon)
                                plot(app.DynamicAxes, basinPolygon(:,1), basinPolygon(:,2), ...
                                    'k-', 'LineWidth', 2, 'DisplayName', selectedBasin);
                            else
                                warning('No polygon found for basin: %s', selectedBasin);
                            end

                            % Optional: also check alternate boundary source
                            if isprop(app, 'BasinBoundaries') && ~isempty(app.BasinBoundaries)
                                basinIdx = strcmp({app.BasinBoundaries.Name}, selectedBasin);
                                if any(basinIdx)
                                    basinData = app.BasinBoundaries(basinIdx);
                                    plot(app.DynamicAxes, basinData.Lon, basinData.Lat, ...
                                        'k-', 'LineWidth', 2, 'DisplayName', [selectedBasin ' (Boundary)']);
                                end
                            end

                            hold(app.DynamicAxes, 'off');

                            % === Final formatting ===
                            xlabel(app.DynamicAxes, 'Longitude');
                            ylabel(app.DynamicAxes, 'Latitude');
                            title(app.DynamicAxes, 'CGWB Spatial Trends');
                            grid(app.DynamicAxes, 'on');
                            axis(app.DynamicAxes, 'equal');

                            % Force axes to show both scatter & polygon
                            axis(app.DynamicAxes, 'tight');

                            % Add legend only if polygon exists
                            if exist('basinPolygon','var') && ~isempty(basinPolygon)
                                legend(app.DynamicAxes, 'show', 'Location', 'best');
                            end

                            success = true;

                        else
                            warning('Spatial data for CGWB is incomplete (missing anomalies, coordinates, or time).');
                        end
                end
            catch ME
                warning(ME.identifier,'Failed to plot CGWB data: %s', ME.message);
            end
        end

        function success = plotGRACEData(app, plotType)
            success = false;
            try
                [JPL_masked, latJPL, lonJPL] = applyBasinMaskWithCoords(app, ...
                    app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);
                [GLDASSM_masked, latGLDAS, lonGLDAS] = applyBasinMaskWithCoords(app, ...
                    app.GLDAS_SMData, app.GLDAS_SMLat, app.GLDAS_SMLon);

                % --- Compute GLDAS anomalies ---
                climYears = 2004:2009;
                climIdx = ismember(year(app.GLDAS_datetime), climYears);
                climMean = mean(GLDASSM_masked(:, climIdx), 2, 'omitnan');
                GLDASSM_anom = GLDASSM_masked - climMean;

                % --- Get datetime arrays ---
                JPL_time = dateshift(app.JPL_datetime, 'start', 'month');
                GLDAS_time = dateshift(app.GLDAS_datetime, 'start', 'month');

                % Remove duplicate months from JPL_time
                [JPL_time_unique, uniqueIdx] = unique(JPL_time, 'stable');
                JPL_masked = JPL_masked(:, uniqueIdx);
                JPL_time = JPL_time_unique;

                % --- Find overlap period ---
                startDate = max([min(JPL_time), min(GLDAS_time)]);
                endDate = min([max(JPL_time), max(GLDAS_time)]);

                % --- Create continuous common time vector ---
                common_time = (dateshift(startDate, 'start', 'month') : calmonths(1) : dateshift(endDate, 'start', 'month'))';

                % --- Initialize matrices with NaN ---
                JPL_full = nan(size(JPL_masked,1), length(common_time));
                GLDAS_full = nan(size(GLDASSM_anom,1), length(common_time));

                % --- Map existing data into common_time ---
                [~, ia_jpl, ib_jpl] = intersect(common_time, JPL_time);
                JPL_full(:, ia_jpl) = JPL_masked(:, ib_jpl);

                [~, ia_gldas, ib_gldas] = intersect(common_time, GLDAS_time);
                GLDAS_full(:, ia_gldas) = GLDASSM_anom(:, ib_gldas);

                % --- Compute spatial mean ---
                JPL_mean = mean(JPL_full, 1, 'omitnan');
                GLDAS_mean = mean(GLDAS_full, 1, 'omitnan');

                switch plotType
                    case 'Temporal'
                        GWSA = JPL_mean*10 - GLDAS_mean;

                        % --- Select representative months (Jan, May, Aug, Nov) ---
                        targetMonths = [1, 5, 8, 11];
                        selIdx = ismember(month(common_time), targetMonths);

                        % Filter time and GWSA data
                        common_time_filtered = common_time(selIdx);
                        GWSA_filtered = GWSA(selIdx);

                        % --- Plot filtered GWSA ---
                        plot(app.DynamicAxes, common_time_filtered, GWSA_filtered, ...
                            'Color', 'black', 'DisplayName', 'GRACE_GWSA', 'LineWidth', 1.5, 'LineStyle', '--');
                        success = true;

                    case 'Spatial'
                        GWSA = JPL_full*10 - GLDAS_full;
                        nPoints = size(GWSA, 1);
                        trend = nan(nPoints, 1);

                        for p = 1:nPoints
                            y = GWSA(p, :);
                            t = (1:length(y))';
                            validIdx = ~isnan(y);
                            if sum(validIdx) >= 2
                                coeffs = polyfit(t(validIdx), y(validIdx), 1);
                                trend(p) = coeffs(1);
                            else
                                trend(p) = NaN;
                            end
                        end

                        % Retrieve the selected basin
                        selectedBasin = app.BasinSelectionButton.Value;
                        basins = app.Basins;

                        % Find the polygon corresponding to the selected basin
                        basinPolygon = [];
                        for i = 1:length(basins)
                            if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                                basinPolygon = [basins(i).X', basins(i).Y'];
                                break;
                            end
                        end

                        % Clean polygon (remove NaNs)
                        basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                        % Call your spatial plotting function
                        plotSpatialBasinMapDynamic(app, lonJPL, latJPL, trend, basinPolygon, 'GRACE_GWSA');
                        success = true;
                end
            catch ME
                warning(ME.identifier,'Failed to plot GRACE data: %s', ME.message);
            end
        end

        function success = plotPrecipitationData(app, plotType)
            success = false;
            try
                switch plotType
                    case 'Temporal'
                        % --- IMD file path ---
                        IMDFile = "./Data/Precipitation/IMD/IMD_Precipitation_1901_2023_Godavari.nc";


                        [IMD_masked, latIMD, lonIMD] = applyBasinMaskWithCoords(app, app.IMDData, app.IMDLat, app.IMDLon);

                        pr_seasonal_anom = computeIMDSeasonalAnomalies(app, IMD_masked, app.IMD_datetime);

                        % Make sure the target axes is active
                        axes(app.DynamicAxes);
                        hold(app.DynamicAxes, 'on');

                        xVals = pr_seasonal_anom.Date;
                        yVals = pr_seasonal_anom.Anomaly;

                        % Plot bars with different colors for positive and negative anomalies
                        for i = 1:length(xVals)
                            if yVals(i) >= 0
                                bar(app.DynamicAxes, xVals(i), yVals(i), ...
                                    'FaceColor', [0.627, 0.80, 0.91], 'EdgeColor', 'none', 'BarWidth', 75);
                            else
                                bar(app.DynamicAxes, xVals(i), yVals(i), ...
                                    'FaceColor', [0.949, 0.733, 0.733], 'EdgeColor', 'none', 'BarWidth', 75);
                            end
                        end

                        % Add zero reference line
                        yline(app.DynamicAxes, 0, 'k--', 'LineWidth', 1);

                        % Format axes for datetime
                        datetick(app.DynamicAxes, 'x', 'mmm-yyyy', 'keeplimits');
                        set(app.DynamicAxes, 'XTickLabelRotation', 45);
                        grid(app.DynamicAxes, 'on');

                        % Create legend manually
                        hPos = bar(app.DynamicAxes, NaT, 0, 'FaceColor', [0.627, 0.80, 0.91], 'EdgeColor', 'none', 'Visible', 'off');
                        hNeg = bar(app.DynamicAxes, NaT, 0, 'FaceColor', [0.949, 0.733, 0.733], 'EdgeColor', 'none', 'Visible', 'off');
                        legend(app.DynamicAxes, [hPos, hNeg], {'Positive', 'Negative'}, 'Location', 'best');

                        hold(app.DynamicAxes, 'off');
                        success = true;

                    case 'Spatial'
                        uialert(app.UIFigure, 'Please use other precipitation option', "Invalid Selection", "Icon", "error");
                end
            catch ME
                warning(ME.identifier,'Failed to plot Precipitation data: %s', ME.message);
            end
        end
        function readAndPlotSensitivityAnalysis(app, analysisType)
            % Read Excel file and create tornado plot for sensitivity analysis
            % based on the analysis type

            try
                % Determine the file path based on analysis type
                if strcmp(analysisType, 'basin_average')
                    filename = './SHAP_Analysis_Results_BasinAverage.xlsx';
                    plotTitle = 'Sensitivity Analysis - Basin Average';
                else
                    filename = './SHAP_Analysis_Results_AllPoints_Basin.xlsx';
                    plotTitle = 'Sensitivity Analysis - All Points';
                end

                % Check if file exists
                if ~isfile(filename)
                    error('Sensitivity analysis file not found: %s', filename);
                end

                % Read the Excel file
                data = readtable(filename);

                % Extract data for tornado plot
                pointNames = data.Point; % First column contains point names
                featureData = data{:, 2:end}; % Remaining columns contain feature data
                featureNames = data.Properties.VariableNames(2:end);

                % Create tornado plot
                createTornadoPlot(app, pointNames, featureData, featureNames, plotTitle);

                disp(['Sensitivity analysis plot created for: ', analysisType]);

            catch ME
                errorMsg = sprintf('Error reading sensitivity analysis file: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'File Read Error');
                rethrow(ME);
            end
        end

        function createTornadoPlot(app, pointNames, featureData, featureNames, plotTitle)
            % Create tornado plot similar to SHAP analysis

            try
                % Create a new figure for the tornado plot
                fig = figure('Name', plotTitle, ...
                    'NumberTitle', 'off', ...
                    'Position', [100, 100, 1200, 800]);

                % Calculate absolute mean values for each feature across all points
                meanAbsValues = mean(abs(featureData), 1, 'omitnan');

                % Sort features by their absolute mean importance (descending)
                [sortedValues, sortIdx] = sort(meanAbsValues, 'descend');
                sortedFeatureNames = featureNames(sortIdx);

                % Create horizontal bar plot (proper tornado plot)
                subplot(1, 2, 1);

                % For tornado plot, we want highest values at top, lowest at bottom
                % So we reverse the order
                y = 1:length(sortedValues);
                if length(sortedValues) > 20
                    topN = 20;
                    barhValues = sortedValues(1:topN);
                    barhFeatures = sortedFeatureNames(1:topN);
                    y = 1:topN;
                else
                    barhValues = sortedValues;
                    barhFeatures = sortedFeatureNames;
                    topN = length(sortedValues);
                end

                % Create the horizontal bars
                h = barh(y, barhValues);
                ylabel('Features');
                xlabel('Mean Absolute Value');
                title('Feature Importance (Tornado Plot)');

                % Set Y-axis properties for proper tornado plot appearance
                set(gca, 'YTick', y, 'YTickLabel', barhFeatures);
                set(gca, 'YDir', 'reverse'); % This puts highest values at top
                grid on;

                % Customize the bars
                h.FaceColor = 'flat';
                h.EdgeColor = 'blue';
                h.LineWidth = 0.5;

                % Add value labels on the bars
                for i = 1:length(barhValues)
                    text(barhValues(i) + max(barhValues)*0.01, i, ...
                        sprintf('%.3f', barhValues(i)), ...
                        'FontSize', 8, 'VerticalAlignment', 'middle');
                end

                % Create a heatmap of values for detailed analysis
                subplot(1, 2, 2);

                % Use only top features for heatmap if there are too many
                if size(featureData, 2) > 20
                    topFeatures = sortIdx(1:min(20, length(sortIdx)));
                    heatmapData = featureData(:, topFeatures);
                    heatmapFeatures = sortedFeatureNames(1:min(20, length(sortIdx)));
                else
                    heatmapData = featureData(:, sortIdx);
                    heatmapFeatures = sortedFeatureNames;
                end

                % Sort heatmap data by the same order as tornado plot
                heatmapData = heatmapData(:, 1:size(heatmapData, 2));

                % Create heatmap with proper orientation
                imagesc(heatmapData);
                colorbar;
                xlabel('Features');
                ylabel('Data Points');
                title('Feature Values Heatmap');
                colormap(jet); % Use jet colormap for better visibility

                % Set tick labels
                if length(heatmapFeatures) <= 30 % Only show labels if not too many
                    set(gca, 'XTick', 1:length(heatmapFeatures), ...
                        'XTickLabel', heatmapFeatures, ...
                        'XTickLabelRotation', 45);
                end

                if length(pointNames) <= 50 % Only show point names if not too many
                    set(gca, 'YTick', 1:length(pointNames), ...
                        'YTickLabel', pointNames);
                end

                % Add overall title
                sgtitle(plotTitle, 'FontSize', 14, 'FontWeight', 'bold');

                % Add some statistics to the command window
                fprintf('\n--- Sensitivity Analysis Statistics ---\n');
                fprintf('Number of data points: %d\n', length(pointNames));
                fprintf('Number of features: %d\n', length(featureNames));
                fprintf('Top %d most important features:\n', min(5, topN));
                for i = 1:min(5, topN)
                    fprintf('  %d. %s: %.4f\n', i, barhFeatures{i}, barhValues(i));
                end

            catch ME
                errorMsg = sprintf('Error creating tornado plot: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Plot Error');
                rethrow(ME);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function updateAllPlots(app)
            % % Get dropdown selection
            screenSize = get(0,'ScreenSize');

            % Design size (from your UIFigure in Design View)
            designWidth = 1212;
            designHeight = 764;

            % Compute scale factor relative to screen size
            scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            %
            % % Apply new scaled size (use 85% of available screen for safety)
            % newWidth = round(designWidth * scale* 0.85);% * 0.85
            % newHeight = round(designHeight * scale* 0.85);
            %
            % % Update UIFigure size
            % app.UIFigure.Position = [50 50 newWidth newHeight];
            cla(app.UIAxes, 'reset');
            legendItems = {};
            plotType = app.PlotTypeDropDown.Value;  % 'Temporal' or 'Spatial'


            switch plotType

                case 'Temporal'
                    % ===== Temporal Plot =====

                    timescales = app.TimescaleDropDown.Value;
                    switch timescales
                        %case 'Daily'
                        case 'Monthly'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending dates for the slider scale
                            startDate = datetime(1901, 1, 1); % Start date (01-1901)
                            endDate = datetime(2025, 12, 1);  % End date (12-2025)
                            % Generate all months between the start and end dates
                            allTimeDates = startDate:calmonths(1):endDate;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allTimeDates));
                            selectedTimeRange = allTimeDates(sIdx:eIdx);
                            x = sIdx:eIdx;
                            % Get start & end years of current visible time range
                            tStart = year(selectedTimeRange(1));
                            tEnd   = year(selectedTimeRange(end));
                            xlim(app.UIAxes, [sIdx eIdx]);
                            % Calculate total span in years
                            spanYears = tEnd - tStart;

                            % Choose a step size dynamically (prevent overlap)
                            if spanYears > 40
                                step = 10;   % e.g., large range → coarser ticks
                            elseif spanYears > 20
                                step = 5;
                            elseif spanYears > 10
                                step = 2;
                            else
                                step = 1;    % short range → finer ticks
                            end

                            % Define ticks
                            tickYears = tStart:step:tEnd;

                            % % Axis ticks every 2 years
                            % tickYears = year(selectedTimeRange(1)) : 2 : year(selectedTimeRange(end));
                            xt = datetime(tickYears, 1, 1);
                            % Convert datetime to indices relative to allTimeDates
                            [~, idxTicks] = ismember(xt, allTimeDates);
                            % Apply ticks and labels
                            app.UIAxes.XTick = idxTicks;
                            app.UIAxes.XTickLabel = cellstr(datestr(xt, 'yyyy'));
                            % Set axis limits (numeric)
                            xlim(app.UIAxes, [sIdx eIdx]);
                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};

                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(applyBasinMask(app, app.Hp_GERECData, app.Hp_GERECLat, app.Hp_GERECLon),1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(applyBasinMask(app, app.Hp_GGRECData, app.Hp_GGRECLat, app.Hp_GGRECLon),1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(applyBasinMask(app, app.Hp_GMRECData, app.Hp_GMRECLat, app.Hp_GMRECLon),1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(applyBasinMask(app, app.Hp_JERECData, app.Hp_JERECLat, app.Hp_JERECLon),1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(applyBasinMask(app, app.Hp_JGRECData, app.Hp_JGRECLat, app.Hp_JGRECLon),1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(applyBasinMask(app, app.Hp_JMRECData, app.Hp_JMRECLat, app.Hp_JMRECLon),1);

                                        REC = [
                                            min(Hp_REC, [], 1,'omitnan');
                                            max(Hp_REC, [], 1,'omitnan');
                                            mean(Hp_REC, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(applyBasinMask(app, app.PalazzoliLSTMData, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon),1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliLSTMnoLCSIFData, app.PalazzoliLSTMnoLCSIFLat, app.PalazzoliLSTMnoLCSIFLon),1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMData, app.PalazzoliBiLSTMLat, app.PalazzoliBiLSTMLon),1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMnoLCSIFData, app.PalazzoliBiLSTMnoLCSIFLat, app.PalazzoliBiLSTMnoLCSIFLon),1);

                                        REC = [
                                            min(Pl_REC, [], 1,'omitnan');
                                            max(Pl_REC, [], 1,'omitnan');
                                            mean(Pl_REC, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(applyBasinMask(app, app.LiRECData, app.LiRECLat, app.LiRECLon),1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;
                                        REC = repmat(Li_REC,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(applyBasinMask(app, app.DengRECData, app.DengRECLat, app.DengRECLon),1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;
                                        REC = repmat(Deng_REC,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(applyBasinMask(app, app.MandalRECData, app.MandalRECLat, app.MandalRECLon),1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;
                                        REC = repmat(Mandal_REC,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');  % Hold once at the beginning

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end


                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);

                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(applyBasinMask(app, app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon),1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.JPLTWSMN) = [JPL_BA; JPL_BA; JPL_BA];

                                    case 'csr'
                                        CSR_BA = 10*mean(applyBasinMask(app, app.CSRTWSData, app.CSRTWSLat, app.CSRTWSLon),1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.CSRTWSMN) = [CSR_BA; CSR_BA; CSR_BA];

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(applyBasinMask(app, app.GFSCTWSData, app.GFSCTWSLat, app.GFSCTWSLon),1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.GFSCTWSMN) = [GSFC_BA; GSFC_BA; GSFC_BA];

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                eIdx = min(eIdx, size(REC,2));
                                x = sIdx:eIdx;

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));%," TWSA"
                            end

                            % Use one of these values: '+' | 'o' | '*' | '.' | 'x' | 'square' | 'diamond' | 'v' | '^' | '>' | '<' | 'pentagram' |
                            % 'hexagram' | '|' | '_' | 'none'.
                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(applyBasinMask(app, app.ThisStudyBestData, ...
                                            app.ThisStudyBestLat, app.ThisStudyBestLon),1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        REC = repmat(Best_REC,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(applyBasinMask(app, app.ThisStudyANNData, ...
                                            app.ThisStudyANNLat, app.ThisStudyANNLon),1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        REC = repmat(ANN_REC,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(applyBasinMask(app, app.ThisStudyLSTMData, ...
                                            app.ThisStudyLSTMLat, app.ThisStudyLSTMLon),1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        REC = repmat(LSTM_REC,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(applyBasinMask(app, app.ThisStudyRFData, ...
                                            app.ThisStudyRFLat, app.ThisStudyRFLon),1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        REC = repmat(RF_REC,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                % Fill band (flat since single dataset)
                                % fill(app.UIAxes, [x fliplr(x)], [REC(2,x) fliplr(REC(1,x))], ...
                                %     c, 'FaceAlpha',0.2, 'EdgeColor','none');
                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));%," RTWSA"

                            end
                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;
                            % Lyticks = 0:round(max(REC,2)/5):max(REC,2);   % adjust step as needed
                            % app.UIAxes.YTick = Lyticks;
                            hold(app.UIAxes,'off');
                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly)
                                        app.IMD_BA = mean(applyBasinMask(app, app.IMDData, app.IMDLat, app.IMDLon),1);
                                        data = NaN(1,1500); data(1,app.IMDMN) = app.IMD_BA;

                                    case 'gpcp'
                                        % GPCP (mm/month)
                                        app.GPCP_BA = mean(applyBasinMask(app, app.GPCPData, app.GPCPLat, app.GPCPLon),1);
                                        data = NaN(1,1500); data(1,app.GPCPMN) = app.GPCP_BA;

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm
                                        app.ERA5_BA = 1000*mean(applyBasinMask(app, app.ERA5Data, app.ERA5Lat, app.ERA5Lon),1);
                                        data = NaN(1,1500); data(1,app.ERA5MN) = app.ERA5_BA;
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What’s selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    % (Only IMD points + whiskers + hollow mean bar in blue tones)
                                    % Hollow mean bar (blue edge)
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min–max) behind mean
                                    legendAdded = false;  % flag to ensure legend appears only once

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            % First whisker line with legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;  % next lines won’t have legend
                                        else
                                            % Other lines: no legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        % Plot IMD as “o” markers
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;  % flag to ensure legend appears only once

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            % First whisker line with legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;  % next lines won’t have legend
                                        else
                                            % Other lines: no legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end


                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the sIdx:eIdx window
                                    mask = (xdata >= sIdx & xdata <= eIdx);
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');

                                    % Add padding for readability
                                    pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin , twsMax + pad];

                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');   % already computed from precip data
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end

                            end

                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;
                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            % if exist('newWidth','var') && ~isempty(newWidth)
                            lg.NumColumns =9;% min(9, floor(newWidth/150));
                            % else
                            %     lg.NumColumns = 3; % fallback
                            % end
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');

                        case 'Annual'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending years for the slider scale
                            startYr = 1901;
                            endYr = 2025;
                            allYrs = startYr:endYr;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allYrs));
                            selectedTimeRange = allYrs(sIdx:eIdx);
                            x = sIdx:eIdx;
                            % Get start & end years of current visible time range
                            tStart = selectedTimeRange(1);
                            tEnd   = selectedTimeRange(end);

                            % Calculate total span in years
                            spanYrs = tEnd - tStart;

                            % Choose a step size dynamically (prevent overlap)
                            if spanYrs > 40
                                step = 10;   % e.g., large range → coarser ticks
                            elseif spanYrs > 20
                                step = 5;
                            elseif spanYrs > 10
                                step = 2;
                            else
                                step = 1;    % short range → finer ticks
                            end

                            % Define ticks
                            tickYrs = tStart:step:tEnd;

                            % Apply ticks and labels
                            app.UIAxes.XTick = find(ismember(allYrs, tickYrs));
                            app.UIAxes.XTickLabel = cellstr(num2str(tickYrs'));

                            % Set axis limits (numeric)
                            xlim(app.UIAxes, [sIdx eIdx]);
                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};
                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(applyBasinMask(app, app.Hp_GERECData, app.Hp_GERECLat, app.Hp_GERECLon),1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(applyBasinMask(app, app.Hp_GGRECData, app.Hp_GGRECLat, app.Hp_GGRECLon),1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(applyBasinMask(app, app.Hp_GMRECData, app.Hp_GMRECLat, app.Hp_GMRECLon),1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(applyBasinMask(app, app.Hp_JERECData, app.Hp_JERECLat, app.Hp_JERECLon),1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(applyBasinMask(app, app.Hp_JGRECData, app.Hp_JGRECLat, app.Hp_JGRECLon),1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(applyBasinMask(app, app.Hp_JMRECData, app.Hp_JMRECLat, app.Hp_JMRECLon),1);

                                        % Convert monthly to annual mean - handle partial years
                                        Hp_REC_annual = NaN(6,125);
                                        for datasetIdx = 1:6
                                            monthly_data = Hp_REC(datasetIdx,:);
                                            for yrIdx = 1:125
                                                start_month = (yrIdx-1)*12 + 1;
                                                end_month = yrIdx*12;
                                                % Only calculate if we have data for this year
                                                year_data = monthly_data(start_month:end_month);
                                                if any(~isnan(year_data))
                                                    Hp_REC_annual(datasetIdx,yrIdx) = mean(year_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Hp_REC_annual, [], 1,'omitnan');
                                            max(Hp_REC_annual, [], 1,'omitnan');
                                            mean(Hp_REC_annual, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(applyBasinMask(app, app.PalazzoliLSTMData, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon),1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliLSTMnoLCSIFData, app.PalazzoliLSTMnoLCSIFLat, app.PalazzoliLSTMnoLCSIFLon),1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMData, app.PalazzoliBiLSTMLat, app.PalazzoliBiLSTMLon),1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMnoLCSIFData, app.PalazzoliBiLSTMnoLCSIFLat, app.PalazzoliBiLSTMnoLCSIFLon),1);

                                        % Convert monthly to annual mean - handle partial years
                                        Pl_REC_annual = NaN(4,125);
                                        for datasetIdx = 1:4
                                            monthly_data = Pl_REC(datasetIdx,:);
                                            for yrIdx = 1:125
                                                start_month = (yrIdx-1)*12 + 1;
                                                end_month = yrIdx*12;
                                                % Only calculate if we have data for this year
                                                year_data = monthly_data(start_month:end_month);
                                                if any(~isnan(year_data))
                                                    Pl_REC_annual(datasetIdx,yrIdx) = mean(year_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Pl_REC_annual, [], 1,'omitnan');
                                            max(Pl_REC_annual, [], 1,'omitnan');
                                            mean(Pl_REC_annual, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(applyBasinMask(app, app.LiRECData, app.LiRECLat, app.LiRECLon),1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Li_REC_annual = NaN(1,125);
                                        monthly_data = Li_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Li_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Li_REC_annual,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(applyBasinMask(app, app.DengRECData, app.DengRECLat, app.DengRECLon),1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Deng_REC_annual = NaN(1,125);
                                        monthly_data = Deng_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Deng_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Deng_REC_annual,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(applyBasinMask(app, app.MandalRECData, app.MandalRECLat, app.MandalRECLon),1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Mandal_REC_annual = NaN(1,125);
                                        monthly_data = Mandal_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Mandal_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Mandal_REC_annual,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end

                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);
                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(applyBasinMask(app, app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon),1);
                                        JPL_TWSA = NaN(1,1500);
                                        JPL_TWSA(1,[app.JPLTWSMN]) = JPL_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        JPL_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = JPL_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                JPL_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(JPL_annual,3,1);

                                    case 'csr'
                                        CSR_BA = 10*mean(applyBasinMask(app, app.CSRTWSData, app.CSRTWSLat, app.CSRTWSLon),1);
                                        CSR_TWSA = NaN(1,1500);
                                        CSR_TWSA(1,[app.CSRTWSMN]) = CSR_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        CSR_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = CSR_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                CSR_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(CSR_annual,3,1);

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(applyBasinMask(app, app.GFSCTWSData, app.GFSCTWSLat, app.GFSCTWSLon),1);
                                        GSFC_TWSA = NaN(1,1500);
                                        GSFC_TWSA(1,[app.GFSCTWSMN]) = GSFC_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        GSFC_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = GSFC_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                GSFC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(GSFC_annual,3,1);

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                eIdx_plot = min(eIdx, size(REC,2));
                                x_plot = sIdx:eIdx_plot;

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x_plot, REC(3,x_plot), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));
                            end

                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(applyBasinMask(app, app.ThisStudyBestData, ...
                                            app.ThisStudyBestLat, app.ThisStudyBestLon),1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        Best_annual = NaN(1,125);
                                        monthly_data = Best_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Best_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Best_annual,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(applyBasinMask(app, app.ThisStudyANNData, ...
                                            app.ThisStudyANNLat, app.ThisStudyANNLon),1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        ANN_annual = NaN(1,125);
                                        monthly_data = ANN_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                ANN_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(ANN_annual,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(applyBasinMask(app, app.ThisStudyLSTMData, ...
                                            app.ThisStudyLSTMLat, app.ThisStudyLSTMLon),1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        LSTM_annual = NaN(1,125);
                                        monthly_data = LSTM_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                LSTM_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(LSTM_annual,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(applyBasinMask(app, app.ThisStudyRFData, ...
                                            app.ThisStudyRFLat, app.ThisStudyRFLon),1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        RF_annual = NaN(1,125);
                                        monthly_data = RF_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                RF_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(RF_annual,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));

                            end

                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;

                            hold(app.UIAxes,'off');

                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly) - convert to annual sum
                                        app.IMD_BA = mean(applyBasinMask(app, app.IMDData, app.IMDLat, app.IMDLon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.IMDMN) = app.IMD_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end

                                    case 'gpcp'
                                        % GPCP (mm/month) - convert to annual sum
                                        app.GPCP_BA = mean(applyBasinMask(app, app.GPCPData, app.GPCPLat, app.GPCPLon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.GPCPMN) = app.GPCP_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm and then annual sum
                                        app.ERA5_BA = 1000*mean(applyBasinMask(app, app.ERA5Data, app.ERA5Lat, app.ERA5Lon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.ERA5MN) = app.ERA5_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What's selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min-max) behind mean
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end
                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the sIdx:eIdx window
                                    mask = (xdata >= sIdx & xdata <= eIdx);
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');
                                    disp(twsMax)
                                    % Add padding for readability
                                    %pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin , twsMax];% + pad];


                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end
                            end
                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;;
                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            % if exist('newWidth','var') && ~isempty(newWidth)
                            %     lg.NumColumns = min(9, floor(newWidth/150));
                            % else
                            lg.NumColumns = 9; % fallback
                            % end
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');
                        case 'Decadal'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending years for the slider scale
                            startYr = 1901;
                            endYr = 2025;
                            allYrs = startYr:endYr;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allYrs));
                            selectedTimeRange = allYrs(sIdx:eIdx);

                            % Calculate decadal bins for the selected time range
                            tStart = selectedTimeRange(1);
                            tEnd = selectedTimeRange(end);

                            % Create decade bins (1901-1910, 1911-1920, etc.)
                            decadeStarts = floor(tStart/10)*10+1:10:floor(tEnd/10)*10+1;
                            if isempty(decadeStarts)
                                decadeStarts = tStart;
                            end

                            % Calculate decade centers for plotting
                            decadeCenters = decadeStarts + 4.5; % Middle of the decade
                            x = 1:numel(decadeStarts);

                            % Apply ticks and labels
                            app.UIAxes.XTick = x;
                            decadeLabels = cell(1, numel(decadeStarts));
                            for i = 1:numel(decadeStarts)
                                decadeEnd = min(decadeStarts(i) + 9, tEnd);
                                decadeLabels{i} = sprintf('%d-%d', decadeStarts(i), decadeEnd);
                            end
                            app.UIAxes.XTickLabel = decadeLabels;

                            % Set axis limits
                            xlim(app.UIAxes, [0.5 numel(decadeStarts)+0.5]);

                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};

                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(applyBasinMask(app, app.Hp_GERECData, app.Hp_GERECLat, app.Hp_GERECLon),1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(applyBasinMask(app, app.Hp_GGRECData, app.Hp_GGRECLat, app.Hp_GGRECLon),1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(applyBasinMask(app, app.Hp_GMRECData, app.Hp_GMRECLat, app.Hp_GMRECLon),1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(applyBasinMask(app, app.Hp_JERECData, app.Hp_JERECLat, app.Hp_JERECLon),1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(applyBasinMask(app, app.Hp_JGRECData, app.Hp_JGRECLat, app.Hp_JGRECLon),1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(applyBasinMask(app, app.Hp_JMRECData, app.Hp_JMRECLat, app.Hp_JMRECLon),1);

                                        % Convert monthly to decadal mean
                                        Hp_REC_decadal = NaN(6, numel(decadeStarts));
                                        for datasetIdx = 1:6
                                            monthly_data = Hp_REC(datasetIdx,:);
                                            for decadeIdx = 1:numel(decadeStarts)
                                                startYear = decadeStarts(decadeIdx);
                                                endYear = min(startYear + 9, tEnd);
                                                % Convert years to month indices
                                                startMonth = (startYear - startYr) * 12 + 1;
                                                endMonth = (endYear - startYr + 1) * 12;
                                                % Ensure we don't exceed data bounds
                                                startMonth = max(startMonth, 1);
                                                endMonth = min(endMonth, length(monthly_data));

                                                decade_data = monthly_data(startMonth:endMonth);
                                                if any(~isnan(decade_data))
                                                    Hp_REC_decadal(datasetIdx,decadeIdx) = mean(decade_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Hp_REC_decadal, [], 1,'omitnan');
                                            max(Hp_REC_decadal, [], 1,'omitnan');
                                            mean(Hp_REC_decadal, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(applyBasinMask(app, app.PalazzoliLSTMData, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon),1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliLSTMnoLCSIFData, app.PalazzoliLSTMnoLCSIFLat, app.PalazzoliLSTMnoLCSIFLon),1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMData, app.PalazzoliBiLSTMLat, app.PalazzoliBiLSTMLon),1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(applyBasinMask(app, app.PalazzoliBiLSTMnoLCSIFData, app.PalazzoliBiLSTMnoLCSIFLat, app.PalazzoliBiLSTMnoLCSIFLon),1);

                                        % Convert monthly to decadal mean
                                        Pl_REC_decadal = NaN(4, numel(decadeStarts));
                                        for datasetIdx = 1:4
                                            monthly_data = Pl_REC(datasetIdx,:);
                                            for decadeIdx = 1:numel(decadeStarts)
                                                startYear = decadeStarts(decadeIdx);
                                                endYear = min(startYear + 9, tEnd);
                                                % Convert years to month indices
                                                startMonth = (startYear - startYr) * 12 + 1;
                                                endMonth = (endYear - startYr + 1) * 12;
                                                % Ensure we don't exceed data bounds
                                                startMonth = max(startMonth, 1);
                                                endMonth = min(endMonth, length(monthly_data));

                                                decade_data = monthly_data(startMonth:endMonth);
                                                if any(~isnan(decade_data))
                                                    Pl_REC_decadal(datasetIdx,decadeIdx) = mean(decade_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Pl_REC_decadal, [], 1,'omitnan');
                                            max(Pl_REC_decadal, [], 1,'omitnan');
                                            mean(Pl_REC_decadal, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(applyBasinMask(app, app.LiRECData, app.LiRECLat, app.LiRECLon),1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;

                                        % Convert monthly to decadal mean
                                        Li_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Li_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Li_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Li_REC_decadal,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(applyBasinMask(app, app.DengRECData, app.DengRECLat, app.DengRECLon),1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;

                                        % Convert monthly to decadal mean
                                        Deng_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Deng_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Deng_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Deng_REC_decadal,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(applyBasinMask(app, app.MandalRECData, app.MandalRECLat, app.MandalRECLon),1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;

                                        % Convert monthly to decadal mean
                                        Mandal_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Mandal_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Mandal_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Mandal_REC_decadal,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end

                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);
                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(applyBasinMask(app, app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon),1);
                                        JPL_TWSA = NaN(1,1500);
                                        JPL_TWSA(1,[app.JPLTWSMN]) = JPL_BA;
                                        % Convert monthly to decadal mean
                                        JPL_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = JPL_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                JPL_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(JPL_decadal,3,1);

                                    case 'csr'
                                        CSR_BA = 10*mean(applyBasinMask(app, app.CSRTWSData, app.CSRTWSLat, app.CSRTWSLon),1);
                                        CSR_TWSA = NaN(1,1500);
                                        CSR_TWSA(1,[app.CSRTWSMN]) = CSR_BA;
                                        % Convert monthly to decadal mean
                                        CSR_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = CSR_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                CSR_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(CSR_decadal,3,1);

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(applyBasinMask(app, app.GFSCTWSData, app.GFSCTWSLat, app.GFSCTWSLon),1);
                                        GSFC_TWSA = NaN(1,1500);
                                        GSFC_TWSA(1,[app.GFSCTWSMN]) = GSFC_BA;
                                        % Convert monthly to decadal mean
                                        GSFC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = GSFC_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                GSFC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(GSFC_decadal,3,1);

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                x_plot = 1:size(REC,2);

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x_plot, REC(3,x_plot), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));
                            end

                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(applyBasinMask(app, app.ThisStudyBestData, ...
                                            app.ThisStudyBestLat, app.ThisStudyBestLon),1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        % Convert monthly to decadal mean
                                        Best_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Best_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Best_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Best_decadal,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(applyBasinMask(app, app.ThisStudyANNData, ...
                                            app.ThisStudyANNLat, app.ThisStudyANNLon),1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        % Convert monthly to decadal mean
                                        ANN_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = ANN_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                ANN_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(ANN_decadal,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(applyBasinMask(app, app.ThisStudyLSTMData, ...
                                            app.ThisStudyLSTMLat, app.ThisStudyLSTMLon),1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        % Convert monthly to decadal mean
                                        LSTM_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = LSTM_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                LSTM_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(LSTM_decadal,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(applyBasinMask(app, app.ThisStudyRFData, ...
                                            app.ThisStudyRFLat, app.ThisStudyRFLon),1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        % Convert monthly to decadal mean
                                        RF_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = RF_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                RF_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(RF_decadal,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));

                            end

                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Decades)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;

                            hold(app.UIAxes,'off');

                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly) - convert to decadal mean annual precipitation
                                        app.IMD_BA = mean(applyBasinMask(app, app.IMDData, app.IMDLat, app.IMDLon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.IMDMN) = app.IMD_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end

                                    case 'gpcp'
                                        % GPCP (mm/month) - convert to decadal mean annual precipitation
                                        app.GPCP_BA = mean(applyBasinMask(app, app.GPCPData, app.GPCPLat, app.GPCPLon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.GPCPMN) = app.GPCP_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm and then decadal mean annual precipitation
                                        app.ERA5_BA = 1000*mean(applyBasinMask(app, app.ERA5Data, app.ERA5Lat, app.ERA5Lon),1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.ERA5MN) = app.ERA5_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What's selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min-max) behind mean
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end
                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the x window
                                    mask = (xdata >= min(x) & xdata <= max(x));
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');
                                    % Add padding for readability
                                    pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin - pad, twsMax + pad];

                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end
                            end

                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Decades)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;;

                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            lg.NumColumns = 9; % fallback
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');

                    end

                case 'Spatial'
                    %% ==================== INITIALIZE ====================
                    cla(app.UIAxes,'reset');
                    hold(app.UIAxes,'on');

                    % Time axis (master monthly line: Jan-1901 ... Dec-2025)
                    startDate   = datetime(1901,1,1);
                    endDate     = datetime(2025,12,1);
                    allTimeDates = startDate:calmonths(1):endDate;  % length = 1500

                    % Slider-selected window
                    sIdx = round(app.TimeSpan19012025Slider.Value(1));
                    eIdx = round(app.TimeSpan19012025Slider.Value(2));
                    sIdx = max(sIdx,1);
                    eIdx = min(eIdx, numel(allTimeDates));

                    % Numeric time in **years** from window start (used for slopes in mm/yr)
                    winDates = allTimeDates(sIdx:eIdx);
                    t = years(winDates - winDates(1));  % column t has units of years
                    x= sIdx:eIdx;
                    % Retrieve the selected basin
                    selectedBasin = app.BasinSelectionButton.Value;
                    % Load shapefile data
                    basins = app.Basins;

                    % Find the polygon corresponding to the selected basin
                    basinPolygon = [];
                    for i = 1:length(basins)
                        if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                            basinPolygon = [basins(i).X', basins(i).Y']; % Extract polygon coordinates
                            break;
                        end
                    end

                    % Clean polygon (remove NaNs)
                    basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                    % if isempty(basinPolygon)
                    %     uialert(app.UIFigure, ...
                    %         {'Please select a basin.', ...
                    %          'If selected, please ensure the selected', ...
                    %          'basin is available in the shapefile.'}, ...
                    %         'Warning', 'Icon','warning');
                    %     return;  % <<< Stop execution here
                    % end
                    % ==================== SELECTION VALIDATION ====================
                    % Collect all selected leaf nodes across all categories
                    selectedRTWSA = app.RTWSA.CheckedNodes;
                    selectedTWSA  = app.TWSA.CheckedNodes;
                    selectedThis  = app.This_Study.CheckedNodes;

                    % Combine only leaf nodes (no parents)
                    allSelected = [selectedRTWSA(~arrayfun(@(n) ~isempty(n.Children), selectedRTWSA)); ...
                        selectedTWSA(~arrayfun(@(n) ~isempty(n.Children), selectedTWSA)); ...
                        selectedThis(~arrayfun(@(n) ~isempty(n.Children), selectedThis))];

                    % Check how many datasets are selected
                    if numel(allSelected) > 1
                        uialert(app.UIFigure, ...
                            {'Spatial plots are available for one dataset at a time.', ...
                            'Please select only one dataset and try again.'}, ...
                            'Multiple Selections Detected', ...
                            'Icon', 'warning');
                        return;  % <<< Stop execution before any plotting
                    end

                    %% ==================== RECONSTRUCTED TREE ====================
                    selectedNodes = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodes)
                        % Skip parent nodes
                        if ~isempty(selectedNodes(i).Children), continue; end
                        rawName = strtrim(selectedNodes(i).Text); % clean up
                        nodeKey = lower(rawName); % normalize to lowercase
                        switch nodeKey
                            %% HUMPHREY (6 datasets)
                            case 'hg19'
                                cla(app.UIAxes);
                                [Hp_GERECmasked,latHp_GEREC, lonHp_GEREC] = applyBasinMaskWithCoords(app, app.Hp_GERECData, app.Hp_GERECLat, app.Hp_GERECLon);
                                Hp_GEREC = NaN(size(latHp_GEREC,1),1500);
                                Hp_GEREC(:,[app.Hp_GERECMN])= Hp_GERECmasked;
                                Hp_GERECmean=mean(Hp_GEREC(:,x),2,'omitnan');
                                plotSpatialBasinMap(app, lonHp_GEREC, latHp_GEREC, Hp_GERECmean, basinPolygon, ...
                                    'Humphrey Basin Mean (selected timespan)');

                                %% PALAZZOLI (LSTM)
                            case 'p25'
                                % --- 1. Apply basin mask with coordinates ---
                                [Pl_RECmasked, latPl_REC, lonPl_REC] = applyBasinMaskWithCoords(app, ...
                                    app.PalazzoliLSTMData, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon);

                                % Allocate container
                                Pl_REC = NaN(size(latPl_REC,1),1500);
                                Pl_REC(:,[app.PalazzoliLSTMMN]) = 10 * Pl_RECmasked;   % note the scaling factor *10

                                % Basin mean for selected time indices (x from slider)
                                Pl_RECmean = mean(Pl_REC(:,x),2,'omitnan');

                                plotSpatialBasinMap(app, lonPl_REC, latPl_REC, Pl_RECmean, basinPolygon, ...
                                    'Palazzoli Basin Mean (selected timespan)');
                                %% LI (single)
                            case 'l21'
                                % --- 1. Apply basin mask to Li data ---
                                [Li_RECmasked, latLi_REC, lonLi_REC] = applyBasinMaskWithCoords(app, ...
                                    app.LiRECData, app.LiRECLat, app.LiRECLon);

                                % Allocate container (like Hp_GEREC)
                                Li_REC = NaN(size(latLi_REC,1),1500);
                                Li_REC(:,[app.LiRECMN]) = 10*Li_RECmasked;

                                % Basin mean across selected time indices (x is from slider)
                                Li_RECmean = mean(Li_REC(:,x),2,'omitnan');

                                plotSpatialBasinMap(app, lonLi_REC, latLi_REC, Li_RECmean, basinPolygon, ...
                                    'Li Basin Mean (selected timespan)');

                                %% DENG (single)
                            case 'd23'
                                % --- 1. Apply basin mask with coordinates ---
                                [Deng_RECmasked, latDeng_REC, lonDeng_REC] = applyBasinMaskWithCoords(app, ...
                                    app.DengRECData, app.DengRECLat, app.DengRECLon);

                                % Allocate container (similar to Li/Hp)
                                Deng_REC = NaN(size(latDeng_REC,1),1500);
                                Deng_REC(:,[app.DengRECMN]) = Deng_RECmasked;

                                % Basin mean across selected time indices (x is from slider)
                                Deng_RECmean = mean(Deng_REC(:,x),2,'omitnan');
                                plotSpatialBasinMap(app, lonDeng_REC, latDeng_REC, Deng_RECmean, basinPolygon, ...
                                    'Deng Basin Mean (selected timespan)');

                                %
                                %% MANDAL (single)
                            case 'm25'
                                % --- 1. Apply basin mask with coordinates ---
                                [Mandal_RECmasked, latMandal_REC, lonMandal_REC] = applyBasinMaskWithCoords(app, ...
                                    app.MandalRECData, app.MandalRECLat, app.MandalRECLon);

                                % Allocate container (same style as hp/li/deng)
                                Mandal_REC = NaN(size(latMandal_REC,1),1500);
                                Mandal_REC(:,[app.MandalRECMN]) = Mandal_RECmasked;

                                % Basin mean for selected time indices (x from slider)
                                Mandal_RECmean = mean(Mandal_REC(:,x),2,'omitnan');
                                % Plot spatial map
                                plotSpatialBasinMap(app, lonMandal_REC, latMandal_REC, Mandal_RECmean, basinPolygon, ...
                                    'Mandal Basin Mean (selected timespan)');

                        end
                    end
                    % Get selected GRACE datasets
                    selectedNodes = app.TWSA.CheckedNodes;
                    hold(app.UIAxes,'on');
                    for i = 1:numel(selectedNodes)
                        % Skip non-leaf nodes
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                        nodeKey = lower(rawName);
                        switch nodeKey
                            %% JPL (mascon)
                            case 'jpl'
                                [JPL_masked, latJPL, lonJPL] = applyBasinMaskWithCoords(app, ...
                                    app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);

                                JPL_REC = NaN(size(latJPL,1),1500);
                                JPL_REC(:,app.JPLTWSMN) = 10 * JPL_masked;

                                JPLmean = mean(JPL_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonJPL, latJPL, JPLmean, basinPolygon, ...
                                    'JPL Mascon Basin Mean (selected timespan)');

                                %% CSR (mascon)
                            case 'csr'
                                [CSR_masked, latCSR, lonCSR] = applyBasinMaskWithCoords(app, ...
                                    app.CSRTWSData, app.CSRTWSLat, app.CSRTWSLon);

                                CSR_REC = NaN(size(latCSR,1),1500);
                                CSR_REC(:,app.CSRTWSMN) = 10 * CSR_masked;

                                CSRmean = mean(CSR_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonCSR, latCSR, CSRmean, basinPolygon, ...
                                    'CSR Mascon Basin Mean (selected timespan)');

                                %% GSFC (mascon)
                            case 'gsfc'
                                [GSFC_masked, latGSFC, lonGSFC] = applyBasinMaskWithCoords(app, ...
                                    app.GFSCTWSData, app.GFSCTWSLat, app.GFSCTWSLon);

                                GSFC_REC = NaN(size(latGSFC,1),1500);
                                GSFC_REC(:,app.GFSCTWSMN) = 10 * GSFC_masked;

                                GSFCmean = mean(GSFC_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonGSFC, latGSFC, GSFCmean, basinPolygon, ...
                                    'GSFC Mascon Basin Mean (selected timespan)');
                        end
                    end
                    selectedNodes = app.This_Study.CheckedNodes;
                    hold(app.UIAxes,'on');

                    for i = 1:numel(selectedNodes)
                        % Skip parent nodes
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                        nodeKey = lower(rawName);                 % normalize to lowercase
                        switch nodeKey
                            %% BEST
                            case 'rf_imd'
                                [Best_masked, latBest, lonBest] = applyBasinMaskWithCoords(app, ...
                                    app.ThisStudyBestData, app.ThisStudyBestLat, app.ThisStudyBestLon);

                                Best_REC = NaN(size(latBest,1),1500);
                                Best_REC(:,app.ThisStudyBestMN) = Best_masked;

                                BestMean = mean(Best_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonBest, latBest, BestMean, basinPolygon, ...
                                    'BEST Basin Mean (selected timespan)');

                                %% ANN
                            case 'ann_g'
                                [ANN_masked, latANN, lonANN] = applyBasinMaskWithCoords(app, ...
                                    app.ThisStudyANNData, app.ThisStudyANNLat, app.ThisStudyANNLon);

                                ANN_REC = NaN(size(latANN,1),1500);
                                ANN_REC(:,app.ThisStudyANNMN) = ANN_masked;

                                ANNMean = mean(ANN_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonANN, latANN, ANNMean, basinPolygon, ...
                                    'ANN Basin Mean (selected timespan)');

                                %% LSTM
                            case 'ann_imd'
                                [LSTM_masked, latLSTM, lonLSTM] = applyBasinMaskWithCoords(app, ...
                                    app.ThisStudyLSTMData, app.ThisStudyLSTMLat, app.ThisStudyLSTMLon);

                                LSTM_REC = NaN(size(latLSTM,1),1500);
                                LSTM_REC(:,app.ThisStudyLSTMMN) = LSTM_masked;

                                LSTMMean = mean(LSTM_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonLSTM, latLSTM, LSTMMean, basinPolygon, ...
                                    'LSTM Basin Mean (selected timespan)');

                                %% RF
                            case 'rf_g'
                                [RF_masked, latRF, lonRF] = applyBasinMaskWithCoords(app, ...
                                    app.ThisStudyRFData, app.ThisStudyRFLat, app.ThisStudyRFLon);

                                RF_REC = NaN(size(latRF,1),1500);
                                RF_REC(:,app.ThisStudyRFMN) = RF_masked;

                                RFMean = mean(RF_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonRF, latRF, RFMean, basinPolygon, ...
                                    'RF Basin Mean (selected timespan)');
                        end
                    end
                case 'Regional Mean'
                    cla(app.UIAxes, 'reset');
                    hold(app.UIAxes, 'on');

                    % Check if more than one dataset is selected
                    totalSelected = 0;

                    % Count selected nodes from all sections
                    selectedNodesRTWSA = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodesRTWSA)
                        if isempty(selectedNodesRTWSA(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    selectedNodesTWSA = app.TWSA.CheckedNodes;
                    for i = 1:numel(selectedNodesTWSA)
                        if isempty(selectedNodesTWSA(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    selectedNodesThisStudy = app.This_Study.CheckedNodes;
                    for i = 1:numel(selectedNodesThisStudy)
                        if isempty(selectedNodesThisStudy(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    % If more than one dataset selected, show warning and return
                    if totalSelected > 1
                        uialert(app.UIFigure, ...
                            'Please check only one dataset at a time for Regional Mean plot.', ...
                            'Multiple Datasets Selected', 'Icon', 'warning');
                        hold(app.UIAxes, 'off');
                        return;
                    end

                    % Define the starting and ending dates for the slider scale
                    startDate = datetime(1901, 1, 1); % Start date (01-1901)
                    endDate = datetime(2025, 12, 1);  % End date (12-2025)
                    % Generate all months between the start and end dates
                    allTimeDates = startDate:calmonths(1):endDate;
                    % Get selected time range from slider
                    sIdx = round(app.TimeSpan19012025Slider.Value(1));
                    eIdx = round(app.TimeSpan19012025Slider.Value(2));
                    sIdx = max(sIdx,1);
                    eIdx = min(eIdx, numel(allTimeDates));
                    selectedTimeRange = allTimeDates(sIdx:eIdx);
                    x = sIdx:eIdx;
                    % Get start & end years of current visible time range
                    tStart = year(selectedTimeRange(1));
                    tEnd   = year(selectedTimeRange(end));
                    xlim(app.UIAxes, [sIdx eIdx]);
                    % Calculate total span in years
                    spanYears = tEnd - tStart;

                    % Choose a step size dynamically (prevent overlap)
                    if spanYears > 40
                        step = 10;   % e.g., large range → coarser ticks
                    elseif spanYears > 20
                        step = 5;
                    elseif spanYears > 10
                        step = 2;
                    else
                        step = 1;    % short range → finer ticks
                    end

                    % Define ticks
                    tickYears = tStart:step:tEnd;

                    % % Axis ticks every 2 years
                    % tickYears = year(selectedTimeRange(1)) : 2 : year(selectedTimeRange(end));
                    xt = datetime(tickYears, 1, 1);
                    % Convert datetime to indices relative to allTimeDates
                    [~, idxTicks] = ismember(xt, allTimeDates);
                    % Apply ticks and labels
                    app.UIAxes.XTick = idxTicks;
                    app.UIAxes.XTickLabel = cellstr(datestr(xt, 'yyyy'));

                    % Initialize variables for regional plot
                    regionalData = [];
                    regionalLats = [];
                    datasetName = '';

                    %% ==================== PROCESS RECONSTRUCTED TREE DATA ====================
                    selectedNodes = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodes)
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text);
                        nodeKey = lower(rawName);

                        switch nodeKey
                            %% HUMPHREY (6 datasets)
                            case 'hg19'
                                [Hp_GERECmasked, latHp_GEREC, lonHp_GEREC] = applyBasinMaskWithCoords(app, app.Hp_GERECData, app.Hp_GERECLat, app.Hp_GERECLon);

                                if ~isempty(Hp_GERECmasked)
                                    % Create full timeline container
                                    Hp_GEREC = NaN(size(latHp_GEREC,1), 1500);
                                    Hp_GEREC(:, app.Hp_GERECMN) = Hp_GERECmasked;

                                    % Extract selected time range
                                    Hp_GEREC_selected = Hp_GEREC(:, x);

                                    % Group by latitude and compute regional mean (latitude-time matrix)
                                    [uniqueLats, ~, idx] = unique(latHp_GEREC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latHp_GEREC == uniqueLats(j));
                                        % Average over all points at this latitude (regional mean)
                                        regionalMeanData(j, :) = mean(Hp_GEREC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Humphrey et al. 2019';
                                end

                                %% PALAZZOLI (LSTM)
                            case 'p25'
                                [Pl_RECmasked, latPl_REC, lonPl_REC] = applyBasinMaskWithCoords(app, ...
                                    app.PalazzoliLSTMData, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon);

                                if ~isempty(Pl_RECmasked)
                                    Pl_REC = NaN(size(latPl_REC,1), 1500);
                                    Pl_REC(:, app.PalazzoliLSTMMN) = 10 * Pl_RECmasked;

                                    Pl_REC_selected = Pl_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latPl_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latPl_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Pl_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Palazzoli et al. 2025';
                                end

                                %% LI (single)
                            case 'l21'
                                [Li_RECmasked, latLi_REC, lonLi_REC] = applyBasinMaskWithCoords(app, ...
                                    app.LiRECData, app.LiRECLat, app.LiRECLon);

                                if ~isempty(Li_RECmasked)
                                    Li_REC = NaN(size(latLi_REC,1), 1500);
                                    Li_REC(:, app.LiRECMN) = 10 * Li_RECmasked;

                                    Li_REC_selected = Li_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latLi_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latLi_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Li_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Li et al. 2021';
                                end

                                %% DENG (single)
                            case 'd23'
                                [Deng_RECmasked, latDeng_REC, lonDeng_REC] = applyBasinMaskWithCoords(app, ...
                                    app.DengRECData, app.DengRECLat, app.DengRECLon);

                                if ~isempty(Deng_RECmasked)
                                    Deng_REC = NaN(size(latDeng_REC,1), 1500);
                                    Deng_REC(:, app.DengRECMN) = Deng_RECmasked;

                                    Deng_REC_selected = Deng_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latDeng_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latDeng_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Deng_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Deng et al. 2023';
                                end

                                %% MANDAL (single)
                            case 'm25'
                                [Mandal_RECmasked, latMandal_REC, lonMandal_REC] = applyBasinMaskWithCoords(app, ...
                                    app.MandalRECData, app.MandalRECLat, app.MandalRECLon);

                                if ~isempty(Mandal_RECmasked)
                                    Mandal_REC = NaN(size(latMandal_REC,1), 1500);
                                    Mandal_REC(:, app.MandalRECMN) = Mandal_RECmasked;

                                    Mandal_REC_selected = Mandal_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latMandal_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latMandal_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Mandal_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Mandal et al. 2025';
                                end
                        end

                        if ~isempty(regionalData)
                            break; % Only process first selected dataset
                        end
                    end

                    %% ==================== PROCESS GRACE DATA ====================
                    if isempty(regionalData)
                        selectedNodes = app.TWSA.CheckedNodes;
                        for i = 1:numel(selectedNodes)
                            if ~isempty(selectedNodes(i).Children), continue; end

                            rawName = strtrim(selectedNodes(i).Text);
                            nodeKey = lower(rawName);

                            switch nodeKey
                                %% JPL (mascon)
                                case 'jpl'
                                    [JPL_masked, latJPL, lonJPL] = applyBasinMaskWithCoords(app, ...
                                        app.JPLTWSData, app.JPLTWSLat, app.JPLTWSLon);

                                    if ~isempty(JPL_masked)
                                        JPL_REC = NaN(size(latJPL,1), 1500);
                                        JPL_REC(:, app.JPLTWSMN) = 10 * JPL_masked;

                                        JPL_selected = JPL_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latJPL);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latJPL == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(JPL_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'JPL Mascon';
                                    end

                                    %% CSR (mascon)
                                case 'csr'
                                    [CSR_masked, latCSR, lonCSR] = applyBasinMaskWithCoords(app, ...
                                        app.CSRTWSData, app.CSRTWSLat, app.CSRTWSLon);

                                    if ~isempty(CSR_masked)
                                        CSR_REC = NaN(size(latCSR,1), 1500);
                                        CSR_REC(:, app.CSRTWSMN) = 10 * CSR_masked;

                                        CSR_selected = CSR_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latCSR);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latCSR == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(CSR_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'CSR Mascon';
                                    end

                                    %% GSFC (mascon)
                                case 'gsfc'
                                    [GSFC_masked, latGSFC, lonGSFC] = applyBasinMaskWithCoords(app, ...
                                        app.GFSCTWSData, app.GFSCTWSLat, app.GFSCTWSLon);

                                    if ~isempty(GSFC_masked)
                                        GSFC_REC = NaN(size(latGSFC,1), 1500);
                                        GSFC_REC(:, app.GFSCTWSMN) = 10 * GSFC_masked;

                                        GSFC_selected = GSFC_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latGSFC);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latGSFC == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(GSFC_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'GSFC Mascon';
                                    end
                            end

                            if ~isempty(regionalData)
                                break;
                            end
                        end
                    end

                    %% ==================== PROCESS THIS STUDY DATA ====================
                    if isempty(regionalData)
                        selectedNodes = app.This_Study.CheckedNodes;
                        for i = 1:numel(selectedNodes)
                            if ~isempty(selectedNodes(i).Children), continue; end

                            rawName = strtrim(selectedNodes(i).Text);
                            nodeKey = lower(rawName);

                            switch nodeKey
                                %% BEST
                                case 'rf_imd'
                                    [Best_masked, latBest, lonBest] = applyBasinMaskWithCoords(app, ...
                                        app.ThisStudyBestData, app.ThisStudyBestLat, app.ThisStudyBestLon);

                                    if ~isempty(Best_masked)
                                        Best_REC = NaN(size(latBest,1), 1500);
                                        Best_REC(:, app.ThisStudyBestMN) = Best_masked;

                                        Best_selected = Best_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latBest);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latBest == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(Best_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'RF_IMD (This Study)';
                                    end

                                    %% ANN
                                case 'ann_g'
                                    [ANN_masked, latANN, lonANN] = applyBasinMaskWithCoords(app, ...
                                        app.ThisStudyANNData, app.ThisStudyANNLat, app.ThisStudyANNLon);

                                    if ~isempty(ANN_masked)
                                        ANN_REC = NaN(size(latANN,1), 1500);
                                        ANN_REC(:, app.ThisStudyANNMN) = ANN_masked;

                                        ANN_selected = ANN_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latANN);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latANN == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(ANN_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'ANN_GLDAS (This Study)';
                                    end

                                    %% LSTM
                                case 'ann_imd'
                                    [LSTM_masked, latLSTM, lonLSTM] = applyBasinMaskWithCoords(app, ...
                                        app.ThisStudyLSTMData, app.ThisStudyLSTMLat, app.ThisStudyLSTMLon);

                                    if ~isempty(LSTM_masked)
                                        LSTM_REC = NaN(size(latLSTM,1), 1500);
                                        LSTM_REC(:, app.ThisStudyLSTMMN) = LSTM_masked;

                                        LSTM_selected = LSTM_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latLSTM);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latLSTM == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(LSTM_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'ANN_IMD (This Study)';
                                    end

                                    %% RF
                                case 'rf_g'
                                    [RF_masked, latRF, lonRF] = applyBasinMaskWithCoords(app, ...
                                        app.ThisStudyRFData, app.ThisStudyRFLat, app.ThisStudyRFLon);

                                    if ~isempty(RF_masked)
                                        RF_REC = NaN(size(latRF,1), 1500);
                                        RF_REC(:, app.ThisStudyRFMN) = RF_masked;

                                        RF_selected = RF_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latRF);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latRF == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(RF_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'RF_GLDAS (This Study)';
                                    end
                            end

                            if ~isempty(regionalData)
                                break;
                            end
                        end
                    end


                    %% ==================== CREATE REGIONAL MEAN CONTOUR PLOT ====================
                    if ~isempty(regionalData) && ~isempty(regionalLats)
                        % Convert datetime to simple year numbers
                        if isdatetime(selectedTimeRange)
                            numericTime = year(selectedTimeRange);
                        else
                            numericTime = double(selectedTimeRange);
                        end

                        % Ensure other variables are numeric
                        regionalLats = double(regionalLats);
                        regionalData = double(regionalData);


                        % Replace NaN with a sentinel value outside data range
                        dataMin = min(regionalData(:), [], 'omitnan');
                        dataMax = max(regionalData(:), [], 'omitnan');
                        maskValue = 0; %dataMin - 999; % something way below min

                        regionalDataMasked = regionalData;
                        % regionalDataMasked(isnan(regionalData)) = maskValue;

                        [T, L] = meshgrid(x, regionalLats);
                        % Clear the axes before plotting
                        cla(app.UIAxes);

                        % Ensure the axes are held correctly
                        hold(app.UIAxes, 'on');
                        contourf(app.UIAxes, T, L, regionalDataMasked, 5, 'LineStyle', 'none');
                        baseColors = [...
                            103 0   31;   % dark red
                            178 24  43;   % medium red
                            214 96  77;   % light red
                            244 165 130;  % very light red
                            247 247 247;  % white
                            146 197 222;  % light blue
                            67  147 195;  % medium blue
                            33  102 172;  % darker blue
                            5   48  97];  % darkest blue

                        % Normalize to [0,1]
                        baseColors = baseColors ./ 255;

                        % --- Interpolate colormap ---
                        x  = linspace(0, 1, size(baseColors,1));
                        xi = linspace(0, 1, 256);
                        cmap = interp1(x, baseColors, xi, 'linear');
                        colormap(app.UIAxes, cmap);
                        colorbar(app.UIAxes);
                        % --- Symmetric color axis ---

                        cabs = max(abs([dataMin dataMax]));
                        clim(app.UIAxes, [-cabs cabs]);


                        xlabel(app.UIAxes, 'Time (Years)', 'FontSize', 12, 'FontName', 'Times New Roman');
                        ylabel(app.UIAxes, 'Latitude', 'FontSize', 12, 'FontName', 'Times New Roman');
                        title(app.UIAxes, ['Regional Mean Plot - ' datasetName], 'FontSize', 14, 'FontName', 'Times New Roman');

                        app.UIAxes.FontName = 'Times New Roman';
                        app.UIAxes.FontSize = 11;
                        grid(app.UIAxes, 'on');
                        drawnow;


                        % Ensure the axes are held correctly
                        hold(app.UIAxes, 'off');

                    else
                        % No data selected or no data in basin
                        % Clear previous text
                        cla(app.UIAxes, 'reset');

                        % Hold off so text is centered cleanly
                        hold(app.UIAxes, 'off');

                        % Set normalized coordinate system
                        text(app.UIAxes, 0.5, 0.5, ...
                            'No data available for selected basin or no dataset selected', ...
                            'Units', 'normalized', ...  % <<< KEY FIX
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 14, ...
                            'FontName', 'Times New Roman', ...
                            'FontWeight', 'bold');

                        % Optionally remove tick marks for cleaner look
                        app.UIAxes.XTick = [];
                        app.UIAxes.YTick = [];
                        title(app.UIAxes, '');
                        xlabel(app.UIAxes, '');
                        ylabel(app.UIAxes, '');

                    end

                    hold(app.UIAxes, 'off');

            end
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            % value = app.LoadDataButton.Value;
            % Get screen size [x y width height]
            % screenSize = get(0,'ScreenSize');
            %
            % % Design size (from your UIFigure in Design View)
            % designWidth = 1212;
            % designHeight = 764;
            %
            % % Compute scale factor relative to screen size
            % scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            %
            % % Apply new scaled size (use 85% of available screen for safety)
            % newWidth = round(designWidth * scale* 0.85);%* 0.85
            % newHeight = round(designHeight * scale* 0.85);
            %
            % % Update UIFigure size
            % app.UIFigure.Position = [50 50 newWidth newHeight];
            %
            % % Center the app
            % movegui(app.UIFigure, 'onscreen');


            % Set auto-resize behavior
            %app.UIFigure.AutoResizeChildren = 'on';

            % % Set normalized units for axes to make them responsive
            % app.UIAxes.Units = 'normalized';
            % app.UIAxes.Position = [0.1 0.25 0.85 0.75]; % Adjust as needed
            % This function loads all required datasets into the app properties
            % Ensure to set the correct paths and variable names for each dataset
            % Path settings (update these paths as needed)
try
    shapefilePath = "./Data/River_Shps/mrb_basins.shp";

    % Load the shapefile
    app.Basins = shaperead(shapefilePath);

catch ME
    warning(ME.identifier, 'Shapefile is not available at %s. Message: %s', ...
        shapefilePath, ME.message);
end
% value = app.LoadDataButton.Value;
% Load Data Button callback


    % Clear existing data first (except basin shapefile)
    app.clearBasinData();
    
    % Get selected basin
    selectedBasin = app.BasinSelectionButton.Value;
    if strcmpi(selectedBasin, 'Select Basin') || isempty(selectedBasin)
        uialert(app.UIFigure, 'Please select a basin from the dropdown first.', 'No Basin Selected', 'Icon', 'warning');
        return;
    end
    
    % Load basin shapefile if not already loaded
    try
        if isempty(app.Basins)
            shapefilePath = "./Data/River_Shps/mrb_basins.shp";
            app.Basins = shaperead(shapefilePath);
        end
        
        % Check if selected basin exists in shapefile
        basinExists = false;
        basinPolygon = [];
        
        for i = 1:length(app.Basins)
            if strcmpi(app.Basins(i).RIVER_BASI, selectedBasin)
                basinExists = true;
                basinPolygon = [app.Basins(i).X', app.Basins(i).Y'];
                break;
            end
        end
        
        if ~basinExists
            uialert(app.UIFigure, ...
                sprintf('Basin "%s" not found in the shapefile. Please select a valid basin.', selectedBasin), ...
                'Basin Not Available', 'Icon', 'error');
            return;
        end
        
        if ~isempty(basinPolygon)
            basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);
        end
        
        if isempty(basinPolygon)
            uialert(app.UIFigure, 'Invalid basin polygon in shapefile.', 'Error', 'Icon', 'error');
            return;
        end
        
    catch ME
        uialert(app.UIFigure, ...
            sprintf('Error loading basin shapefile: %s', ME.message), ...
            'Shapefile Error', 'Icon', 'error');
        return;
    end
    
    % Create progress dialog
    d = uiprogressdlg(app.UIFigure, 'Title', 'Loading Data', ...
        'Message', sprintf('Loading data for %s basin...', selectedBasin), ...
        'Indeterminate', 'on');
    
    try
        % ==================== PRECIPITATION DATASETS ====================
        d.Message = 'Loading IMD Precipitation data...';
        IMDFile = "./Data/Precipitation/IMD/IMD_Monthly_1901_2023.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(IMDFile, 'RAINFALL','LATITUDE','LONGITUDE','time');
        [app.IMDData, app.IMDTime, app.IMDLat, app.IMDLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading GPCP Precipitation data...';
        GPCPFile = "./Data/Precipitation/GPCP/GPCP_Total_Monthly_Precipitation_mm.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(GPCPFile, 'tp_monthly_total','lat','lon','time');
        [app.GPCPData, app.GPCPTime, app.GPCPLat, app.GPCPLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading ERA5 Precipitation data...';
        ERA5File = "./Data/Precipitation/ERA5/ERA5_Total_Monthly_Precipitation_mm_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(ERA5File, 'tp_monthly_total','lat','lon','valid_time');
        [app.ERA5Data, app.ERA5Time, app.ERA5Lat, app.ERA5Lon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== HUMHREY RECONSTRUCTION DATASETS ====================
        d.Message = 'Loading Humphrey reconstruction data...';
        baseDir = fullfile('Data', 'Reconstructed_TWSA', 'Humphrey_2019');
        Hp_GHFile = fullfile(baseDir, 'modified_GRACE_REC_v03_G_E_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_GHFile, 'TWS','lat','lon','time');
        [app.Hp_GERECData, app.Hp_GERECTime, app.Hp_GERECLat, app.Hp_GERECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        Hp_GGFile = fullfile(baseDir, 'modified_GRACE_REC_v03_GSFC_GSWP3_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_GGFile, 'TWS','lat','lon','time');
        [app.Hp_GGRECData, app.Hp_GGRECTime, app.Hp_GGRECLat, app.Hp_GGRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        Hp_GMFile = fullfile(baseDir, 'modified_GRACE_REC_v03_GSFC_MSWEP_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_GMFile, 'TWS','lat','lon','time');
        [app.Hp_GMRECData, app.Hp_GMRECTime, app.Hp_GMRECLat, app.Hp_GMRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        Hp_JEFile = fullfile(baseDir, 'modified_GRACE_REC_v03_J_E_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_JEFile, 'TWS','lat','lon','time');
        [app.Hp_JERECData, app.Hp_JERECTime, app.Hp_JERECLat, app.Hp_JERECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        Hp_JGFile = fullfile(baseDir, 'modified_GRACE_REC_v03_JPL_GSWP3_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_JGFile, 'TWS','lat','lon','time');
        [app.Hp_JGRECData, app.Hp_JGRECTime, app.Hp_JGRECLat, app.Hp_JGRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        Hp_JMFile = fullfile(baseDir, 'modified_GRACE_REC_v03_JPL_MSWEP_monthly_ensemble_mean_174_MRB.nc');
        [data, time, lat, lon, ~] = app.loadNetCDFData(Hp_JMFile, 'TWS','lat','lon','time');
        [app.Hp_JMRECData, app.Hp_JMRECTime, app.Hp_JMRECLat, app.Hp_JMRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== OTHER RECONSTRUCTION DATASETS ====================
        d.Message = 'Loading Deng 2023 reconstruction...';
        DengFile = "./Data/Reconstructed_TWSA/Deng_2023/GRID_JPL_ERA_REC_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(DengFile, 'twsa_rec','lat','lon','time');
        [app.DengRECData, app.DengRECTime, app.DengRECLat, app.DengRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading Li 2021 reconstruction...';
        LiFile = "./Data/Reconstructed_TWSA/Li_2021/GRACE_REC_Li_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(LiFile, 'lwe_thickness','lat','lon','time');
        [app.LiRECData, app.LiRECTime, app.LiRECLat, app.LiRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading Mandal 2025 reconstruction...';
        MandalFile = "./Data/Reconstructed_TWSA/Mandal_2025/BNML_TWSA_Global_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(MandalFile, 'bnml_twsa','lat','lon','time');
        [app.MandalRECData, app.MandalRECTime, app.MandalRECLat, app.MandalRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== PALAZZOLI RECONSTRUCTION DATASETS ====================
        d.Message = 'Loading Palazzoli 2025 reconstructions...';
        PalazzoliFile1 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_LSTM_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(PalazzoliFile1, 'TWSA','lat','lon','time');
        [app.PalazzoliLSTMData, app.PalazzoliLSTMTime, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        PalazzoliFile2 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_LSTMnoLCSIF_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(PalazzoliFile2, 'TWSA','lat','lon','time');
        [app.PalazzoliLSTMnoLCSIFData, app.PalazzoliLSTMnoLCSIFTime, app.PalazzoliLSTMnoLCSIFLat, app.PalazzoliLSTMnoLCSIFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        PalazzoliFile3 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_BiLSTM_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(PalazzoliFile3, 'TWSA','lat','lon','time');
        [app.PalazzoliBiLSTMData, app.PalazzoliBiLSTMTime, app.PalazzoliBiLSTMLat, app.PalazzoliBiLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        PalazzoliFile4 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_BiLSTMnoLCSIF_174_MRB.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(PalazzoliFile4, 'TWSA','lat','lon','time');
        [app.PalazzoliBiLSTMnoLCSIFData, app.PalazzoliBiLSTMnoLCSIFTime, app.PalazzoliBiLSTMnoLCSIFLat, app.PalazzoliBiLSTMnoLCSIFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== THIS STUDY DATASETS ====================
        d.Message = 'Loading This Study reconstructions...';
        ThisStudyFile1 = "./Data/This_Study/RTWSA_GRACE_IMD_RF_Godavari.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(ThisStudyFile1, 'TWSA','lat','lon','time');
        [app.ThisStudyBestData, app.ThisStudyBestTime, app.ThisStudyBestLat, app.ThisStudyBestLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        ThisStudyFile2 = "./Data/This_Study/RTWSA_GRACE_ANN_Godavari.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(ThisStudyFile2, 'TWSA','lat','lon','time');
        [app.ThisStudyANNData, app.ThisStudyANNTime, app.ThisStudyANNLat, app.ThisStudyANNLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        ThisStudyFile3 = "./Data/This_Study/RTWSA_GRACE_IMD_ANN_Godavari.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(ThisStudyFile3, 'TWSA','lat','lon','time');
        [app.ThisStudyLSTMData, app.ThisStudyLSTMTime, app.ThisStudyLSTMLat, app.ThisStudyLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        ThisStudyFile4 = "./Data/This_Study/RTWSA_GRACE_RF_Godavari.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(ThisStudyFile4, 'TWSA','lat','lon','time');
        [app.ThisStudyRFData, app.ThisStudyRFTime, app.ThisStudyRFLat, app.ThisStudyRFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== GRACE DATASETS ====================
        d.Message = 'Loading GRACE JPL data...';
        JPLFile = "./Data/GRACE/JPL/GRCTellus.JPL.200204_202508.GLO.RL06.3M.MSCNv04CRI.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(JPLFile,'lwe_thickness','lat','lon','time');
        [app.JPLTWSData, app.JPLTWSTime, app.JPLTWSLat, app.JPLTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading GRACE CSR data...';
        CSRFile = "./Data/GRACE/CSR/CSR_GRACE_GRACE-FO_RL0603_Mascons_all-corrections.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(CSRFile, 'lwe_thickness','lat','lon','time');
        [app.CSRTWSData, app.CSRTWSTime, app.CSRTWSLat, app.CSRTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        d.Message = 'Loading GRACE GFSC data...';
        GFSCFile = "./Data/GRACE/GFSC/GSFC_200204_202410.nc";
        [data, time, lat, lon, ~] = app.loadNetCDFData(GFSCFile,'lwe_thickness','lat','lon','time');
        [app.GFSCTWSData, app.GFSCTWSTime, app.GFSCTWSLat, app.GFSCTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== GLDAS DATASETS ====================
        d.Message = 'Loading GLDAS datasets...';
        GLDASFile = "./Data/GLDAS/GLDAS_NOAH_1948_2024_JPLgrid_174_MRB.nc";
        
        % Precipitation
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Rainf_f_tavg', 'lat', 'lon', 'time');
        [app.GLDAS_PData, app.GLDAS_PTime, app.GLDAS_PLat, app.GLDAS_PLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Evapotranspiration
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Evap_tavg', 'lat', 'lon', 'time');
        [app.GLDAS_ETData, app.GLDAS_ETTime, app.GLDAS_ETLat, app.GLDAS_ETLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Soil Moisture
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Soil_Moisture', 'lat', 'lon', 'time');
        [app.GLDAS_SMData, app.GLDAS_SMTime, app.GLDAS_SMLat, app.GLDAS_SMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Air Temperature
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Tair_f_inst', 'lat', 'lon', 'time');
        [app.GLDAS_TData, app.GLDAS_TTime, app.GLDAS_TLat, app.GLDAS_TLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Wind Speed
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Wind_f_inst', 'lat', 'lon', 'time');
        [app.GLDAS_WSData, app.GLDAS_WSTime, app.GLDAS_WSLat, app.GLDAS_WSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Specific Humidity
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Qair_f_inst', 'lat', 'lon', 'time');
        [app.GLDAS_SHData, app.GLDAS_SHTime, app.GLDAS_SHLat, app.GLDAS_SHLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Longwave Radiation
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'LWdown_f_tavg', 'lat', 'lon', 'time');
        [app.GLDAS_LWData, app.GLDAS_LWTime, app.GLDAS_LWLat, app.GLDAS_LWLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % Shortwave Radiation
        [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'SWdown_f_tavg', 'lat', 'lon', 'time');
        [app.GLDAS_SWData, app.GLDAS_SWTime, app.GLDAS_SWLat, app.GLDAS_SWLon] = app.extractBasinData(data, time, lat, lon, basinPolygon);
        clear data time lat lon;
        
        % ==================== CGWB DATA ====================
        d.Message = 'Loading CGWB groundwater data...';
        try
            excelFile = "./Data/CGWB/OB_Wells_Godavari.xlsx";
            app.CGWBData = readtable(excelFile);
            
            % Extract year columns (2002 to 2018)
            yearCols = [];
            years = [];
            for i = 1:width(app.CGWBData)
                colName = app.CGWBData.Properties.VariableNames{i};
                yearMatch = regexp(colName, '\d{4}', 'match');
                if ~isempty(yearMatch)
                    year = str2double(yearMatch{1});
                    if year >= 2002 && year <= 2018
                        yearCols = [yearCols, i];
                        years = [years, year];
                    end
                end
            end
            
            [years, sortIdx] = sort(years);
            yearCols = yearCols(sortIdx);
            
            T_data = app.CGWBData{:, yearCols};
            T_scaled = 1000 * 0.05 * T_data;
            
            climYears = [2004, 2005, 2006, 2007, 2008, 2009];
            climIdx = ismember(years, climYears);
            T_clim = mean(T_scaled(:, climIdx), 2, 'omitnan');
            
            app.CGWBAnomalies = T_scaled - T_clim;
            app.CGWBAvg = mean(app.CGWBAnomalies, 1, 'omitnan');
            
            app.CGWBLat = app.CGWBData{:, 'LAT'};
            app.CGWBLon = app.CGWBData{:, 'LON'};
            
            app.CGWBTime = datetime.empty;
            years = 2002:2018;
            for y = years
                for m = [1,5,8,11]
                    app.CGWBTime = [app.CGWBTime; datetime(y,m,1)];
                end
            end
            
        catch ME2
            warning('Could not load CGWB data: %s', ME2.message);
            app.CGWBData = [];
            app.CGWBAvg = [];
            app.CGWBLat = [];
            app.CGWBLon = [];
            app.CGWBTime = [];
        end
        
        % ==================== CLEANUP AND FINALIZATION ====================
        close(d); % Close progress dialog
        
        % Force garbage collection
        pause(0.1);
        clearvars -except app selectedBasin;
        
        % Store selected basin name for reference
        app.CurrentBasin = selectedBasin;
        
        % Enable analysis buttons if needed
        if ~isempty(app.IMDData)
            % Enable other buttons in your GUI
            % app.AnalysisButton.Enable = 'on';
            % app.PlotButton.Enable = 'on';
        end
        
        uialert(app.UIFigure, ...
            sprintf('Data for %s basin loaded successfully!\n\nMemory usage optimized: Only basin data stored.', selectedBasin), ...
            'Data Loading Complete', 'Icon', 'success');
        
    catch ME
        % Close progress dialog if it exists
        if exist('d', 'var') && isvalid(d)
            close(d);
        end
        
        uialert(app.UIFigure, ...
            sprintf('Error loading data: %s\n\nLine: %d', ME.message, ME.stack(1).line), ...
            'Data Loading Failed', 'Icon', 'error');
        
        % Clear any partially loaded data
        app.clearBasinData();
    end


        end

        % Value changed function: TimeSpan19012025Slider
        function TimeSpanTimeSpan19012025SliderValueChanging(app, event)
            timescales = app.TimescaleDropDown.Value;
            %Callback function for TimeSpanSlider value changing
            %Get the current timescale selection
            switch timescales
                case 'Monthly'
                    % Define the starting and ending dates for the slider scale
                    startDate = datetime(1901, 1, 1);
                    endDate   = datetime(2025, 12, 1);

                    % Generate all months between the start and end dates
                    allTimeDates = startDate:calmonths(1):endDate;
                    totalMonths = numel(allTimeDates);

                    % Slider limits
                    app.TimeSpan19012025Slider.Limits = [1, totalMonths];

                    % --- Compute tick positions ---
                    years = year(startDate):year(endDate);

                    % Choose step (e.g., every 10 years to avoid overlap)
                    tickStep = 10;
                    yearsShown = years(mod(years, tickStep) == 0);  % pick 1910, 1920, ...

                    % Find January positions for chosen years
                    yearDates = datetime(yearsShown,1,1);
                    [~, yearTicks] = ismember(yearDates, allTimeDates);

                    % Assign ticks and labels
                    app.TimeSpan19012025Slider.MajorTicks = yearTicks;
                    app.TimeSpan19012025Slider.MajorTickLabels = string(yearsShown);

                    % --- Update slider value safely ---
                    currentSliderValue = round(event.Value);
                    currentSliderValue(1) = max(currentSliderValue(1), app.TimeSpan19012025Slider.Limits(1));
                    currentSliderValue(2) = min(currentSliderValue(2), app.TimeSpan19012025Slider.Limits(2));
                    app.TimeSpan19012025Slider.Value = currentSliderValue;

                    % --- Map back to month-year strings ---
                    app.selectedStartTime = datestr(allTimeDates(currentSliderValue(1)), 'yyyy');
                    app.selectedEndTime   = datestr(allTimeDates(currentSliderValue(2)), 'yyyy');

                    % --- Update UI labels ---
                    app.StartTimeLabel.Text = app.selectedStartTime;
                    app.EndTimeLabel.Text   = app.selectedEndTime;

                case 'Annual'
                    % Show error message and revert to Monthly
                    uialert(app.UIFigure, 'Annual timescale is not available.', 'Feature Not Available');
                    %app.TimescaleDropDown.Value = 'Monthly';
                    % The callback will trigger again with 'Monthly' selection

                case 'Decadal'
                    % Show error message and revert to Monthly
                    uialert(app.UIFigure, 'Decadal timescale is not available.', 'Feature Not Available');
                    %app.TimescaleDropDown.Value = 'Monthly';
                    % The callback will trigger again with 'Monthly' selection
            end
            updateAllPlots(app);
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            updateAllPlots(app);
        end

        % Value changed function: TimescaleDropDown
        function TimescaleDropDownValueChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: RTWSA
        function RTWSACheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: TWSA
        function TWSACheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: This_Study
        function This_StudyCheckedNodesChanged(app, event)
            updateAllPlots(app);
            if any(app.This_Study.CheckedNodes == app.ReconstructionNode)
                disp('Reconstruction tab is selected.');

                % Ask user for reconstruction type
                choice = questdlg('Select reconstruction type:', ...
                    'Reconstruction Type', ...
                    'Basin Average', 'All Points', 'Basin Average');

                % Handle response
                switch choice
                    case 'Basin Average'
                        app.reconstructTWSWithRF('basin_average');
                    case 'All Points'
                        app.reconstructTWSWithRF('all_points');
                    otherwise
                        return;
                end
            end
        end

        % Callback function: Precipitation
        function PrecipitationCheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Value changed function: ExportPlotButton
        function ExportPlotButtonValueChanged(app, event)
            % Get the button value (useful if it's a toggle button)
            value = app.ExportPlotButton.Value;

            % Only export if button is "pressed" (true)
            if value
                % Ask user where to save the plot
                [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'}, 'Save Plot As');

                % Check if user canceled
                if isequal(file,0) || isequal(path,0)
                    % Reset button value if canceled
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % Full path to save
                filename = fullfile(path, file);

                % ========= Ask user for figure size =========
                prompt = {'Enter figure width (inches):','Enter figure height (inches):'};
                dlgtitle = 'Figure Size';
                dims = [1 35];
                definput = {'6','4'};  % default size
                answer = inputdlg(prompt, dlgtitle, dims, definput);

                % If user cancels input dialog
                if isempty(answer)
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % Convert input to numeric
                widthInches  = str2double(answer{1});
                heightInches = str2double(answer{2});

                if isnan(widthInches) || isnan(heightInches) || widthInches<=0 || heightInches<=0
                    uialert(app.UIFigure, "Invalid figure size entered.", 'Input Error', 'Icon', 'error');
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % ========= Figure export with DPI fallback =========
                % Get the figure containing the UIAxes
                fig = ancestor(app.UIAxes, 'figure');

                % Store original figure settings
                originalUnits = fig.PaperUnits;
                originalPos = fig.PaperPosition;

                % Set units to inches and adjust figure size
                fig.PaperUnits = 'inches';
                fig.PaperPosition = [0 0 widthInches heightInches];

                % DPI fallback list
                dpiList = [2500, 1500, 800, 600];
                success = false;
                lastError = '';

                for dpi = dpiList
                    try
                        exportgraphics(app.UIAxes, filename, 'Resolution', dpi);
                        success = true;
                        break; % stop trying if successful
                    catch ME
                        lastError = ME.message;
                        % Try the next DPI
                    end
                end

                if success
                    uialert(app.UIFigure, ...
                        sprintf("Plot saved successfully!\nSize: %.1f x %.1f in @ %d DPI", ...
                        widthInches, heightInches, dpi), ...
                        'Success', 'Icon', 'success');
                else
                    uialert(app.UIFigure, ...
                        sprintf("Failed to export plot at all tested resolutions.\nLast error:\n%s", lastError), ...
                        "Export Error");
                end

                % Restore original figure settings
                fig.PaperUnits = originalUnits;
                fig.PaperPosition = originalPos;

                % Reset the button value after saving
                app.ExportPlotButton.Value = false;
            end


        end

        % Callback function: Trend
        function TrendCheckedNodesChanged(app, event)

            % Get selected trend methods
            trendNodes = app.Trend.CheckedNodes;

            % Handle case when no nodes are selected
            if isempty(trendNodes)
                checkedMethods = {};
            else
                checkedMethods = lower(strtrim(string({trendNodes.Text})));
            end

            % All possible trend methods (excluding the parent 'trends' node if present)
            allMethods = {'linear', 'polynomial', 'loess', 'sens slope'};

            % Remove trends for unchecked methods
            for m = 1:numel(allMethods)
                method = allMethods{m};
                tagName = ['trend-' strrep(method, ' ', '-')];
                if ~ismember(method, checkedMethods)
                    % Safely delete trend lines and text
                    lineObjs = findobj(app.UIAxes, 'Type', 'line', 'Tag', tagName);
                    if ~isempty(lineObjs)
                        delete(lineObjs);
                    end
                    textObjs = findobj(app.UIAxes, 'Type', 'text', 'Tag', tagName);
                    if ~isempty(textObjs)
                        delete(textObjs);
                    end
                end
            end

            % Stop if nothing is checked
            if isempty(checkedMethods) || (numel(checkedMethods) == 1 && strcmp(checkedMethods{1}, 'trends'))
                % Refresh legend and return
                legend(app.UIAxes, 'off');
                legend(app.UIAxes, 'on');
                return;
            end

            % Filter out the parent 'trends' node if present
            validMethods = setdiff(checkedMethods, 'trends');
            if isempty(validMethods)
                legend(app.UIAxes, 'off');
                legend(app.UIAxes, 'on');
                return;
            end

            % Find base lines (skip existing trends)
            lineObjects = findobj(app.UIAxes, 'Type', 'line', '-not', 'LineStyle', '--');
            if isempty(lineObjects)
                msgbox('No time series lines found on the plot. Please plot some data first.', 'Info');
                return;
            end

            % Define consistent style mapping
            trendStyles = containers.Map( ...
                {'linear', 'polynomial', 'loess', 'sens slope'}, ...
                {{'--', 2.5}, {':', 2.5}, {'-.', 2.5}, {'--', 2.5}} ...
                );

            hold(app.UIAxes, 'on');
            trendResults = {};

            % === Loop through each checked method ===
            for t = 1:numel(validMethods)
                trendMethod = validMethods{t};
                tagName = ['trend-' strrep(trendMethod, ' ', '-')];

                % Remove any existing plot of this method (avoid duplicates)
                delete(findobj(app.UIAxes, 'Type', 'line', 'Tag', tagName));
                delete(findobj(app.UIAxes, 'Type', 'text', 'Tag', tagName));

                % === Loop through each base data line ===
                for i = 1:numel(lineObjects)
                    lineObj = lineObjects(i);

                    % Skip if this is already a trend line
                    if ~isempty(lineObj.Tag) && contains(lineObj.Tag, 'trends')
                        continue;
                    end

                    % Extract data
                    xData = lineObj.XData;
                    yData = lineObj.YData;
                    lineColor = lineObj.Color;
                    lineName = lineObj.DisplayName;

                    % Clean data
                    validMask = ~isnan(xData) & ~isnan(yData);
                    if sum(validMask) < 3
                        continue;
                    end
                    x_clean = xData(validMask);
                    y_clean = yData(validMask);

                    % Sort data
                    [x_clean, sortIdx] = sort(x_clean);
                    y_clean = y_clean(sortIdx);

                    % === Calculate trend ===
                    try
                        [trendLine, slope, ~, r2, pval] = app.calculateTrend(x_clean, y_clean, trendMethod);
                    catch ME
                        warning(ME.identifier,'Trend calculation failed for %s (%s): %s', lineName, trendMethod, ME.message);
                        continue;
                    end

                    if isempty(trendLine) || numel(trendLine) ~= numel(x_clean)
                        continue;
                    end

                    % Determine which Y-axis to use (default = left)
                    axSide = getappdata(lineObj, 'YAxisLocation');
                    if isempty(axSide)
                        axSide = 'left';
                    end
                    yyaxis(app.UIAxes, axSide);

                    % Respect inverted axes
                    set(app.UIAxes, 'YDir', get(app.UIAxes, 'YDir'));

                    % Style for this method
                    if isKey(trendStyles, trendMethod)
                        style = trendStyles(trendMethod);
                        lineStyle = style{1};
                        lineWidth = style{2};
                    else
                        lineStyle = '--';
                        lineWidth = 2;
                    end

                    % === Plot trend line ===
                    plot(app.UIAxes, x_clean, trendLine, lineStyle, ...
                        'Color', lineColor, ...
                        'LineWidth', lineWidth, ...
                        'DisplayName', sprintf('%s - %s Trend', lineName, trendMethod), ...
                        'Tag', tagName);

                    % === Add annotation text ===
                    yRange = range(app.UIAxes.YLim);
                    xRange = range(app.UIAxes.XLim);
                    textX = min(x_clean) + 0.02 * xRange;
                    textY = max(app.UIAxes.YLim) - (i-1) * 0.05 * yRange;

                    if strcmp(trendMethod, 'sens slope')
                        txt = sprintf('%s (%s): %.3f mm/yr (p=%.3f)', lineName, trendMethod, slope, pval);
                    else
                        txt = sprintf('%s (%s): %.3f mm/yr (R²=%.3f, p=%.3f)', lineName, trendMethod, slope, r2, pval);
                    end

                    text(app.UIAxes, textX, textY, txt, ...
                        'Color', lineColor, 'FontSize', 10, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'white', 'EdgeColor', lineColor, ...
                        'Margin', 2, 'VerticalAlignment', 'bottom', ...
                        'Tag', tagName);

                    % Store results
                    trendResults{end+1} = struct('Name', lineName, ...
                        'Method', trendMethod, ...
                        'Axis', axSide, ...
                        'Slope', slope, ...
                        'R2', r2, ...
                        'PValue', pval);
                end
            end

            legend(app.UIAxes, 'show');
            hold(app.UIAxes, 'off');

            % === Print results ===
            if ~isempty(trendResults)
                fprintf('\n=== Trend Analysis Results ===\n');
                for i = 1:numel(trendResults)
                    r = trendResults{i};
                    fprintf('%s (%s, %s-axis): slope = %.4f mm/yr, R² = %.4f, p = %.4f\n', ...
                        r.Name, r.Method, r.Axis, r.Slope, r.R2, r.PValue);
                end
                fprintf('============================================\n\n');
            end


        end

        % Callback function: evalution_metrices
        function evalution_metricesCheckedNodesChanged(app, event)
            % Only run evaluation metrics if sensitivity analysis is NOT checked
            if ~any(app.evalution_metrices.CheckedNodes == app.SensitivityAnalysisNode)
                % --- Get selected evaluation metrics ---
                checkedNodes = app.evalution_metrices.CheckedNodes;

                % Remove previous evaluation metric annotations
                allText = findobj(app.UIAxes, 'Type', 'text');
                for i = 1:length(allText)
                    if contains(allText(i).String, 'CC:') || contains(allText(i).String, 'NSE:') || ...
                            contains(allText(i).String, 'RMSE:') || contains(allText(i).String, 'Evaluation:')
                        delete(allText(i));
                    end
                end

                % If no metrics are checked, exit silently
                if isempty(checkedNodes)
                    return;
                end

                % --- Get all line objects from the axes ---
                lineObjects = findobj(app.UIAxes, 'Type', 'line', '-and', 'LineStyle', '-');
                if isempty(lineObjects)
                    msgbox('No time series lines found on the plot. Please plot some data first.', 'Info');
                    return;
                end

                % Check if we have at least 2 datasets
                if length(lineObjects) < 2
                    msgbox('Evaluation metrics require at least 2 datasets for comparison. Please plot more datasets.', 'Info');
                    return;
                end

                % --- Let user choose reference dataset if more than 2 ---
                if length(lineObjects) > 2
                    datasetNames = {};
                    for i = 1:length(lineObjects)
                        name = lineObjects(i).DisplayName;
                        if isempty(name)
                            name = sprintf('Dataset %d', i);
                        end
                        datasetNames{end+1} = name;
                    end

                    [selection, ok] = listdlg('ListString', datasetNames, ...
                        'Name', 'Select Reference Dataset', ...
                        'PromptString', 'Choose the reference dataset (observed data):', ...
                        'SelectionMode', 'single', ...
                        'ListSize', [300 150]);

                    if ~ok
                        return;
                    end

                    referenceIdx = selection;
                    comparisonIndices = setdiff(1:length(lineObjects), referenceIdx);
                else
                    % With exactly 2 datasets, use first as reference
                    referenceIdx = 1;
                    comparisonIndices = 2;
                end

                hold(app.UIAxes, 'on');

                % --- Get the selected metrics ---
                selectedMetrics = {};
                for i = 1:length(checkedNodes)
                    if isempty(checkedNodes(i).Children)
                        metricName = lower(strtrim(checkedNodes(i).Text));
                        selectedMetrics{end+1} = metricName;
                    end
                end

                % --- Calculate metrics ---
                referenceLine = lineObjects(referenceIdx);
                referenceName = referenceLine.DisplayName;
                if isempty(referenceName)
                    referenceName = sprintf('Dataset %d', referenceIdx);
                end

                results = {};

                for i = 1:length(comparisonIndices)
                    compIdx = comparisonIndices(i);
                    compLine = lineObjects(compIdx);
                    compName = compLine.DisplayName;
                    if isempty(compName)
                        compName = sprintf('Dataset %d', compIdx);
                    end

                    % Get data
                    y_obs = referenceLine.YData;
                    y_sim = compLine.YData;

                    % Align data
                    [y_obs_aligned, y_sim_aligned] = alignData(app, y_obs, y_sim);

                    if length(y_obs_aligned) < 2
                        continue;
                    end

                    % Calculate metrics
                    metrics = struct();
                    for k = 1:length(selectedMetrics)
                        switch selectedMetrics{k}
                            case 'cc'
                                metrics.CC = calculateCorrelationCoefficient(app, y_obs_aligned, y_sim_aligned);
                            case 'nse'
                                metrics.NSE = calculateNSE(app, y_obs_aligned, y_sim_aligned);
                            case 'rmse'
                                metrics.RMSE = calculateRMSE(app, y_obs_aligned, y_sim_aligned);
                        end
                    end

                    results{end+1} = struct(...
                        'Reference', referenceName, ...
                        'Comparison', compName, ...
                        'Color', compLine.Color, ...
                        'Metrics', metrics, ...
                        'N', length(y_obs_aligned));
                end

                % --- Display results ---
                if ~isempty(results)
                    yRange = range(app.UIAxes.YLim);
                    xRange = range(app.UIAxes.XLim);
                    textX = min(app.UIAxes.XLim) + 0.02 * xRange;
                    textY = max(app.UIAxes.YLim) - 0.05 * yRange;

                    % Title with reference dataset
                    text(app.UIAxes, textX, textY, sprintf('Evaluation Metrics (Reference: %s):', referenceName), ...
                        'Color', 'k', 'FontSize', 12, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'white', 'EdgeColor', 'k', ...
                        'Margin', 2, 'VerticalAlignment', 'bottom');

                    % Display each comparison result
                    for i = 1:length(results)
                        result = results{i};
                        textY = textY - 0.08 * yRange;

                        metricStr = sprintf('%s: ', result.Comparison);
                        metricParts = {};

                        if isfield(result.Metrics, 'CC')
                            metricParts{end+1} = sprintf('CC=%.3f', result.Metrics.CC);
                        end
                        if isfield(result.Metrics, 'NSE')
                            metricParts{end+1} = sprintf('NSE=%.3f', result.Metrics.NSE);
                        end
                        if isfield(result.Metrics, 'RMSE')
                            metricParts{end+1} = sprintf('RMSE=%.3f', result.Metrics.RMSE);
                        end

                        metricStr = [metricStr strjoin(metricParts, ', ')];
                        metricStr = [metricStr sprintf(' (n=%d)', result.N)];

                        text(app.UIAxes, textX, textY, metricStr, ...
                            'Color', result.Color, 'FontSize', 10, 'FontWeight', 'bold', ...
                            'BackgroundColor', 'white', 'EdgeColor', result.Color, ...
                            'Margin', 2, 'VerticalAlignment', 'bottom');
                    end
                end
                hold(app.UIAxes, 'off');
            end
            if any(app.evalution_metrices.CheckedNodes == app.SensitivityAnalysisNode)
                try
                    disp('Sensitivity Analysis tab is selected.');

                    % Ask user for reconstruction type
                    choice = questdlg('Select reconstruction type:', ...
                        'Sensitivity Analysis Type', ...
                        'Basin Average', 'All Points', 'Basin Average');

                    % Handle response
                    switch choice
                        case 'Basin Average'
                            readAndPlotSensitivityAnalysis(app, 'basin_average');

                        case 'All Points'
                            readAndPlotSensitivityAnalysis(app, 'all_points');

                        otherwise
                            return;
                    end
                catch ME
                    uialert(app.UIFigure, 'Maybe reconstruction is not done. Please reconstruct to plot data.', "Plotting Error","Icon","error");
                end
            end
        end

        % Callback function: validation1
        function validation1CheckedNodesChanged(app, event)
            updateAllPlots(app);
            % if ~ismember(app.SensitivityAnalysisNode, app.validation1.CheckedNodes)
            % --- Step 1: Get checked nodes ---
            checkedNodes = app.validation1.CheckedNodes;
            if isempty(checkedNodes)
                checkedTexts = {};
            elseif isa(checkedNodes, 'matlab.ui.container.TreeNode')
                checkedTexts = {checkedNodes.Text};
            elseif iscell(checkedNodes)
                checkedTexts = cellfun(@(n) n.Text, checkedNodes, 'UniformOutput', false);
            else
                checkedTexts = {};
            end

            % --- Step 2: Handle dynamic axes based on checked state ---
            if isempty(checkedTexts)  % Nothing selected - remove dynamic axes
                validation1Clicked(app);
            else  % Something selected - create/show dynamic axes
                createDynamicAxes(app);
                plotOnDynamicAxes(app, checkedTexts);
            end

            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Welcome to the Global TWSA analysing GUI: TWS100')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TWSA = uitree(app.UIFigure, 'checkbox');
            app.GRACETWSANode = uitreenode(app.TWSA);
            app.GRACETWSANode.Text = 'GRACE TWSA';
            app.JPLNode = uitreenode(app.GRACETWSANode);
            app.JPLNode.Text = 'JPL';
            app.CSRNode = uitreenode(app.GRACETWSANode);
            app.CSRNode.Text = 'CSR';
            app.GSFCNode = uitreenode(app.GRACETWSANode);
            app.GSFCNode.Text = 'GSFC';
            % Assign Checked Nodes
            app.TWSA.CheckedNodesChangedFcn = createCallbackFcn(app, @TWSACheckedNodesChanged, true);
            app.RTWSA = uitree(app.UIFigure, 'checkbox');
            app.Previous_TWSANode = uitreenode(app.RTWSA);
            app.Previous_TWSANode.Text = 'Previous_TWSA';
            app.HG19Node = uitreenode(app.Previous_TWSANode);
            app.HG19Node.Text = 'HG19';
            app.D23Node = uitreenode(app.Previous_TWSANode);
            app.D23Node.Text = 'D23';
            app.L21Node = uitreenode(app.Previous_TWSANode);
            app.L21Node.Text = 'L21';
            app.M25Node = uitreenode(app.Previous_TWSANode);
            app.M25Node.Text = 'M25';
            app.P25Node = uitreenode(app.Previous_TWSANode);
            app.P25Node.Text = 'P25';
            % Assign Checked Nodes
            app.RTWSA.CheckedNodesChangedFcn = createCallbackFcn(app, @RTWSACheckedNodesChanged, true);
            app.This_Study = uitree(app.UIFigure, 'checkbox');
            app.ThisStudyNode = uitreenode(app.This_Study);
            app.ThisStudyNode.Text = 'This Study';
            app.ANN_GNode = uitreenode(app.ThisStudyNode);
            app.ANN_GNode.Text = 'ANN_G';
            app.RF_GNode = uitreenode(app.ThisStudyNode);
            app.RF_GNode.Text = 'RF_G';
            app.ANN_IMDNode = uitreenode(app.ThisStudyNode);
            app.ANN_IMDNode.Text = 'ANN_IMD';
            app.RF_IMDNode = uitreenode(app.ThisStudyNode);
            app.RF_IMDNode.Text = 'RF_IMD';
            app.ReconstructionNode = uitreenode(app.This_Study);
            app.ReconstructionNode.Text = 'Reconstruction';
            % Assign Checked Nodes
            app.This_Study.CheckedNodesChangedFcn = createCallbackFcn(app, @This_StudyCheckedNodesChanged, true);
            app.Precipitation = uitree(app.UIFigure, 'checkbox');
            app.PrecipitationNode = uitreenode(app.Precipitation);
            app.PrecipitationNode.Text = 'Precipitation';
            app.IMDNode = uitreenode(app.PrecipitationNode);
            app.IMDNode.Text = 'IMD';
            app.ERA5Node = uitreenode(app.PrecipitationNode);
            app.ERA5Node.Text = 'ERA5';
            app.GPCPNode = uitreenode(app.PrecipitationNode);
            app.GPCPNode.Text = 'GPCP';
            % Assign Checked Nodes
            app.Precipitation.CheckedNodesChangedFcn = createCallbackFcn(app, @PrecipitationCheckedNodesChanged, true);
            app.Trend = uitree(app.UIFigure, 'checkbox');
            app.TrendsNode = uitreenode(app.Trend);
            app.TrendsNode.Text = 'Trends';
            app.LinearNode = uitreenode(app.TrendsNode);
            app.LinearNode.Text = 'Linear';
            app.PolynomialNode = uitreenode(app.TrendsNode);
            app.PolynomialNode.Text = 'Polynomial';
            app.LoessNode = uitreenode(app.TrendsNode);
            app.LoessNode.Text = 'Loess';
            app.SensslopeNode = uitreenode(app.TrendsNode);
            app.SensslopeNode.Text = 'Sens slope';
            % Assign Checked Nodes
            app.Trend.CheckedNodesChangedFcn = createCallbackFcn(app, @TrendCheckedNodesChanged, true);
            app.ExportPlotButton = uibutton(app.UIFigure, 'state');
            app.ExportPlotButton.ValueChangedFcn = createCallbackFcn(app, @ExportPlotButtonValueChanged, true);
            app.ExportPlotButton.Text = 'Export Plot';
            app.evalution_metrices = uitree(app.UIFigure, 'checkbox');
            app.EvaluationMetricsNode = uitreenode(app.evalution_metrices);
            app.EvaluationMetricsNode.Text = 'Evaluation Metrics';
            app.CCNode = uitreenode(app.EvaluationMetricsNode);
            app.CCNode.Text = 'CC';
            app.NSENode = uitreenode(app.EvaluationMetricsNode);
            app.NSENode.Text = 'NSE';
            app.RSMENode = uitreenode(app.EvaluationMetricsNode);
            app.RSMENode.Text = 'RSME';
            app.SensitivityAnalysisNode = uitreenode(app.evalution_metrices);
            app.SensitivityAnalysisNode.Text = 'Sensitivity Analysis';
            % Assign Checked Nodes
            app.evalution_metrices.CheckedNodesChangedFcn = createCallbackFcn(app, @evalution_metricesCheckedNodesChanged, true);
            app.validation1 = uitree(app.UIFigure, 'checkbox');
            app.GroundwaterNode = uitreenode(app.validation1);
            app.GroundwaterNode.Text = 'Groundwater';
            app.CGWBNode = uitreenode(app.GroundwaterNode);
            app.CGWBNode.Text = 'CGWB';
            app.GRACENode = uitreenode(app.GroundwaterNode);
            app.GRACENode.Text = 'GRACE';
            app.PrecipitationNode_2 = uitreenode(app.validation1);
            app.PrecipitationNode_2.Text = 'Precipitation';
            app.IMDNode_2 = uitreenode(app.PrecipitationNode_2);
            app.IMDNode_2.Text = 'IMD';
            % Assign Checked Nodes
            app.validation1.CheckedNodesChangedFcn = createCallbackFcn(app, @validation1CheckedNodesChanged, true);
            app.TimeSpan19012025SliderLabel = uilabel(app.UIFigure);
            app.TimeSpan19012025SliderLabel.Text = 'Time Span (1901-2025)';
            app.TimeSpan19012025Slider = uislider(app.UIFigure, 'range');
            app.TimeSpan19012025Slider.Limits = [0 1500];
            app.TimeSpan19012025Slider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500];
            app.TimeSpan19012025Slider.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000', '1100', '1200', '1300', '1400', '1500'};
            app.TimeSpan19012025Slider.ValueChangedFcn = createCallbackFcn(app, @TimeSpanTimeSpan19012025SliderValueChanging, true);
            app.TimeSpan19012025Slider.Step = 100;
            app.TimeSpan19012025Slider.Value = [0 1500];
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.BasinDropDownLabel = uilabel(app.UIFigure);
            app.BasinDropDownLabel.Text = 'Basin';
            app.BasinSelectionButton = uidropdown(app.UIFigure);
            app.BasinSelectionButton.Items = {'Select Basin', 'ABHE BAD (also ABHE BID HAYK)', 'ALAKOL', 'ALAZEYA', 'ALBANY', 'AMAZON (also AMAZONAS)', 'AMUR', 'ANABAR', 'ANADYR', 'ANDERSON', 'ARAL SEA', 'ASHBURTON', 'BACK', 'BANCANNIA LAKE', 'BANDAMA', 'BRAHMAPUTRA', 'BRAZOS', 'BURDEKIN', 'CANIAPISCAU', 'CHAD', 'CHAO PHRAYA', 'CHUBUT', 'CHURCHILL', 'CHURCHILL', 'CHUY (also SHU)', 'COLORADO', 'COLORADO', 'COLORADO (also COLORADO RIVER)', 'COLUMBIA', 'COLVILLE', 'COMOE (also KOMOE)', 'CONGO', 'COPPER', 'CUANZA', 'CUVELAI', 'CUYUNI', 'DANUBE', 'DAUGAVA (also ZAPADNAYA DVINA, also ZAKHODNAYA DZVINA)', 'DNIEPER', 'DNIESTR', 'DOCE', 'DON', 'DOURO (also DUERO)', 'DRAA', 'EBRO', 'ELBE', 'FITZROY', 'FITZROY', 'FLINDERS', 'FRASER', 'GANGES', 'GODAVARI', 'GREAT SALT LAKE', 'GUIR', 'HAI HE', 'HAMUN', 'HAMUN-I-MASHKEL', 'HAR US NUUR', 'HAYES', 'HUAI HE', 'INDIGIRKA', 'INDUS', 'IRRAWADDY', 'JUBBA (also GENALE WENZ)', 'KAPUAS', 'KEMIJOKI', 'KHATANGA', 'KIZILIRMAK', 'KOLYMA', 'KRISHNA', 'KUNENE (also CUNENE)', 'KURA', 'KUSKOKWIM', 'LA GRANDE', 'LAKE BALKHASH', 'LAKE EYRE', 'LAKE GAIRDNER', 'LAKE TURKANA', 'LENA', 'LIAO HE', 'LIMPOPO', 'LOIRE', 'MACKENZIE', 'MAGDALENA', 'MAHANADI', 'MAR CHIQUITA', 'MEGHNA', 'MEKONG', 'MELRHIR', 'MEZEN', 'MISSISSIPPI', 'MOBILE RIVER', 'MOOSE', 'MURCHISON', 'MURRAY', 'NADYM', 'NARMADA', 'NARVA', 'NEGRO', 'NELSON', 'NEMAN (also NEMUNAS, also NYOMAN)', 'NEVA', 'NIGER', 'NILE', 'NOTTAWAY', 'OB', 'ODER', 'OGOOUE', 'OKAVANGO (also CUBANGO)', 'OLENEK', 'ONEGA', 'ORANGE', 'ORINOCO', 'PANUCO', 'PARANA', 'PARNAIBA', 'PECHORA', 'PIVDENNY BUH (also SOUTHERN BUG)', 'PO', 'POPIGAY', 'PUR', 'PYASINA', 'RED', 'RHARSA', 'RHINE', 'RHONE', 'RIO DE LA PLATA', 'RIO GRANDE (also BRAVO)', 'RUFIJI', 'RUKWA', 'RUVUMA (also ROVUMA)', 'SACRAMENTO', 'SAGUENAY', 'SAINT LAWRENCE (also SAINT-LAURENT)', 'SALWEEN', 'SAN JOAQUIN', 'SANAGA', 'SANTIAGO', 'SAO FRANCISCO', 'SARYSU', 'SAVE', 'SEINE', 'SENEGAL', 'SEPIK', 'SEVERN', 'SEVERNAYA DVINA', 'SHATT AL ARAB', 'SUSQUEHANNA', 'TAGUS', 'TALAS', 'TANA', 'TARIM HE (also TALIMU HE)', 'TAZ', 'THELON', 'THLEWIAZA', 'TOCANTINS', 'TORGHAY', 'URAL (also ORAL)', 'URUGUAY', 'USUMACINTA', 'UVS NUUR (also UBSU-NUR)', 'VICTORIA', 'VISTULA', 'VOLGA', 'VOLTA', 'WINISK', 'YANA', 'YANGTZE', 'YAQUI', 'YELLOW RIVER', 'YENISEY', 'YUKON', 'ZAMBEZI', 'ZHEM (also EMBA)', 'ZHU'};
            app.BasinSelectionButton.Value = 'Select Basin';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Temporal', 'Spatial', 'Regional Mean'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Temporal';
            app.TimescaleDropDown = uidropdown(app.UIFigure);
            app.TimescaleDropDown.Items = {'Monthly', 'Annual', 'Decadal'};
            app.TimescaleDropDown.ValueChangedFcn = createCallbackFcn(app, @TimescaleDropDownValueChanged, true);
            app.TimescaleDropDown.Value = 'Monthly';
            app.TimescaleDropDownLabel = uilabel(app.UIFigure);
            app.TimescaleDropDownLabel.Text = 'Time scale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TWS100

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @updateAllPlots)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end